classdef ROIStats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetGroupsourceButton         matlab.ui.control.Button
        GroupsourceButton              matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        CamerareadnoiseEditField       matlab.ui.control.NumericEditField
        CamerareadnoiseEditFieldLabel  matlab.ui.control.Label
        CameraoffsetEditField          matlab.ui.control.NumericEditField
        CameraoffsetEditFieldLabel     matlab.ui.control.Label
        CameraGainEditField            matlab.ui.control.NumericEditField
        CameraGainEditFieldLabel       matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
    end

    
    properties (Access = private)
        paramArray
        meanim
        areas
        meanItx
        measuredPhotons
        simulatedSTD
        estimatedSTD
        bleachingTimeConstant
        spikeDetectionSTD
        spikeHeight
        ogNames
        sourceFiles
        roiIndices
    end
    
    methods (Access = private)
        
        function loadROIInfo(app)
            for i = 1:length(app.paramArray)
                roifn = getFileName(app.paramArray(i), 'ROI');
                MovavgFn = getFileName(app.paramArray(i),'Movavg');
                roidata = load(roifn);
                app.areas{i} = squeeze(sum(roidata.roi,[1,2]));
                
                app.meanim{i} = tiffreadVolume(MovavgFn);
                rois2d = reshape(roidata.roi,[],size(roidata.roi,3));
                meanIm1d = reshape(app.meanim{i},[],1);
                summed = rois2d'*single(meanIm1d);
                app.meanItx{i} = summed./app.areas{i};
            end
            app.updateTable();
        end
        function updateTable(app)
            fsData = zeros(length(app.paramArray),1);
            for i = 1:length(app.paramArray)
                TSdata = load(getFileName(app.paramArray(i),'TS'));
                tm = TSdata.tm{1};
                if size(tm,1)==1
                    tm = tm';
                end
                tm = tm(app.paramArray(i).framesToDisregard+1:end);
                fsData(i) = 1/mean(diff(tm));
                numNeuron = size(TSdata.ts{1},2);
                app.bleachingTimeConstant{i} = zeros(numNeuron,1);
    
                hpFreq = 20;
                spikeWindowSize = 11; numEachSide = floor(spikeWindowSize/2);
                medFiltBleachingWindowSize = 31;
                

                stimCheckWindowSize = 10;
                [fsStim,stim] = load_stim_series(app.paramArray(i),0);
                roiFile = load(getFileName(app.paramArray(i), 'ROI'));
                dmdCorrespondence = [];
                nanStims = ~isempty(stim) && isfield(roiFile,'stimCorrespondence');

                for j = 1:numNeuron
                    data = TSdata.ts{1}(:,j);
                    data = data(app.paramArray(i).framesToDisregard+1:end);
                    medfiltData  = medfilt1(data,medFiltBleachingWindowSize,'truncate');
                    f = fit(tm,double(medfiltData), 'exp1');  % 'exp1' models y = a*exp(b*x)
                    app.bleachingTimeConstant{i}(j) = -1/f.b;
                    if app.bleachingTimeConstant{i}(j)<0
                        app.bleachingTimeConstant{i}(j) = inf;
                    end
    
    
                    Wn = hpFreq / (fsData(i)/2); % Normalized highpass frequency cutoff
                    [b, a] = butter(4, Wn, 'low'); % Design the highpass Butterworth filter
                    % The loop below applies the Butterworth filter to each column of data
            
                    low = filtfilt(b, a, data);
                    data = 100*(data-low)./low; % Apply the highpass filter
    
                    idxs = getSpkIx(data,app.spikeDetectionSTD);
                    spikeHeights = zeros(1,length(idxs));
                    spikeWindow = cell(length(idxs),1);
                    for k = 1:length(idxs)
                        idx = idxs(k);
                        start_idx = max(1, idx - numEachSide);
                        end_idx = min(length(data), idx + numEachSide);
                        spikeWindow{k} = -data(start_idx:end_idx);
                        spikeWindow{k} = spikeWindow{k}-median(spikeWindow{k});
                        spikeHeights(k) = spikeWindow{k}(numEachSide+1);
                    end

                    % figure;plot(-data); hold on; plot(idxs,3,'r.')
                    
                    % figure; hold on;
                    % for k = 1:length(idxs) %nan out spikes
                    %     if numel(spikeWindow{k}) ~= median(cellfun(@numel,spikeWindow))
                    %         continue
                    %     end
                    %     plot(spikeWindow{k}); 
                    % end
                    app.spikeHeight{i}(j) = mean(spikeHeights);

                    if nanStims
                        [~,neuronStims] = getThisNeuronsStims(app.paramArray(i),j,roiFile.stimCorrespondence,stim);
                        neuronStimStartTimes = find(diff([0;neuronStims])>0)/fsStim;
                        thresh = std(data)*4;
                        for k = 1:length(neuronStimStartTimes)
                            startIdx = max(find(tm>neuronStimStartTimes(k),1)-stimCheckWindowSize/2,1);
                            endIdx = min(startIdx+stimCheckWindowSize,length(tm));
                            stimWindow = data(startIdx:endIdx);
                            stimWindow(stimWindow>thresh) = NaN;
                            data(startIdx:endIdx) = stimWindow;
                        end
                    end

                    for k = 1:length(idxs) %nan out spikes
                        idx = idxs(k);
                        start_idx = max(1, idx - numEachSide);
                        end_idx = min(length(data), idx + numEachSide);
                        data(start_idx:end_idx) = NaN;
                    end
                    data(isnan(data)) = [];
                    app.estimatedSTD{i}(j) = std(data);
                    
                    if false
                        lengthToSimulate = min(length(data),1000);
                        simulatedData = simulateDataPoissonRead(app.meanItx{i}(j)-app.CameraoffsetEditField.Value, app.CameraGainEditField.Value,app.CamerareadnoiseEditField.Value,app.areas{i}(j),lengthToSimulate);
                        low = filtfilt(b, a, simulatedData);
                        simulatedData = 100*(simulatedData-low)./low; % Apply the highpass filter
    
                        app.simulatedSTD{i}(j) = std(simulatedData);
                    else
                        app.simulatedSTD{i} = [];
                    end
                end
                measuredPhotonsPerSecond = (app.meanItx{i}-app.CameraoffsetEditField.Value).*app.areas{i}.*app.CameraGainEditField.Value.*fsData(i);
                app.measuredPhotons{i} = measuredPhotonsPerSecond;
                [path,folder4] = fileparts(app.paramArray(i).pth);[path,folder3] = fileparts(path);[path,folder2] = fileparts(path);[~,folder1] = fileparts(path);
                sourceName = fullfile(folder1,folder2,folder3,folder4);
                app.sourceFiles{i} = repmat(sourceName,[numNeuron,1]);
                app.roiIndices{i} = 1:numNeuron;
            end
            sourceFiles = cat(1,app.sourceFiles{:}); sourceFiles = cellfun(@char,num2cell(sourceFiles),'UniformOutput',false);
            roiIndices = cat(2,app.roiIndices{:})'; 
            areas = cat(1,app.areas{:}); 
            meanItx = cat(1,app.meanItx{:}); 
            measuredPhotonsPerSec = cat(1,app.measuredPhotons{:});
            simulatedSTD = cat(1,app.simulatedSTD{:});
            estimatedSTD = cat(2,app.estimatedSTD{:})'; 
            bleachingConstant = cat(1,app.bleachingTimeConstant{:});
            spikeHeights = cat(2,app.spikeHeight{:})';
            spikeSTDRatio = spikeHeights./estimatedSTD;
            goodIndices = ~cellfun(@isempty,{sourceFiles,roiIndices,areas,meanItx,measuredPhotonsPerSec,simulatedSTD,estimatedSTD,bleachingConstant,spikeHeights,spikeSTDRatio});
            app.UITable.Data = cat(2,sourceFiles,num2cell(roiIndices),num2cell(areas),num2cell(meanItx),num2cell(measuredPhotonsPerSec),num2cell(simulatedSTD),num2cell(estimatedSTD),num2cell(bleachingConstant),num2cell(spikeHeights),num2cell(spikeSTDRatio));
            app.UITable.ColumnName = app.ogNames(goodIndices);
            app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, threshSTD)
            app.ogNames = app.UITable.ColumnName;
            goodIndices = false(length(param),1);
            for i = 1:length(param)
                roifn = getFileName(param(i), 'ROI');
                movAvgfn = getFileName(param(i),'Movavg');
                TSfn = getFileName(param(i),'TS');
                fn = fullfile(param(i).pth, param(i).imtoken);
                if exist(roifn,'file') ~= 2
                    warning("must be an roi file to work: %s", fn);
                elseif exist(movAvgfn,'file') ~= 2
                    warning("must be a movavg file to work: %s", fn);
                elseif exist(TSfn,'file') ~= 2
                    warning("must be a TS file to work: %s", fn);
                else
                    goodIndices(i) = 1;
                end
            end
            param = param(goodIndices);
            app.paramArray = param;
            
            app.spikeDetectionSTD = threshSTD;
            app.UITable.Units='normalized';
            app.loadROIInfo();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Extract selected columns indices from the table
            selectedColumns = unique(app.UITable.Selection(:, 2), 'stable'); % Assuming selection format [row, col]
            selectedColumns(selectedColumns==1) = [];
            selectedRows = unique(app.UITable.Selection(:, 1)); % Assuming selection format [row, col]
            selectedRows = sort(selectedRows);


            figure; ax = gca();

            % Check if the selected columns are sufficient for plotting
            if isscalar(selectedColumns)
                data = app.UITable.Data(selectedRows, selectedColumns(1));
                origin = string(app.UITable.Data(selectedRows, 1));
                % if length(unique(origin))>4
                %     origin = fileparts(origin);
                % end
                boxplot(ax,cell2mat(data),origin)
                ylabel(ax,[app.UITable.ColumnName{selectedColumns(1)}]);
                return;
            end

            [fileNames,idx] = unique(string(app.UITable.Data(selectedRows,1)));
            % if numel(idx)>4
            %     [fileNames,idx] = unique(fileparts(string(app.UITable.Data(selectedRows,1))));
            % end
            [idx,order] = sort(idx);
            fileNames = fileNames(order);

            markerSize = 25;
            colors = brewermap(length(idx),'Dark2');
            for i = 1:length(idx)-1
                rowIndices = selectedRows(idx(i):idx(i+1)-1);
                % Extract data from the selected columns
                dataX = app.UITable.Data(rowIndices, selectedColumns(1));
                dataY = app.UITable.Data(rowIndices, selectedColumns(2));
                labels = app.UITable.Data(rowIndices,2);
                h = plot(ax, cat(1,dataX{:}), cat(1,dataY{:}), '.', MarkerSize=markerSize, Color=colors(i,:), DisplayName=fileNames{i});
                dtt = dataTipTextRow('Source', repmat(string(fileNames{i}),[length(labels),1]));
                h.DataTipTemplate.DataTipRows(end+1) = dtt;  % Append the new row to the data tip
                dtt = dataTipTextRow('ROI', labels);
                h.DataTipTemplate.DataTipRows(end+1) = dtt;  % Append the new row to the data tip


                hold(ax,'on');
            end
            rowIndices = selectedRows(idx(end):end);
            % Extract data from the selected columns
            dataX = app.UITable.Data(rowIndices, selectedColumns(1));
            dataY = app.UITable.Data(rowIndices, selectedColumns(2));
            
            labels = app.UITable.Data(rowIndices,2);
            h = plot(ax,cat(1,dataX{:}), cat(1,dataY{:}),  '.',MarkerSize=markerSize,Color=colors(end,:),DisplayName=fileNames{end});
            dtt = dataTipTextRow('Source', repmat(string(fileNames{end}),[length(labels),1]));
            h.DataTipTemplate.DataTipRows(end+1) = dtt;  % Append the new row to the data tip
            dtt = dataTipTextRow('ROI', labels);
            h.DataTipTemplate.DataTipRows(end+1) = dtt;  % Append the new row to the data tip
        
            legend;
            title('Plot of Selected Columns');
            xlabel(ax,[app.UITable.ColumnName{selectedColumns(1)}]);
            ylabel(ax,[app.UITable.ColumnName{selectedColumns(2)}]);
            % Check if either label contains 'time constant'
            if contains(app.UITable.ColumnName{selectedColumns(1)}, 'tau')
                set(ax, 'XScale', 'log');  % Set Y axis to logarithmic scale
            end
            if contains(app.UITable.ColumnName{selectedColumns(2)}, 'tau')
                set(ax, 'YScale', 'log');  % Set Y axis to logarithmic scale
            end
            grid on;
        end

        % Value changed function: CameraGainEditField, 
        % ...and 2 other components
        function CameraGainEditFieldValueChanged(app, event)
            app.updateTable();
        end

        % Button pushed function: GroupsourceButton
        function GroupsourceButtonPushed(app, event)
            app.UITable.Data(:,1) = fileparts(app.UITable.Data(:,1));
        end

        % Button pushed function: ResetGroupsourceButton
        function ResetGroupsourceButtonPushed(app, event)
            fullSources = cat(1,app.sourceFiles{:}); fullSources = cellfun(@char,num2cell(fullSources),'UniformOutput',false);
            app.UITable.Data(:,1) = fullSources;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Source'; 'roi index'; 'Area'; 'Mean intensity'; 'Photons/Second'; 'Simulated STD'; 'Estimated STD'; 'Bleaching tau'; 'spike height'; 'Spike STD ratio'};
            app.UITable.RowName = {};
            app.CameraGainEditFieldLabel = uilabel(app.UIFigure);
            app.CameraGainEditFieldLabel.Text = 'Camera Gain';
            app.CameraGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.CameraGainEditField.Limits = [0 Inf];
            app.CameraGainEditField.ValueChangedFcn = createCallbackFcn(app, @CameraGainEditFieldValueChanged, true);
            app.CameraGainEditField.Value = 0.25;
            app.CameraoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.CameraoffsetEditFieldLabel.Text = 'Camera offset';
            app.CameraoffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.CameraoffsetEditField.Limits = [0 Inf];
            app.CameraoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @CameraGainEditFieldValueChanged, true);
            app.CameraoffsetEditField.Value = 100;
            app.CamerareadnoiseEditFieldLabel = uilabel(app.UIFigure);
            app.CamerareadnoiseEditFieldLabel.Text = 'Camera read noise';
            app.CamerareadnoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.CamerareadnoiseEditField.Limits = [0 Inf];
            app.CamerareadnoiseEditField.ValueChangedFcn = createCallbackFcn(app, @CameraGainEditFieldValueChanged, true);
            app.CamerareadnoiseEditField.Value = 1.6;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GroupsourceButton = uibutton(app.UIFigure, 'push');
            app.GroupsourceButton.ButtonPushedFcn = createCallbackFcn(app, @GroupsourceButtonPushed, true);
            app.GroupsourceButton.Text = 'Group source';
            app.ResetGroupsourceButton = uibutton(app.UIFigure, 'push');
            app.ResetGroupsourceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGroupsourceButtonPushed, true);
            app.ResetGroupsourceButton.Text = 'Reset Group source';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIStats(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end