classdef VoltageGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        GLMPlotButton                  matlab.ui.control.Button
        OpenStimPlotsButton            matlab.ui.control.Button
        RemoveCurrentButton            matlab.ui.control.Button
        OpenROIstatsButton             matlab.ui.control.Button
        FramesdisregardEditField       matlab.ui.control.NumericEditField
        FramesdisregardEditFieldLabel  matlab.ui.control.Label
        ColorSchemeDropDown            matlab.ui.control.DropDown
        TryUnifyROIsButton             matlab.ui.control.Button
        RunMotionOnAllButton           matlab.ui.control.Button
        BGballradiusEditField          matlab.ui.control.NumericEditField
        BGballradiusEditFieldLabel     matlab.ui.control.Label
        MotionCorrectionLabel          matlab.ui.control.Label
        MotionCheckBox                 matlab.ui.control.CheckBox
        SmartROIButton                 matlab.ui.control.StateButton
        ManualDrawDropdown             matlab.ui.control.DropDown
        OptimizespxButton              matlab.ui.control.Button
        lockdaspectCheckBox            matlab.ui.control.CheckBox
        BackgroundImageDropdown        matlab.ui.control.DropDown
        FiltersourceBGEditField_2      matlab.ui.control.NumericEditField
        neuropilMax                    matlab.ui.control.NumericEditField
        neuropilMin                    matlab.ui.control.NumericEditField
        neuropildistLabel              matlab.ui.control.Label
        MotionThreshEditField          matlab.ui.control.NumericEditField
        MotionThresholdLabel           matlab.ui.control.Label
        TFiltersourceBGEditField       matlab.ui.control.NumericEditField
        TemporalFilterLabel            matlab.ui.control.Label
        BlackEditField                 matlab.ui.control.NumericEditField
        BlackEditFieldLabel            matlab.ui.control.Label
        WhiteEditField                 matlab.ui.control.NumericEditField
        WhiteLabel                     matlab.ui.control.Label
        ROIListBoxImageAx              matlab.ui.control.ListBox
        ROIListListBoxLabel            matlab.ui.control.Label
        SelectNewFileButton            matlab.ui.control.Button
        FileDropDown                   matlab.ui.control.DropDown
        ManualROIButton                matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        PSTHButton                     matlab.ui.control.Button
        ManualAddButton                matlab.ui.control.Button
        SaveROIsButton                 matlab.ui.control.Button
        DeleteROIsButton               matlab.ui.control.Button
        SmartAddButton                 matlab.ui.control.Button
        LoadROIsButton                 matlab.ui.control.Button
        MainAxes                       matlab.ui.control.UIAxes
        TimeSeriesAxes                 matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        FlipdfFLabel                   matlab.ui.control.Label
        LockplotLabel                  matlab.ui.control.Label
        lockPlotCheckBox               matlab.ui.control.CheckBox
        HPfreqEditField                matlab.ui.control.NumericEditField
        HPfreqEditFieldLabel           matlab.ui.control.Label
        SplitStimButton                matlab.ui.control.StateButton
        DetectlightartifactButton      matlab.ui.control.Button
        MotionRegressionDropdown       matlab.ui.control.DropDown
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        FileDropDown_2                 matlab.ui.control.DropDown
        ROIListBoxPlotAx               matlab.ui.control.ListBox
        ROIListListBox_2Label          matlab.ui.control.Label
        flipdffCheckbox                matlab.ui.control.CheckBox
        smoothEditField                matlab.ui.control.NumericEditField
        smoothEditFieldLabel           matlab.ui.control.Label
        SpikeSTDEditField              matlab.ui.control.NumericEditField
        SpikeSTDEditFieldLabel         matlab.ui.control.Label
        SeperationEditField            matlab.ui.control.NumericEditField
        SeperationLabel                matlab.ui.control.Label
        popoutButton                   matlab.ui.control.Button
        BigTimeSeriesAx                matlab.ui.control.UIAxes
        BigTimeSeriesMotionAx          matlab.ui.control.UIAxes
    end

    %CURRENT BUGS-- no way to recalculate time series for small time series
    %graph when loading ROIs. Means the preview is copied even if loading
    %ROIs from another session
    properties (Access = private)
        hasChanged = 0 % Description
        cmapstrings = {'parula'; 'gray'; 'hot'; 'cool'; 'bone'; 'copper'; 'pink'; 'flag'; ...
    'prism'; 'jet'; 'hsv'; 'green'; 'red'; 'blue'; 'Green hot'; 'Red hot'; ...
    'Blue hot'; 'clut2b2'} % Description
        params
        paramIndex=1 %probs could be replaced by app.FileDropDown.Value (or at least a get)
        roi = {}
        mainAxImage
        meanim
        correlationim
        dfRangeim
        dfRatioim
        distanceMat
        mov
        replotFn
        reader
        region
%         filterFreq = 192;
        filterFreq = [];
        fs
        tm
        stimCorrespondence
        roiIndices = [];
    end

    properties(Dependent, Access = private)
        param
   end

    methods
        function fs = get.fs(app)
            if isempty(app.fs)
                app.fs= 1/mean(diff(app.tm));
            end
            fs = app.fs;
        end

        function tm = get.tm(app)
            if isempty(app.tm)
                app.tm = app.reader.metadata.tm; 
                while mean(diff(app.tm))>0.05 %assume more than 20 Hz
                    app.tm = app.tm/1000; %seconds. Unfortunately sometimes varies what units
                end
                app.tm = app.tm-min(app.tm);
            end
            tm = app.tm;
        end
        function param = get.param(app)
            param = app.params(app.paramIndex);
        end
        function set.param(app, newParam)
            newFields = setdiff(fieldnames(newParam),fieldnames(app.params));
                
            % Add the missing fields to app.params and initialize them to []
            for i = 1:length(newFields)
                [app.params.(newFields{i})] = deal([]);
            end
            app.params(app.paramIndex) = newParam;
        end
        function reader = get.reader(app)
            if ~isempty(app.reader)
                reader = app.reader;
                return
            end
            if length(app.param.imtoken)<=1
                tifFiles = dir(fullfile(app.param.pth, '*.tif'));
                tiffFiles = dir(fullfile(app.param.pth, '*.tiff'));
                if ~isempty(tifFiles)
                    imageInfo = imfinfo(fullfile(tifFiles(1).folder,tifFiles(1).name));
                elseif ~isempty(tiffFiles)
                    imageInfo = imfinfo(fullfile(tiffFiles(1).folder,tiffFiles(1).name));
                end
                try %def wrong way to do this but idc for now
                    app.region = getMetaVal(imageInfo.ImageDescription,'region');
                    app.region = app.region([1:2 4:5]);
                catch
                end
            end
            reader = FrameReader(fullfile(app.param.pth,app.param.imtoken));
            app.reader = reader;
            if ~isempty(reader.region)
                app.region = reader.region;
            end
        end
        function out = get.mov(app)
            if ~isempty(app.mov)
                out=app.mov;
                return
            end
            fnRegMov = getFileName(app.param,'RegBGMov');
            if ~isempty(app.param.doRegistration) && app.param.doRegistration && exist(fnRegMov,'file')
                try
                    fr = FrameReader(fnRegMov);
                catch ME
                    app.reader.pointer = 1;
                    fr = app.reader;
                    msg = ME.message;
                    warning(ME.identifier, 'Error occurred while initializing FrameReader: %s', msg);
                end
            else
                app.reader.pointer = 1;
                fr = app.reader;
            end
            app.mov = single(fr.getFrames(5000));
            out = app.mov;
%             out = app.mov;
%             
%             if exist(getfile(,'DMDPatternInfo.mat'),"file") && ~isempty(app.region)
%                 dmdPatternInfo = load(fullfile(analysisDir,'DMDPatternInfo.mat'));
%                 camOutView = dmdPatternInfo.camOutView;
%                 
%                 regMask = camOutView(app.region(3):app.region(4),app.region(1):app.region(2));
%                 seSize = 0;
%                 se = strel('disk', abs(seSize));
%                 if seSize<0 %erode the background (include more off-targetted for registration)
%                     regMask = ~imerode(double(~regMask),se);
%                 elseif seSize>0        %erode the mask (include less targetted for registration);
%                     regMask = imerode(double(regMask),se);
%                 end
%             else
%                 savefn = getFileName(app.param, 'RegInfo');
%                 if exist(savefn,"file")
%                     load(savefn,'regMask')
%                 end
%                 if ~exist("regMask","var")
%                     tempmask.mask = [];
%                     selectROI2(mean(app.mov,3), @drawrectangle,@setMask);
%                     uiwait(); %have to wait until figure done--- it's annoying but return vars of this function are dependent
%                     regMask = tempmask.mask;
%                     if exist(savefn,'file')
%                         save(savefn,"regMask",'-append');
%                     else
%                         save(savefn,"regMask");
%                     end
%                 end

%             end
%             
%             if sum(regMask>0,'all')<=0
%                 return % no registration
%             elseif sum(regMask>0,'all')<=500
%                 pgon = polyshape([app.region(1),app.region(1),app.region(2),app.region(2)],[app.region(3),app.region(4),app.region(4),app.region(3)]);
%                 displayImage = zeros(3200,3200);
%                 displayImage(app.region(3):app.region(4),app.region(1):app.region(2)) =  mean(app.mov,3);
%                 %figure;imagesc(displayImage);figure;imagesc(dmdPatternInfo.camOutView);hold on; plot(pgon);
%                 figure;imagesc(displayImage);hold on; plot(pgon);
%                 keyboard;  %double check mask and video registration 
%                 return
%             end
%             app.mov = registerStack(app.mov,[],regMask);
%             out = app.mov;
%             function setMask(maskOut,~)
%                 tempmask.mask = maskOut; % Assign the mask variable when the app is completed
%                 uiresume();
%             end
        end
    end
    methods (Access = private)
        
        
        function updateAxis(app)
            
            cl = prctile(app.mainAxImage.CData(:), 100.*[app.BlackEditField.Value app.WhiteEditField.Value]);
            if cl(2)<=cl(1)
                cl(2) = cl(1)+1;
            end
            
            clim(app.MainAxes, cl);
            axis(app.MainAxes,'tight');
        end
        
        function run_time_series(app)
            for i = 1:length(app.params)
                app.params(i).neuropilRange = [app.neuropilMin.Value,app.neuropilMax.Value];
            end
            
            app.saveROI_button_fcn(1);
            
            if exist(getFileName(app.param, 'TS'),'file') && ~app.hasChanged 
                return
            end
            if isfield(app.param,'doRegistration') && app.param.doRegistration
                Pipeline_MotionCorrectRemoveBackground(app.param);
            end
            Pipeline_getTimeSeries(app.param);

            app.hasChanged=0; hasChanged = 0; %#ok<ADPROP> 
            save(getFileName(app.param, 'ROI'),'hasChanged','-append');

            app.stimCorrespondence = [];
        end
        
        function saveROI_button_fcn(app,hideUI)
            if ~app.hasChanged && hideUI
                return
            end
            hideUI=exist("hideUI",'var') && hideUI;            
            Nroi = numel(app.roi);
            r = size(app.roi(1).mask, 2);
            c = size(app.roi(1).mask, 1);
            roi = zeros(c,r,Nroi); %#ok<ADPROPLC> 
            timeSeries = cell(Nroi,1);
            for i = 1:Nroi
                roi(:,:,i) = app.roi(i).mask; %#ok<ADPROPLC> 
                timeSeries{i} = app.roi(i).time_series;
            end
            
            savefn = getFileName(app.param, 'ROI');

            if ~hideUI
                [savefn, pth] = uiputfile(savefn, 'ROI file');
                if savefn == 0
                    return;
                end
                savefn = fullfile(pth, savefn);
            end
            
            imtoken = fullfile(app.param.pth, app.param.imtoken);
            hasChanged = app.hasChanged; %#ok<ADPROPLC> 
            neuropilRange = [app.neuropilMin.Value,app.neuropilMax.Value];
            if diff(neuropilRange<=0)
                neuropilRange = [0 0];
            end
            save(savefn, 'roi','timeSeries','imtoken','hasChanged','neuropilRange');
            if ~isempty(app.region)
                region = app.region;
                save(savefn, 'region','-append');
            end
            if ~isempty(app.roiIndices)
                roiIndices = app.roiIndices;
                save(savefn, 'roiIndices','-append');
            end
        end
        
        function masks = getManualROI(app,justOne)
            if ~exist("justOne", "var")
                justOne = false;
            end
            polyToDelete={};
            
            fn = eval("@" + app.ManualDrawDropdown.Value);
            poly = fn(app.MainAxes, 'DrawingArea', [0,0,width(app.meanim),height(app.meanim)]);
            if sum(createMask(poly),"all")<10
                delete(poly);
                masks=[];
                return
            end
            if justOne
                masks = createMask(poly);
                delete(poly);
                return
            end
            
            masks = {};
            while ~isempty(poly) && sum(createMask(poly),"all")>=10
                polyToDelete{end+1} = poly; %#ok<AGROW>
                masks{end+1} = createMask(poly); %#ok<AGROW>
%                 (app.,poly);
                poly = fn(app.MainAxes, 'DrawingArea', [0,0,width(app.meanim),height(app.meanim)]);
            end
            for i = 1:length(polyToDelete)
                delete(polyToDelete{i})
            end
            if isempty(masks)
                delete(poly);
                delete(masks)
                return;
            end
            
            delete(poly)
        end
        
        function newmask = getSmartROI(app)
            ROIpt = drawpoint(app.MainAxes);
            if isempty(ROIpt)
                delete(ROIpt);
                return;
            end
            
            cc = ROIpt.Position;
            
            im = app.mainAxImage.CData;
            newmask = app.getSmartROIhelper(im,cc);
            delete(ROIpt);
        end

        function newmask = getSmartROIhelper(~,im,ptIn)

            cx = ptIn(1);
            cy = ptIn(2);
            
            Npts = 24;
            len = 20;
            
            rad = linspace(0, 2*pi, Npts);
            
            ringx = cx+len.*cos(rad);
            ringy = cy+len.*sin(rad);
            
            imx = 1:size(im, 2);
            imy = 1:size(im, 1);
            
            vv = zeros(Npts, Npts);
            for i = 1:Npts
                xx = linspace(cx, ringx(i), Npts);
                yy = linspace(cy, ringy(i), Npts);
                vv(:,i) = interp2(imx, imy, double(im), xx, yy);
            end
            
            avgdiff = median(diff(vv, 1, 1), 2);
            [~, nominalRad] = min(avgdiff);
            
            pos = 1:Npts;
            alpha = 1;
            beta = 0.4;
            
            cost = zeros(size(vv));
            minix = zeros(Npts, 1);
            xpos = zeros(Npts, 1);
            ypos = zeros(Npts, 1);
            for i = 1:Npts
                %     [~, ix] = sort(diff(vv(:,i)), 'ascend');
                %     ix(end+1) = Npts;
                [~, ~, rankorder] = unique(diff(vv(:,i)));
                rankcost = beta*(rankorder'.^2);
                rankcost(end+1) = max(rankcost);
                
                radcost = alpha*((pos-nominalRad)./2).^2;
                cost(:,i) = radcost + rankcost;
                [~, minix(i)] = min(cost(:,i));
            
            end
            minix = medfilt1([minix; minix; minix], 3);
            minix = minix(Npts+1:Npts+Npts);
            minix = minix+2;
            
            for i = 1:Npts
                xx = linspace(cx, ringx(i), Npts);
                yy = linspace(cy, ringy(i), Npts);
                
                xpos(i) = xx(minix(i));
                ypos(i) = yy(minix(i));
            
                hold on; 
                plot([cx xpos(i)], [cy ypos(i)], 'r.-');
            end
            
            newmask = false(size(im));
            for ii = 1:size(newmask,1)
                for jj = 1:size(newmask,2)
                    newmask(ii,jj) = inpolygon(ii,jj, ypos, xpos);
                end
            end
            newmask = double(newmask);
        end


        function colorsText = getColorsText(app)
            colorsText = app.ColorSchemeDropDown.Value;
            colorsText = split(colorsText,'|');
            colorsText = strtrim(colorsText(1));
            if iscell(colorsText)
                colorsText = colorsText{1};
            end
        end

        function color = col(app,idx)
            colorsText = app.getColorsText();
            numNeurons = numel(app.ROIListBoxImageAx.Items);
            colors = brewermap(numNeurons,colorsText);
            color = colors(idx,:);
        end


        function recalculateTimeSeriesROIs(app)
            neuropilRange = [app.neuropilMin.Value,app.neuropilMax.Value];
            allROIMask = sum(cat(3,app.roi.mask),3)>0.5;
            timeFilterBGSize = app.FiltersourceBGEditField_2.Value;
            for i = 1:length(app.roi)
                mask = app.roi(i).mask;
                app.roi(i).time_series = getTimeSeriesBinaryMask(app.mov,mask,neuropilRange,1,allROIMask,timeFilterBGSize);
            end
            app.updateLittleTimeSeriesAx();
        end

        function addMaskAsROI(app, newmask, num)
            if ~exist("num",'var')
                num = numel(app.roi)+1;
            end
            if num<=numel(app.roi)
                if ~isempty(app.roi(num).handle)
                    for j= 1:length(app.roi(num).handle)
                        delete(app.roi(num).handle(j));
                    end
                end
                if (~isempty(app.roi(num).hline))
                    delete(app.roi(num).hline)
                end
            end
            
            ctemp = contourc(double(newmask), [1,1].*0.5);
            app.roi(num).mask = newmask;
            app.roi(num).binary = 1;
            app.roi(num).handle = [];
            app.roi(num).cntr = ctemp;
            app.roi(num).hline = [];
            app.roiIndices(num) = max(app.roiIndices,[],'all') + 1;
            
            Nroi = numel(app.roi);
            
            str = cell(Nroi, 1);
            for i = 1:Nroi
                str{i} = ['ROI # ' num2str(app.roiIndices(i))];
            end
            app.ROIListBoxImageAx.Items = str;
            app.ROIListBoxImageAx.ItemsData = 1:Nroi;
            app.ROIListBoxImageAx.Value = num;
            app.ROIListBoxPlotAx.Items = str;
            app.ROIListBoxPlotAx.ItemsData = 1:Nroi;
            app.ROIListBoxPlotAx.Value = num;
            
            app.tryAddStyleToROIList();
            
            app.overlayContours()
            app.hasChanged=1;
        end

        function clear_and_reset(app)
            for j=1:length(app.roi)
                if (~isempty(app.roi(j).handle))
                    delete(app.roi(j).handle)
                end
            end
            app.roi = {};
            app.meanim = [];
            app.correlationim = [];
            app.dfRangeim= [];
            app.dfRatioim= [];
            app.mov = [];
            app.fs = [];
            app.hasChanged=0;
            app.reader = [];
            app.region = [];
            app.distanceMat = [];
            app.stimCorrespondence=[];
            app.ROIListBoxImageAx.Items = {};
            app.ROIListBoxImageAx.ItemsData = {};
            app.ROIListBoxPlotAx.Items = {};
            app.ROIListBoxPlotAx.ItemsData = {};
            
            app.roiIndices = [];
            app.updateLittleTimeSeriesAx();
        end
        
        function Select_and_add_input_files(app)
            paramsArray = Select_input_files(app);
            if length(paramsArray)==1 && paramsArray(1).pth ==""
                return
            end

            if ~isempty(app.params) 
                if all(ismember(fullfile([paramsArray.pth],[paramsArray.imtoken]),fullfile([app.params.pth],[app.params.imtoken])))
                    app.FileDropDown.Value = find(fullfile([paramsArray.pth],[paramsArray.imtoken])==fullfile([app.params.pth],[app.params.imtoken]));
                    app.FileDropDown_2.Value=app.FileDropDown.Value;
                    %should be setting it to that one but for now no
                    return
                end
            end

            for dex = 1:length(paramsArray)
                Pipeline_getExpInfo(paramsArray(dex));
            end

            oldSize = length(app.params);
            if isempty(app.params)
                app.params = paramsArray;
            else
                % Check if the fields in paramsArray already exist in app.params
                newFields = setdiff(fieldnames(paramsArray), fieldnames(app.params));
                
                % Add the missing fields to app.params and initialize them to []
                for i = 1:length(newFields)
                    app.params.(newFields{i}) = [];
                end
                % Check if the fields in paramsArray already exist in app.params
                newFields = setdiff(fieldnames(app.params),fieldnames(paramsArray));
                
                % Add the missing fields to app.params and initialize them to []
                for i = 1:length(newFields)
                    for j = 1:length(paramsArray)
                        paramsArray(j).(newFields{i}) = [];
                    end
                end
                app.params(end+1:end+length(paramsArray)) = paramsArray;
            end
            for i=1:1:length(app.params)
                temp(i) = fullfile(app.params(i).pth,app.params(i).imtoken);
            end
            app.paramIndex = oldSize + 1;
            app.FileDropDown.Items = temp;
            app.FileDropDown.ItemsData = 1:length(app.params);
            app.FileDropDown.Value = app.paramIndex;
            app.FileDropDown_2.Items = temp;
            app.FileDropDown_2.ItemsData = 1:length(app.params);
            app.FileDropDown_2.Value = app.paramIndex;
        end
        
        function overlayContours(app)
            for j=1:length(app.roi)
                if (~isempty(app.roi(j).handle))
                    delete(app.roi(j).handle)
                end
                ctemp = app.roi(j).cntr;
                if ~isempty(app.roi(j).cntr)
                    app.roi(j).handle = app.plotContour(ctemp, app.col(j), 0, 0);
                end
            end
            app.updateCountours();
        end
        
        function hand = plotContour(app, ctemp, clr, xoff, yoff)
            thr = 0.5;
            newcolumns = find(ctemp(1,:)==thr);
            hand = zeros(numel(newcolumns), 1);
            for i = 1:numel(newcolumns)
                ind1 = newcolumns(i)+1;
                if (i+1>numel(newcolumns))
                    ind2 = size(ctemp, 2);
                else
                    ind2 = newcolumns(i+1)-1;
                end
                
                hand(i) = line(app.MainAxes,ctemp(1,ind1:ind2)-xoff, ctemp(2, ind1:ind2)-yoff, 'Color', clr, 'LineWidth', 1);
            end
        end
        
        function extraFigClose(app,fig,row,column)
            app.BigTimeSeriesAx.Parent = app.GridLayout2;
            app.BigTimeSeriesAx.Layout.Row = row; 
            app.BigTimeSeriesAx.Layout.Column = column;
            delete(fig);
        end
        
        function updateLittleTimeSeriesAx(app)
            hold(app.TimeSeriesAxes, 'off');
            cla(app.TimeSeriesAxes)
            if isempty(app.roi)
                return;
            end
            try
                time = app.tm(app.param.framesToDisregard+1:length(app.roi(1).time_series));
            catch
                time = 1:(length(app.roi(1).time_series)-app.param.framesToDisregard);
            end
            time = time-min(time(:));

            rois = app.ROIListBoxImageAx.Value;
            maxVal = 0;
            for num = rois
                if (~isempty(app.roi(num).hline))
                    delete(app.roi(num).hline)
                end
                ts = app.roi(num).time_series(app.param.framesToDisregard+1:end);
                
                if app.filterFreq>0
                    BW = 2; % Bandwidth of the notch filter (in Hz)
                    
                    % Define the cutoff frequencies for the Butterworth filter
                    Wn = [(app.filterFreq-BW)/(app.fs/2) (app.filterFreq+BW)/(app.fs/2)];
                    [b, a] = butter(4, Wn, 'stop'); % Design the Butterworth filter
                    
                    ts = filtfilt(b, a, double(ts)); % Apply the Butterworth filter
                    %         figure;[power,freq] = pspectrum(detrend(data,3),fs); plot(freq,power);    
                end
                maxVal = max(max(abs(MySmooth(ts, app.TFiltersourceBGEditField.Value)),maxVal));
                app.roi(num).hline = plot(app.TimeSeriesAxes, time, MySmooth(ts, app.TFiltersourceBGEditField.Value), 'Color', app.col(num), 'LineWidth', 1);
                hold (app.TimeSeriesAxes, 'on');
            end
            
            xlim(app.TimeSeriesAxes,[0 max(time)])
            if (maxVal>0)
                ylim(app.TimeSeriesAxes,[-maxVal,maxVal]);
            end
            yline(app.TimeSeriesAxes, 0, 'k:', 'LineWidth', 1);
        end
        
        function replot(app)
            if ~isempty(app.replotFn)
                app.replotFn()
            end
        end
        
        function showImage(app)

            MovavgFn = getFileName(app.param, 'Movavg');

            delete(app.mainAxImage);
            app.mainAxImage = imagesc(app.MainAxes,app.meanim);
            
            if exist(MovavgFn, 'File')
                % fileContents = load(MovavgFn);
                app.meanim = tiffreadVolume(MovavgFn);
                % if isfield(fileContents,'correlationim')
                %     app.correlationim = fileContents.correlationim;
                % end
                % if isfield(fileContents,'dfRangeim')
                %     app.dfRangeim = fileContents.dfRangeim;
                % end
            else
                frames = app.reader.getFrames(600); %done this way instead of mov as mov can be BG/Regged
                app.meanim = median(frames, 3);
                saveTiff(MovavgFn,app.meanim);
                if ~isempty(app.correlationim)
                    saveTiff(getFileName(app.param, 'corrim'),app.correlationim);
                end
                if ~isempty(app.dfRangeim)
                    saveTiff(getFileName(app.param, 'dfRangeIm'),app.dfRangeim);
                end
            end

            value = app.BackgroundImageDropdown.Value;
            if value==1
                app.mainAxImage.CData = app.meanim;
            elseif value==2
                if ~isempty(app.correlationim)
                    app.mainAxImage.CData = app.correlationim;
                else
                    app.correlationim = getCorrelationim(app.mov);
                    app.mainAxImage.CData = app.correlationim;
                    saveTiff(getFileName(app.param, 'corrim'),app.correlationim);
                end
            elseif value==3 || value ==4
                if value==3
                    fieldName = 'dfRangeim';
                else
                    fieldName = 'dfRatioim';
                end

                if ~isempty(app.(fieldName))
                    app.mainAxImage.CData = app.(fieldName);
                else
                    [app.dfRangeim,app.dfRatioim] = getSTDRatioRange(app.mov); %#ok<ADPROP> 
                    % save(getFileName(app.param, 'Movavg'),'dfRatioim','dfRangeim',"-append");
                    saveTiff(getFileName(app.param, 'dfRangeim'),app.dfRangeim);
                    saveTiff(getFileName(app.param, 'dfRatioim'),app.dfRatioim);
                    app.mainAxImage.CData = app.(fieldName);
                end
            elseif value == 5
                mainAxisIms = load(getFileName(app.param, 'Movavg'));
                if isfield(mainAxisIms,'bgDotProdImage')
                    bgDotProdImage = mainAxisIms.bgDotProdImage;
                else
                    bgDotProdImage = dotProductWithBackground(app.param);
                    save(getFileName(app.param, 'Movavg'),'bgDotProdImage',"-append");
                end
                app.mainAxImage.CData = bgDotProdImage;
            end
            app.updateAxis();
        end
        
        function setDaspect(app)
            if app.lockdaspectCheckBox.Value
                daspect(app.MainAxes,[1 1 1]);
            else
                daspect(app.MainAxes,'auto');
            end
        end
        
        function load_stim_correspondence(app)
            if ~isempty(app.stimCorrespondence)
                return
            end
            
            amountToDilate = 0; %3 pixels on DMD

            fullfnRoi = getFileName(app.param, 'ROI');
            if exist(fullfnRoi,'file')~=2
                return
            end

            % f = load(fullfnRoi);
            % if isfield(f,"stimCorrespondence") && ~isempty(f.stimCorrespondence)
            %     app.stimCorrespondence = f.stimCorrespondence;
            %     return
            % end
            
            fullfnDMD = getFileName(app.param, 'DMDPatternInfo');
            if exist(fullfnDMD,'file')~=2
                return
            end
            optoFN = getFileName(app.param, 'OptoData');
            if exist(optoFN,'file')~=2
                [~,~] = load_stim_series(app.param);
            end
            
            dmdInfo = load(fullfnDMD);
            roiInfo = load(fullfnRoi);
            nRoi = numel(app.roi);
            outputViewCam = imref2d(size(zeros(3200, 3200)));
            patterns = dmdInfo.dmdPatterns;
            nPatterns = size(patterns,3);
            camPatternStim = zeros(3200,3200,nPatterns);
            if isempty(app.region) || all(app.region==-1)
                camPatternStimMyFOV = zeros([size(app.meanim,[1,2]),nPatterns]);
                for j = 1:nPatterns
                    pattern = patterns(:,:,j); pattern(dmdInfo.excitationRegistration.maskBoundingDMD') = 0;
                    if ~isfield(dmdInfo,'excitationToStimRegistration')
                        warning('using stimRegistration as excitationToStimRegistration is not saved')
                        camPatternStim(:,:,j) = imwarp(pattern, invert(dmdInfo.stimRegistration.T2), 'OutputView', outputViewCam);
                    else
                        camPatternStim(:,:,j) = imwarp(pattern, invert(dmdInfo.excitationToStimRegistration.T2), 'OutputView', outputViewCam);
                    end
                end
                tempMeanim = app.meanim-min(app.meanim,[],"all"); tempMeanim = tempMeanim./max(tempMeanim,[],"all");

                optoFN = getFileName(app.param, 'OptoData');
                optoData = load(optoFN);
                if isfield(optoData,"T2")
                    T2 = optoData.T2;
                else
                    [pts1,pts2]=cpselect(sum(camPatternStim,3)',tempMeanim,'Wait',true);
                    T2 = estgeotform2d(pts1, pts2, 'projective');
                    save(optoFN,"T2","-append");
                end
                refIm = imref2d(size(tempMeanim));
                for j = 1:nPatterns
                    camPatternStimMyFOV(:,:,j) = imwarp(camPatternStim(:,:,j)',T2,'OutputView', refIm);
                end
                roiSums = squeeze(sum(sum(roiInfo.roi, 1), 2)) * 0.1;

                for i = 1:nPatterns
                    pattern = camPatternStimMyFOV(:,:,i);
                    % Use matrix multiplication for weighted sum over the ROIs, exploiting broadcasting
                    % This step assumes binary (logical) values for roi masks, adjust if needed
                    patternSumOverRoi = sum(sum(bsxfun(@times, pattern, roiInfo.roi), 1), 2);
                    app.stimCorrespondence(i,:) = squeeze(patternSumOverRoi) > roiSums;
                end
            else
                roiDMD = false(768,1024,nRoi);
                outputViewDMD = imref2d(size(zeros(768,1024)));
                
                for j = 1:nRoi
                    tempCamPatternRoi = zeros(3200,3200);
                    tempCamPatternRoi(app.region(3):app.region(4),app.region(1):app.region(2)) = app.roi(j).mask;
                    roiDMD(:,:,j) = imwarp(tempCamPatternRoi',dmdInfo.excitationToStimRegistration.T2, 'OutputView',outputViewDMD)>0;
                        % Create a disk-shaped structuring element with a radius of 3 pixels
                    se = strel('disk', amountToDilate);
                                            
                    % Ensure the output is binary
                    roiDMD(:,:,j) = imdilate(roiDMD(:,:,j), se) > 0;
                end

                
                roiSums = squeeze(sum(sum(roiDMD, [1,2]), 2)) * 0.02;
                sums = reshape(patterns,[],size(patterns,3),1)'*reshape(roiDMD,[],size(roiDMD,3),1); %reshape to be [patternID x pix] * [pix x roi] to get [patternID x roi]
                app.stimCorrespondence =  sums>roiSums';
                app.stimCorrespondence =  sums>0;
%                     keyboard %was messed up because was EconomoGUI was using stimRegistration instead of excitationToStimRegistration
            end
            
            app.stimCorrespondence = app.stimCorrespondence(2:end,:);
            stimCorrespondence = app.stimCorrespondence; %#ok<ADPROPLC>

            save(fullfnRoi,"stimCorrespondence","-append"); 
            optoData = load(optoFN);
            if isfield(optoData,'stimOffset')
                shift = optoData.stimOffset;
                app.stimCorrespondence = circshift(app.stimCorrespondence,[shift,0]);
            else
                stimOffset = 0;
                save(optoFN,"stimOffset","-append"); 
            end
        end
        
        function saveStimOrder(app)
            DMDPatternFN = getFileName(app.param, 'DMDPatternInfo');
            if exist(DMDPatternFN,'file')~=2
                return
            end
            dmdInfo = load(DMDPatternFN);
            patterns = dmdInfo.dmdPatterns;
            [x,y,z] = size(patterns);
            patterns = reshape(patterns,x*y,z,1);
            covMat = patterns'*(patterns) + ~patterns'*(~patterns);
            covMat = covMat== size(patterns,1);
            for j = 1:size(covMat,1)
                indicies(j) = find(covMat(j,:),1,"first");
            end
            indicies = indicies(2:end)-1; %first one is blank and not repeated

            optoFN = getFileName(app.param, 'OptoData');
            save(optoFN,"indicies","-append");
        end
        
        function shiftStimHelper(app,shift)
            app.stimCorrespondence = circshift(app.stimCorrespondence,[shift,0]);
            stimCorrespondence = app.stimCorrespondence; %#ok<ADPROPLC> 
            fullfnROI = getFileName(app.param, 'ROI');
            optoFN = getFileName(app.param, 'OptoData');
            optoFileData = load(optoFN);
            if isfield(optoFileData,"stimOffset")
                stimOffset = optoFileData.stimOffset+shift;
            else
                stimOffset = shift;
            end
            save(optoFN,"stimOffset","-append");
            save(fullfnROI,"stimCorrespondence","-append"); 
            app.replot();
        end
        
        function updateDistanceMat(app)
            if ~isempty(app.distanceMat)
                return
            end
            masks = cat(3,app.roi.mask);
            numMasks = size(masks,3);
            app.distanceMat = zeros(numMasks);
            for i = 1:numMasks
                myDist = bwdist(masks(:,:,i));
                tempMasks = repmat(myDist,[1,1,numMasks]);
                tempMasks(~masks) = inf;
                app.distanceMat(:,i) = squeeze(min(tempMasks,[],[1,2]));
                app.distanceMat(i,:) = app.distanceMat(:,i);
            end
        end
        
        function tryAddStyleToROIList(app)

            try
                removeStyle(app.ROIListBoxPlotAx);
                removeStyle(app.ROIListBoxImageAx);
                for i = 1:numel(app.ROIListBoxImageAx.Items)
                    style = uistyle("FontColor",app.col(i));
                    addStyle(app.ROIListBoxPlotAx,style,"item",i);
                    addStyle(app.ROIListBoxImageAx,style,"item",i);
                end
            catch
            end
        end
        
        function updateCountours(app)
            value = app.ROIListBoxPlotAx.Value;
            for i = 1:length(app.roi)
                if ~isempty(value) && ismember(i,value)
                    lineWidth = 5;
                else
                    lineWidth = 1;
                end
                if isfield(app.roi(i), 'bgOutline')
                    for line = app.roi(i).bgOutline
                        delete(line);
                    end
                end
                % Check if 'handle' is a valid handle before modifying 'LineWidth'
                set(app.roi(i).handle, 'LineWidth', lineWidth);
            end
            if isfield(app.roi, 'bgOutline')
                app.roi = rmfield(app.roi, 'bgOutline');
            end

            if isscalar(value)
                neuropilRange = [app.neuropilMin.Value,app.neuropilMax.Value];
                seBG = strel('disk', neuropilRange(2));
                seIgnore = strel('disk', neuropilRange(1));
                mask = app.roi(value).mask;
                maskOuter = ~imerode(double(~mask),seBG)-~imerode(double(~mask),seIgnore);
                boundaries = bwboundaries(maskOuter);
                for k = 1:length(boundaries)
                    boundary = boundaries{k};
                    app.roi(value).bgOutline(k) = plot(app.MainAxes, boundary(:,2), boundary(:,1), '--', 'Color', app.col(value),'LineWidth', 1);
                end
            end
        end


        function addROIS(app,roiFileData)
            for j=1:length(app.roi)
                if (~isempty(app.roi(j).handle))
                    delete(app.roi(j).handle)
                end
            end
            app.roi = [];
            clear('app.roi');
            app.ROIListBoxImageAx.Items = strings;
            app.ROIListBoxPlotAx.Items = strings;
            app.ROIListBoxImageAx.ItemsData = 1:numel(strings);
            app.ROIListBoxPlotAx.ItemsData = 1:numel(strings);
            

            if ~exist("roiFileData","var")
                return
            end
            if isfield(roiFileData,'hasChanged')
                app.hasChanged = roiFileData.hasChanged;
            else
                app.hasChanged=1;
            end
            if isfield(roiFileData,'neuropilRange')
                app.neuropilMin.Value = roiFileData.neuropilRange(1);
                app.neuropilMax.Value = roiFileData.neuropilRange(2);
            end

            if isfield(roiFileData,'region')
                app.region = roiFileData.region;
            end
            if ~isfield(roiFileData,'roi')
                return
            end
            Nroi = size(roiFileData.roi, 3);
            str = cell(Nroi, 1);
            
            for i = 1:Nroi            
                p.mask = roiFileData.roi(:,:,i);
                ctemp = contourc(p.mask, [1,1].*0.5);
                
                p.binary = 1;
                p.handle = [];
                p.cntr = ctemp;
                if isfield(roiFileData,'imtoken') && app.compareFoldersAndName(roiFileData.imtoken,fullfile(app.param.pth, app.param.imtoken))
                    try
                        p.time_series = roiFileData.timeSeries{i};
                    catch
                    end
                end

                if ~isfield(p,'time_series') || isempty(p.time_series)
                    p.time_series = getTimeSeriesBinaryMask(app.mov, p.mask,[],1,sum(roiFileData.roi,3)>0.5);
                    app.hasChanged=1;
                end

                p.hline = [];
                
                str{i} = ['ROI # ' num2str(i)];
                r(i) = p;
                clear p
            end
            app.roi = r;
            app.ROIListBoxImageAx.Items = str;
            app.ROIListBoxImageAx.ItemsData = 1:Nroi;
            app.ROIListBoxPlotAx.Items = str;
            app.ROIListBoxPlotAx.ItemsData = 1:Nroi;
            
            app.tryAddStyleToROIList();

            app.overlayContours()
            app.updateLittleTimeSeriesAx();
        end
        
        
        function loadROIsHelper(app,full_fn)
            if ~isfile(full_fn)
                return
            end

            [~,~,ext] = fileparts(full_fn);
            if strcmp(ext,'.mat')
                roiFileData = load(full_fn);

                if isfield(roiFileData,'roiIndices')
                    app.roiIndices = roiFileData.roiIndices;
                else
                    app.roiIndices = 1:size(roiFileData.roi, 3);
                end
                app.addROIS(roiFileData)
                return
            end

            
            if strcmp(ext,'.tif') || strcmp(ext,'.tiff')
                getROICorrespondence(getFileName(app.param, 'Movavg'),[],full_fn,@callbackToAddROIs);
            end

            function callbackToAddROIs(rois,uniques)
                roiData.roi = double(rois);
                app.roiIndices = uniques;
                app.addROIS(roiData);
            end
        end
    end
    
    methods(Static)

        function dff = convertToDFF(ts)
            if size(ts,1) == 1
                ts = ts';
            end
            f = fit((1:length(ts))', medfilt1(ts,31), 'exp1');  % 'exp1' models y = a*exp(b*x)
                
            % Evaluate the fitted model over the time vector
            fitted_exp = f.a .* exp(f.b .* (1:length(ts))');
            
            dff = (ts - fitted_exp)*100./fitted_exp;
        end

        function isMatch = compareFoldersAndName(string1,string2,depth)
            if nargin<3
                depth = 3;
            end
            for i = 1:depth
                [string1,name1,ext1] = fileparts(string1);
                [string2,name2,ext2] = fileparts(string2);
                if ~strcmp(name1,name2) || ~strcmp(ext1,ext2)
                    isMatch = false;
                    return
                end
            end
            isMatch = true;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            addpath(fullfile(fileparts(pwd),"Microscope Control")); % I think just to get selectROI2 and vm? (yeah I know it's bad idc)
            
            app.SelectNewFileButtonPushed()
            linkaxes([app.BigTimeSeriesAx,app.BigTimeSeriesMotionAx],'x');
        end

        % Button pushed function: LoadROIsButton
        function LoadROIsButtonPushed(app, event)
            savefn = getFileName(app.param, 'ROI');
            prompt = 'Select roi file';
            rootDir = getpref(class(app),'lastPath',pwd);
            REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
            outFile = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
            app.loadROIsHelper(outFile);
        end

        % Button pushed function: PSTHButton
        function PSTHButtonPushed(app, event)
            
            app.updateDistanceMat();
            app.run_time_series()
            thresh = app.SpikeSTDEditField.Value;
            indicies = app.ROIListBoxPlotAx.Value;
            if isempty(indicies)
                indicies = app.ROIListBoxPlotAx.ItemsData;
            end
            indicies = sort(indicies);
            % numIndividualToPlot = inf;
            numIndividualToPlot = 90;
            includeBootstrap = 0;

            app.param.highPassWindowSize = round(app.fs/50); %for detecting spikes
            app.load_stim_correspondence();
            spike_avg_plots(app.params(app.paramIndex),thresh,numIndividualToPlot,indicies,app.distanceMat,includeBootstrap,app.stimCorrespondence);
            % stim_avg_plot(app.params(app.paramIndex),thresh,numIndividualToPlot,indicies,app.distanceMat,includeBootstrap,app.stimCorrespondence);
        end

        % Button pushed function: DeleteROIsButton
        function DeleteROIsButtonPushed(app, event)
            app.hasChanged=1;
            rois = app.ROIListBoxImageAx.Value;
            
            for i = 1:numel(rois)
                num = rois(i);
                if ~isempty(app.roi(num).handle)
                    for j= 1:length(app.roi(num).handle)
                        delete(app.roi(num).handle(j));
                    end
                end
                if (~isempty(app.roi(num).hline))
                    delete(app.roi(num).hline)
                end
                if isfield(app.roi,'bgOutline')
                    for l = app.roi(num).bgOutline
                        delete(l);
                    end
                end
            end
            keep = ~ismember(1:numel(app.roi), rois);
            app.roi = app.roi(keep);
            app.roiIndices = app.roiIndices(keep);
            
            Nroi = sum(keep);
            str = cell(Nroi, 1);
            for i = 1:Nroi
                str{i} = ['ROI # ' num2str(app.roiIndices(i))];
            end
            
            app.ROIListBoxImageAx.Items = str;
            app.ROIListBoxImageAx.ItemsData = 1:Nroi;
            app.ROIListBoxPlotAx.Items = str;
            app.ROIListBoxPlotAx.ItemsData = 1:Nroi;
            app.tryAddStyleToROIList();
            
            app.overlayContours();
            app.updateLittleTimeSeriesAx();
        end

        % Button pushed function: SelectNewFileButton
        function SelectNewFileButtonPushed(app, event)
            app.Select_and_add_input_files();
            app.FileDropDownValueChanged()
        end

        % Button pushed function: ManualAddButton
        function ManualAddButtonPushed(app, event)
            
            if isempty(app.roi)
                return;
            end
            app.hasChanged=1;
            num = app.ROIListBoxImageAx.Value(1);
            
            newmask = app.getManualROI(true);
            
            % close(newfig);
            newmask = double((app.roi(num).mask+newmask)>0.5);
            app.addMaskAsROI(newmask, num);
            app.recalculateTimeSeriesROIs();
        end

        % Value changed function: BlackEditField, WhiteEditField
        function ContrastEditFieldValueChanged(app, event)
            app.updateAxis();
        end

        % Value changed function: ROIListBoxImageAx, ROIListBoxPlotAx
        function ROIListBoxImageAxValueChanged(app, event)
            value = event.Value;
            if isempty(value)
                return
            end
            app.ROIListBoxPlotAx.Value = value;
            app.ROIListBoxImageAx.Value = value;


            app.updateCountours();

            app.updateLittleTimeSeriesAx();
            if app.TabGroup.SelectedTab == app.Tab2
                app.replot();
            end
        end

        % Button pushed function: ManualROIButton
        function ManualROIButtonPushed(app, event)
            newmasks = app.getManualROI();
            for dex = 1:length(newmasks)
                app.addMaskAsROI(newmasks{dex});
            end
            app.recalculateTimeSeriesROIs();
            app.ROIListBoxImageAx.Value = app.ROIListBoxImageAx.ItemsData(end);
        end

        % Value changed function: SmartROIButton
        function SmartROIButtonPushed(app, event)
           
            if app.SmartROIButton.Value
                roiHasChanged=0;
                while app.SmartROIButton.Value
                    newRoi = app.getSmartROI();
                    pause(0.01)
                    if app.SmartROIButton.Value
                        app.addMaskAsROI(newRoi);
                        roiHasChanged = 1;
                    end
                end
                if roiHasChanged
                    app.recalculateTimeSeriesROIs();
                    app.ROIListBoxImageAx.Value = app.ROIListBoxImageAx.ItemsData(end);
                end
            end
        end

        % Button pushed function: SmartAddButton
        function SmartAddButtonPushed(app, event)
            
            if isempty(app.roi)
                return;
            end
            app.hasChanged=1;
            num = app.ROIListBoxImageAx.Value(1);
            
            if manual
                newmask = app.getSmartROI();
            end
            
            % close(newfig);
            newmask = double((app.roi(num).mask+newmask)>0.5);
            app.addMaskAsROI(newmask, num);
            app.recalculateTimeSeriesROIs();
        end

        % Callback function: MotionRegressionDropdown, PlotButton, 
        % ...and 3 other components
        function PlotButtonPushed(app, event)
            if app.lockPlotCheckBox.Value && ~app.hasChanged
                app.TabGroup.SelectedTab = app.Tab2;
                return
            end
            app.run_time_series()
            filt = app.TFiltersourceBGEditField.Value;
            spikeThresh = app.SpikeSTDEditField.Value;
            motionThresh = app.MotionThreshEditField.Value;
            indicies = app.ROIListBoxPlotAx.Value;
            if isempty(indicies)
                indicies = app.ROIListBoxPlotAx.ItemsData;
            end
            seperation = app.SeperationEditField.Value;
            axes = [app.BigTimeSeriesAx, app.BigTimeSeriesMotionAx];
            flipdff = app.flipdffCheckbox.Value;
            motionRegressOpt = app.MotionRegressionDropdown.Value;
            

            fullfn = getFileName(app.param, 'OptoData');
            if exist(fullfn,'file')==2
                [~,~] = load_stim_series(app.param);
            end

            if app.SplitStimButton.Value == 1
                app.load_stim_correspondence();
            end
            correspondance = app.stimCorrespondence;
            
            colorsText = app.getColorsText();
            totalNumNeurons = numel(app.ROIListBoxImageAx.Items);
            colors = brewermap(totalNumNeurons,colorsText);

            simple_plot(app.param,filt,spikeThresh,motionThresh,seperation,axes,flipdff,indicies,correspondance,app.filterFreq,motionRegressOpt,colors,app.HPfreqEditField.Value);
            axes(2).InnerPosition([1,3]) = axes(1).InnerPosition([1,3]);
            if app.BigTimeSeriesAx.Parent==app.GridLayout2 && exist('event','var') && ~isempty(event) && event.Source == app.PlotButton
                app.TabGroup.SelectedTab = app.Tab2;
            end
            app.replotFn = @app.PlotButtonPushed; %should eventually simplify this
        end

        % Value changed function: TFiltersourceBGEditField, smoothEditField
        function TemporalFilterEditFieldValueChanged(app, event)
            app.TFiltersourceBGEditField.Value = event.Value;
            app.smoothEditField.Value = event.Value;
            app.updateLittleTimeSeriesAx();
            if app.TabGroup.SelectedTab == app.Tab2
                app.replot();
            end
        end

        % Button pushed function: popoutButton
        function popoutButtonPushed(app, event)
            fig = figure(); 
            row =app.BigTimeSeriesAx.Layout.Row;
            column = app.BigTimeSeriesAx.Layout.Column;
            fig.CloseRequestFcn = @(src, event) app.extraFigClose(fig,row,column); 
            app.BigTimeSeriesAx.Parent = fig;
            app.BigTimeSeriesAx.Units = 'normalized';
            app.BigTimeSeriesAx.Position = [0.01,0.01,0.98,0.98];
        end

        % Value changed function: FileDropDown, FileDropDown_2
        function FileDropDownValueChanged(app, event)
            oldNumROIS = length(app.ROIListBoxImageAx.ItemsData); oldIndex = app.ROIListBoxImageAx.Value;
            app.clear_and_reset();
            if exist("event","var")
                app.FileDropDown.Value = event.Value;
                app.FileDropDown_2.Value = event.Value;
            end
            app.paramIndex = app.FileDropDown.Value;

            cla(app.MainAxes);
            app.showImage()
            hold(app.MainAxes,"on");
            colormap(app.MainAxes,[app.cmapstrings{2} '(256)']);
            colorbar(app.MainAxes);

            axis(app.MainAxes,'off');
            app.setDaspect();
            
            app.loadROIsHelper(getFileName(app.param, 'ROI'))


            savefn = getFileName(app.param, 'TS');
            if isfield(app.param,'BGRemovalRadius') && ~isempty(app.param.BGRemovalRadius)
                app.BGballradiusEditField.Value = app.param.BGRemovalRadius;
            else
                 app.param.BGRemovalRadius = app.BGballradiusEditField.Value;
            end
            if isfield(app.param,'doRegistration') && ~isempty(app.param.doRegistration)
                app.MotionCheckBox.Value = app.param.doRegistration;
            else
                app.param.doRegistration = app.MotionCheckBox.Value;
            end

            if exist(savefn,"file")
                fileContentsTS = load(savefn);
                
                didRegistration = isfield(fileContentsTS,"didReg") && (~isempty(fileContentsTS.didReg) && fileContentsTS.didReg);
                registrationChanged =didRegistration~= app.MotionCheckBox.Value;
                
                if isfield(fileContentsTS,"BGRemovalRadius") && ~isempty(fileContentsTS.BGRemovalRadius)
                    prevBGRemovalRadius = fileContentsTS.BGRemovalRadius; 
                else
                    prevBGRemovalRadius = 0;
                end
                BGRemovalRadiusChanged = prevBGRemovalRadius ~=app.BGballradiusEditField.Value;

                app.hasChanged = app.hasChanged || BGRemovalRadiusChanged || registrationChanged;
            end

            if oldNumROIS==length(app.ROIListBoxImageAx.ItemsData)
                app.ROIListBoxImageAx.Value = oldIndex;
                app.ROIListBoxPlotAx.Value = oldIndex;
            end
            
            app.FramesdisregardEditField.Value = app.param.framesToDisregard;
            app.updateCountours();
            app.updateLittleTimeSeriesAx(); 
            app.updateAxis();
            
            cla(app.BigTimeSeriesAx)
            if ~app.hasChanged && exist(savefn,"file") && app.TabGroup.SelectedTab == app.Tab2
                app.replot()
            end

        end

        % Value changed function: FiltersourceBGEditField_2, neuropilMax, 
        % ...and 1 other component
        function recalculateTimeSeries(app, event)
            app.recalculateTimeSeriesROIs();
            app.hasChanged=1;
            fakeEvent.Value = app.ROIListBoxImageAx.Value;
            app.ROIListBoxImageAxValueChanged(fakeEvent);
        end

        % Button pushed function: SaveROIsButton
        function SaveROIsButtonPushed(app, event)
            app.saveROI_button_fcn();
        end

        % Value changed function: BackgroundImageDropdown
        function BackgroundImageDropdownValueChanged(app, event)
            app.showImage();
            app.overlayContours();
        end

        % Value changed function: lockdaspectCheckBox
        function lockdaspectCheckBoxValueChanged(app, event)
            app.setDaspect();
        end

        % Button pushed function: OptimizespxButton
        function OptimizespxButtonPushed(app, event)
            app.saveROI_button_fcn(1);
            app.run_time_series();
            
            spikeWindowSize = 5;
            numEachSide = (spikeWindowSize-1)/2;
            framesXYGaussSigma = 1;

            if ~isempty(app.param.doRegistration) && app.param.doRegistration && exist(getFileName(app.param,'RegBGMov'),'file')==2
                fr = FrameReader(getFileName(app.param,'RegBGMov'));
            else
                app.reader.pointer = 1;
                fr = app.reader;
            end
            ROIs = load(getFileName(app.param,'ROI')).roi;
            TS = load(getFileName(app.param,'TS')).ts{1};
            
            threshSD = app.SpikeSTDEditField.Value;
            framesToCutoff = app.param.framesToDisregard;
            nFrames = 10000;

            fr.pointer = 1;
            frames = fr.getFrames(nFrames,1); 
            frames = frames(:,:,framesToCutoff+1:end);
            frames = single(frames);
            meanFrame = mean(frames,3);
            frames = imgaussfilt(frames,framesXYGaussSigma);

            % dffFrames = (dffFrames-meanFrame)./meanFrame;
            % 
            % dffFrames = dffFrames - smoothdata(dffFrames,3,"gaussian",round(app.fs/2));
            % 
            % dffVar = var(dffFrames,0,3);
            % weightedVar = dffVar.*meanFrame;
            
            numCells = size(TS,2);
            highPassWindowSize = round(app.fs/50);
            for j = 1:numCells
                spikeFrames = zeros(size(frames,[1,2]));
                ts = TS(framesToCutoff+1:end-framesToCutoff,j);
                
                ts = app.convertToDFF(ts);

                spikeIdx = getSpkIx(ts, threshSD,0,highPassWindowSize);
                spikeIdx = spikeIdx+framesToCutoff;
                if length(spikeIdx)<4
                    continue
                end
                for i = 1:length(spikeIdx)
                    idx = spikeIdx(i);
                    spikeFrames = spikeFrames + double(fr.getFramesByIndexes(idx-numEachSide,idx+numEachSide));
                end
                spikeFrames = (spikeFrames - mean(spikeFrames,3));
                spikeFrame = spikeFrames(:,:,numEachSide+1);
                spikeFrame = imgaussfilt(spikeFrame,1.5);
                centroid = round(regionprops(ROIs(:,:,j),'centroid').Centroid);
                ROI = false(size(meanFrame));
                ROI(centroid(2)-1:centroid(2)+1,centroid(1)-1:centroid(1)+1) = 1; %assumes centroid isn't on the edges

                % Flag to check if the ROI was updated
                updated = true;

                ts = getTimeSeriesBinaryMask(frames,ROI);
                currentRatio = calcRatio(spikeFrame,ROI,meanFrame,ts);
                beenChecked = false(size(ROIs));
                ntimesUpdates = 0;
                while updated
                    % currently assumes are pixels are independent random
                    % variables which is definitely problematic and pushes
                    % things to be smaller ROIs

                    updated = false;
                    % Get all border pixels around the current ROI
                    borderPixels = bwperim(~ROI);
                    borderPixels([1,end],:) = false;borderPixels(:,[1,end]) = false;
                    [borderRows, borderCols] = find(borderPixels);
                    
                    % Iterate over each border pixel
                    for k = 1:length(borderRows)
                        if beenChecked(borderRows(k), borderCols(k))
                            continue
                        end
                        beenChecked(borderRows(k), borderCols(k)) = 1;
                        tempROI = ROI;
                        tempROI(borderRows(k), borderCols(k)) = true;  % Add the border pixel
                        
                        % Calculate the new ratio with this potential addition
                        ts = double(getTimeSeriesBinaryMask(frames,tempROI));
                        ts = app.convertToDFF(ts);

                        ts = ts - MySmooth(ts, highPassWindowSize,0);

                        newRatio = calcRatio(spikeFrame,tempROI,meanFrame,ts);
                        
                        % Check if the new ratio is better
                        if newRatio > currentRatio
                            ROI = tempROI;  % Update the ROI
                            currentRatio = newRatio;  % Update the best known ratio
                            updated = true;  % Mark that we updated the ROI
                        end
                    end
                    
                    [rows, cols] = size(ROI);
                    for r = 2:rows-1
                        for c = 2:cols-1
                            filledNeighbors = sum(ROI(r-1:r+1, c-1:c+1),"all");
                            
                            % Fill in the current pixel if 6 or more neighbors are filled
                            if filledNeighbors >= 6
                                ROI(r, c) = 1;
                            end
                        end
                    end

                    if ~updated && ntimesUpdates ==0
                        ROI = imdilate(ROI,ones(3));
                    end
                    currentRatio = calcRatio(spikeFrame,ROI,meanFrame,ts);
                    ntimesUpdates = ntimesUpdates+1;
                end
                ROI = imclose(ROI,ones(3));
                ROI = imopen(ROI,ones(3));
                app.addMaskAsROI(ROI,j);
            end
            app.recalculateTimeSeriesROIs();

            function toOptimize = calcRatio(spikeFrame,ROI,meanFrame,ts)
                toOptimize = -sum(spikeFrame(ROI)); %difference in photons of spike frame with surrounding frames (negative because negative spikes)
                toOptimize = toOptimize/sum(meanFrame(ROI)); %dff of spike
                toOptimize = toOptimize/std(ts); % dff of spike normalized to standard deviation of dff time series
            end
        end

        % Button down function: Tab2
        function Tab2ButtonDown(app, event)
            if ~app.hasChanged
                app.PlotButtonPushed()
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.shiftStimHelper(-1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.shiftStimHelper(1)
        end

        % Value changed function: MotionCheckBox
        function MotionCheckBoxValueChanged(app, event)
            if app.param.doRegistration ~= event.Value
                app.hasChanged = 1;
            end
            app.param.doRegistration = event.Value;
        end

        % Value changed function: BGballradiusEditField
        function BGballradiusEditFieldValueChanged(app, event)
            if app.param.BGRemovalRadius ~= event.Value
                app.hasChanged = 1;
            end
            MovavgFn = getFileName(app.param, 'Movavg');
            
            app.param.BGRemovalRadius = event.Value;
            if exist(MovavgFn, 'File')
                % fileContents = load(MovavgFn);
                app.meanim = tiffreadVolume(MovavgFn);
                if event.Value>0
                    app.meanim = rollingBall(app.meanim,app.param.BGRemovalRadius);
                end
                app.mainAxImage.CData = app.meanim;
                app.updateAxis()
            end
        end

        % Button pushed function: RunMotionOnAllButton
        function RunMotionOnAllButtonPushed(app, event)
            if ~app.MotionCheckBox.Value
                warndlg("check motion correction checkbox");
                return
            end
            bad_indices = [];
            f = waitbar(0,'running motion + BG on all');
            saveFnCurrentParam = getFileName(app.param, 'ROI');
            currentReader = FrameReader(fullfile(app.param.pth,app.param.imtoken));
            currentWidth = currentReader.w;
            currentHeight = currentReader.h;
            clear currentReader;
            for i = 1:length(app.params)
                app.params(i).BGRemovalRadius = app.BGballradiusEditField.Value;
                app.params(i).doRegistration = app.MotionCheckBox.Value;
                app.params(i).neuropilRange = [app.neuropilMin.Value,app.neuropilMax.Value];
                try
                    Pipeline_MotionCorrectRemoveBackground(app.params(i));
                catch ME
                    bad_indices(end+1) = i;
                    warning(ME.identifier,'Error occurred while motion correcting index %d: %s', i, ME.getReport);
                end
                waitbar(i/length(app.params),f,sprintf('Reg + BG on all; %d / %d done',i,length(app.params)));
            end
            delete(f)
            

            if ~isempty(bad_indices)
                warningMessage = sprintf("Motion correction failed for indices: %s", num2str(bad_indices));
                warndlg(warningMessage);
            end
            bad_indices_all = bad_indices;
            bad_indices = [];

            f = waitbar(0,sprintf('Time series extraction; %d / %d done',0,length(app.params)));
            for i = 1:length(app.params)
                if ismember(i,bad_indices_all)
                    continue
                end
                try
                    savefnThisParam = getFileName(app.params(i), 'ROI');
                    if exist(savefnThisParam,'file')~=2 && exist(saveFnCurrentParam,'file')==2 % there isn't a file there and is for current param
                        nextReader = FrameReader(fullfile(app.params(i).pth,app.params(i).imtoken));
                        if currentWidth==nextReader.w && currentHeight==nextReader.h
                            copyfile(saveFnCurrentParam,savefnThisParam);
                            stimCorrespondence = []; %#ok<ADPROPLC>
                            save(savefnThisParam,'stimCorrespondence','-append');
                        end
                    end

                    app.FileDropDown.Value = i;
                    app.FileDropDown_2.Value = i;
                    app.FileDropDownValueChanged();
    
                    Pipeline_getTimeSeries(app.params(i));
                    hasChanged = 0; %#ok<ADPROPLC> 
                    if exist(savefnThisParam,'file')==2
                        imtoken = fullfile(app.params(i).pth, app.params(i).imtoken);
                        save(savefnThisParam,"imtoken","hasChanged",'-append');
                    end
                    save(getFileName(app.params(i), 'TS'),'hasChanged','-append');
                    waitbar(i/length(app.params),f,sprintf('Time series extraction; %d / %d done',i,length(app.params)));
                catch ME
                    bad_indices(end+1) = i;
                    warning(ME.identifier,'Error occurred while processing index %d: %s', i, ME.getReport);
                end
            end
            if ~isempty(bad_indices)
                warningMessage = sprintf("error occured during TS extraction for indices: %s", num2str(bad_indices));
                warndlg(warningMessage);
            end
            bad_indices = [];
        end

        % Button pushed function: DetectlightartifactButton
        function DetectlightartifactButtonPushed(app, event)
            
            fullfn = fullfile(getFileName(app.param, 'OptoData'));
            delete(fullfn);
            [~, ~] = load_stim_series(app.param,[],1);
            app.replot();

        end

        % Button pushed function: TryUnifyROIsButton
        function TryUnifyROIsButtonPushed(app, event)
            choice = questdlg('Are you sure you want to overwrite the other ROIs and calculated time series?', ...
	        'Confirm Overwrite', ...
	        'Yes', 'No', 'No');
            if ~strcmp(choice,'Yes')
                return
            end
            currentReader = FrameReader(fullfile(app.param.pth,app.param.imtoken));
            currentWidth = currentReader.w;
            currentHeight = currentReader.h;
            clear currentReader;
            saveFnCurrentParam = getFileName(app.param, 'ROI');
            for i = 1:length(app.params)
                if i == app.paramIndex
                    continue
                end
                savefnThisParam = getFileName(app.params(i), 'ROI');
                nextReader = FrameReader(fullfile(app.params(i).pth,app.params(i).imtoken));
                if currentWidth==nextReader.w && currentHeight==nextReader.h
                    copyfile(saveFnCurrentParam,savefnThisParam);
                    stimCorrespondence = []; %#ok<ADPROPLC>
                    save(savefnThisParam,'stimCorrespondence','-append');
                end
            end

            for i = 1:length(app.params)
                savefnThisParam = getFileName(app.params(i), 'ROI');

                Pipeline_getTimeSeries(app.params(i));

                hasChanged = 0; %#ok<ADPROPLC> 
                if exist(savefnThisParam,'file')==2
                    imtoken = fullfile(app.params(i).pth, app.params(i).imtoken);
                    save(savefnThisParam,"imtoken","hasChanged",'-append');
                end
            end
        end

        % Value changed function: SplitStimButton
        function SplitStimButtonValueChanged(app, event)
            value = app.SplitStimButton.Value;
            if value == 1
                app.load_stim_correspondence();
                app.saveStimOrder();
                app.Button.Visible="on";
                app.Button_2.Visible="on";
                app.Button.Enable="on";
                app.Button_2.Enable="on";
            else
                app.stimCorrespondence = [];
            end
            app.PlotButtonPushed();
        end

        % Value changed function: ColorSchemeDropDown
        function ColorSchemeDropDownValueChanged(app, event)
            app.tryAddStyleToROIList();
            app.overlayContours();
        end

        % Value changed function: FramesdisregardEditField
        function FramesdisregardEditFieldValueChanged(app, event)
            value = app.FramesdisregardEditField.Value;
            app.param.framesToDisregard = value;
        end

        % Value changed function: HPfreqEditField, lockPlotCheckBox
        function replotCallback(app, event)
            app.replotFn();
        end

        % Button pushed function: OpenROIstatsButton
        function OpenROIstatsButtonPushed(app, event)
            ROIStats(app.params,app.SpikeSTDEditField.Value);
        end

        % Button pushed function: RemoveCurrentButton
        function RemoveCurrentButtonPushed(app, event)
            app.params(app.paramIndex) = [];
            app.FileDropDown.Items(app.paramIndex) = [];
            app.FileDropDown.ItemsData(end) = [];
            app.FileDropDown.Value = max(1,app.paramIndex-1);
            app.FileDropDown_2.Items(app.paramIndex) = [];
            app.FileDropDown_2.ItemsData(end) = [];
            app.FileDropDown_2.Value = max(1,app.paramIndex-1);
            app.FileDropDownValueChanged();
        end

        % Button pushed function: OpenStimPlotsButton
        function OpenStimPlotsButtonPushed(app, event)
            time_series_plots(app.params);
        end

        % Button pushed function: GLMPlotButton
        function GLMPlotButtonPushed(app, event)
            
            app.updateDistanceMat();
            indicies = app.ROIListBoxPlotAx.Value;
            if isempty(indicies)
                indicies = app.ROIListBoxPlotAx.ItemsData;
            end
            indicies = sort(indicies);
            app.param.highPassWindowSize = round(app.fs/50); %for detecting spikes
            GLMPlot(param=app.param,DistanceMat=app.distanceMat,NeuronIndices=indicies,HPFreq=app.HPfreqEditField.Value,nBasisFunctions = 30);
            

            % numBasisFunctions = 30;
            % numTimePoints = 200;
            % t = 1:numTimePoints;
            % basisFunctions = zeros(numTimePoints, numBasisFunctions);
            % mu = 0;
            % sigmaDelta = 2*numTimePoints/numBasisFunctions/(numBasisFunctions+1);
            % for i = 1:numBasisFunctions
            %     sigma = i * sigmaDelta;
            %     basisFunctions(:, i) = exp(-(t - mu).^2 / (2 * sigma^2));
            %     mu = mu+sigma;
            % end
            % for i = 1:numBasisFunctions
            %     basisFunctions(:, i) = basisFunctions(:, i) / sum(basisFunctions(:, i));
            % end
            % GLMPlot(app.param,BasisFunctions=basisFunctions,DistanceMat=app.distanceMat,NeuronIndices=indicies,RegressOwnSpikes=1,UseExpBasis=0,Lambda=-1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.TimeSeriesAxes = uiaxes(app.GridLayout);
            title(app.TimeSeriesAxes, 'Title')
            xlabel(app.TimeSeriesAxes, 'X')
            ylabel(app.TimeSeriesAxes, 'Y')
            zlabel(app.TimeSeriesAxes, 'Z')
            app.MainAxes = uiaxes(app.GridLayout);
            title(app.MainAxes, 'Title')
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            zlabel(app.MainAxes, 'Z')
            app.LoadROIsButton = uibutton(app.GridLayout, 'push');
            app.LoadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButtonPushed, true);
            app.LoadROIsButton.Text = 'Load ROIs';
            app.SmartAddButton = uibutton(app.GridLayout, 'push');
            app.SmartAddButton.ButtonPushedFcn = createCallbackFcn(app, @SmartAddButtonPushed, true);
            app.SmartAddButton.Text = 'Smart Add';
            app.DeleteROIsButton = uibutton(app.GridLayout, 'push');
            app.DeleteROIsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIsButtonPushed, true);
            app.DeleteROIsButton.Text = 'Delete ROIs';
            app.SaveROIsButton = uibutton(app.GridLayout, 'push');
            app.SaveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIsButtonPushed, true);
            app.SaveROIsButton.Text = 'Save ROIs';
            app.ManualAddButton = uibutton(app.GridLayout, 'push');
            app.ManualAddButton.ButtonPushedFcn = createCallbackFcn(app, @ManualAddButtonPushed, true);
            app.ManualAddButton.Text = 'Manual Add';
            app.PSTHButton = uibutton(app.GridLayout, 'push');
            app.PSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHButtonPushed, true);
            app.PSTHButton.Text = 'PSTH';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ManualROIButton = uibutton(app.GridLayout, 'push');
            app.ManualROIButton.ButtonPushedFcn = createCallbackFcn(app, @ManualROIButtonPushed, true);
            app.ManualROIButton.Text = 'Manual ROI';
            app.FileDropDown = uidropdown(app.GridLayout);
            app.FileDropDown.Items = {};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Value = {};
            app.SelectNewFileButton = uibutton(app.GridLayout, 'push');
            app.SelectNewFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNewFileButtonPushed, true);
            app.SelectNewFileButton.Text = 'Select New File';
            app.ROIListListBoxLabel = uilabel(app.GridLayout);
            app.ROIListListBoxLabel.Text = 'ROI List';
            app.ROIListBoxImageAx = uilistbox(app.GridLayout);
            app.ROIListBoxImageAx.Items = {};
            app.ROIListBoxImageAx.Multiselect = 'on';
            app.ROIListBoxImageAx.ValueChangedFcn = createCallbackFcn(app, @ROIListBoxImageAxValueChanged, true);
            app.ROIListBoxImageAx.Value = {};
            app.WhiteLabel = uilabel(app.GridLayout);
            app.WhiteLabel.Text = 'White %';
            app.WhiteEditField = uieditfield(app.GridLayout, 'numeric');
            app.WhiteEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.WhiteEditField.Value = 1;
            app.BlackEditFieldLabel = uilabel(app.GridLayout);
            app.BlackEditFieldLabel.Text = 'Black %';
            app.BlackEditField = uieditfield(app.GridLayout, 'numeric');
            app.BlackEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.BlackEditField.Value = 0.02;
            app.TemporalFilterLabel = uilabel(app.GridLayout);
            app.TemporalFilterLabel.Text = 'T Filter source/BG';
            app.TFiltersourceBGEditField = uieditfield(app.GridLayout, 'numeric');
            app.TFiltersourceBGEditField.Limits = [1 Inf];
            app.TFiltersourceBGEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalFilterEditFieldValueChanged, true);
            app.TFiltersourceBGEditField.Value = 1;
            app.MotionThresholdLabel = uilabel(app.GridLayout);
            app.MotionThresholdLabel.Text = 'Motion Thresh';
            app.MotionThreshEditField = uieditfield(app.GridLayout, 'numeric');
            app.neuropildistLabel = uilabel(app.GridLayout);
            app.neuropildistLabel.Text = 'Neuropil Range';
            app.neuropilMin = uieditfield(app.GridLayout, 'numeric');
            app.neuropilMin.ValueChangedFcn = createCallbackFcn(app, @recalculateTimeSeries, true);
            app.neuropilMin.Value = 6;
            app.neuropilMax = uieditfield(app.GridLayout, 'numeric');
            app.neuropilMax.ValueChangedFcn = createCallbackFcn(app, @recalculateTimeSeries, true);
            app.neuropilMax.Value = 6;
            app.FiltersourceBGEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.FiltersourceBGEditField_2.Limits = [1 Inf];
            app.FiltersourceBGEditField_2.ValueChangedFcn = createCallbackFcn(app, @recalculateTimeSeries, true);
            app.FiltersourceBGEditField_2.Value = 1;
            app.BackgroundImageDropdown = uidropdown(app.GridLayout);
            app.BackgroundImageDropdown.Items = {'Mean Image', 'Correlation', 'Range/STD', 'STDratio', 'dotProdWithBG'};
            app.BackgroundImageDropdown.ItemsData = [1 2 3 4 5];
            app.BackgroundImageDropdown.ValueChangedFcn = createCallbackFcn(app, @BackgroundImageDropdownValueChanged, true);
            app.BackgroundImageDropdown.Value = 1;
            app.lockdaspectCheckBox = uicheckbox(app.GridLayout);
            app.lockdaspectCheckBox.ValueChangedFcn = createCallbackFcn(app, @lockdaspectCheckBoxValueChanged, true);
            app.lockdaspectCheckBox.Text = 'lock daspect';
            app.lockdaspectCheckBox.Value = true;
            app.OptimizespxButton = uibutton(app.GridLayout, 'push');
            app.OptimizespxButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizespxButtonPushed, true);
            app.OptimizespxButton.Text = 'Optimize (spx)';
            app.ManualDrawDropdown = uidropdown(app.GridLayout);
            app.ManualDrawDropdown.Items = {'drawpolygon', 'drawrectangle', 'drawassisted', 'drawellipse', 'drawfreehand'};
            app.ManualDrawDropdown.Value = 'drawpolygon';
            app.SmartROIButton = uibutton(app.GridLayout, 'state');
            app.SmartROIButton.ValueChangedFcn = createCallbackFcn(app, @SmartROIButtonPushed, true);
            app.SmartROIButton.Text = 'Smart ROI';
            app.MotionCheckBox = uicheckbox(app.GridLayout);
            app.MotionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotionCheckBoxValueChanged, true);
            app.MotionCheckBox.Text = '';
            app.MotionCorrectionLabel = uilabel(app.GridLayout);
            app.MotionCorrectionLabel.Text = 'Motion Correction';
            app.BGballradiusEditFieldLabel = uilabel(app.GridLayout);
            app.BGballradiusEditFieldLabel.Text = 'BG ball radius ';
            app.BGballradiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.BGballradiusEditField.Limits = [0 Inf];
            app.BGballradiusEditField.ValueChangedFcn = createCallbackFcn(app, @BGballradiusEditFieldValueChanged, true);
            app.RunMotionOnAllButton = uibutton(app.GridLayout, 'push');
            app.RunMotionOnAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunMotionOnAllButtonPushed, true);
            app.RunMotionOnAllButton.Text = 'Run Motion On All';
            app.TryUnifyROIsButton = uibutton(app.GridLayout, 'push');
            app.TryUnifyROIsButton.ButtonPushedFcn = createCallbackFcn(app, @TryUnifyROIsButtonPushed, true);
            app.TryUnifyROIsButton.Text = 'Try Unify ROIs';
            app.ColorSchemeDropDown = uidropdown(app.GridLayout);
            app.ColorSchemeDropDown.Items = {'Accent | Qualitative', 'Blues | Sequential', 'PuBuGn | Sequential', 'Dark2 | Qualitative', 'BuGn | Sequential', 'PuRd | Sequential', 'Paired | Qualitative', 'BuPu | Sequential', 'Purples | Sequential', 'Pastel1 | Qualitative', 'GnBu | Sequential', 'RdPu | Sequential', 'Pastel2 | Qualitative', 'Greens | Sequential', 'Reds | Sequential', 'RdGy | Diverging', 'Set1 | Qualitative', 'Greys | Sequential', 'YlGn | Sequential', 'Set2 | Qualitative', 'OrRd | Sequential', 'YlGnBu | Sequential', 'Set3 | Qualitative', 'Oranges | Sequential', 'YlOrBr | Sequential', 'PuBu | Sequential', 'YlOrRd | Sequential', 'BrBG | Diverging', 'PiYG | Diverging', 'PRGn | Diverging', 'PuOr | Diverging', 'RdBu | Diverging', 'RdYlBu | Diverging', 'RdYlGn | Diverging', 'Spectral | Diverging'};
            app.ColorSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSchemeDropDownValueChanged, true);
            app.ColorSchemeDropDown.Value = 'Dark2 | Qualitative';
            app.FramesdisregardEditFieldLabel = uilabel(app.GridLayout);
            app.FramesdisregardEditFieldLabel.Text = 'Frames disregard';
            app.FramesdisregardEditField = uieditfield(app.GridLayout, 'numeric');
            app.FramesdisregardEditField.Limits = [0 Inf];
            app.FramesdisregardEditField.ValueChangedFcn = createCallbackFcn(app, @FramesdisregardEditFieldValueChanged, true);
            app.OpenROIstatsButton = uibutton(app.GridLayout, 'push');
            app.OpenROIstatsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenROIstatsButtonPushed, true);
            app.OpenROIstatsButton.Text = 'Open ROI stats';
            app.RemoveCurrentButton = uibutton(app.GridLayout, 'push');
            app.RemoveCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCurrentButtonPushed, true);
            app.RemoveCurrentButton.Text = 'Remove Current';
            app.OpenStimPlotsButton = uibutton(app.GridLayout, 'push');
            app.OpenStimPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenStimPlotsButtonPushed, true);
            app.OpenStimPlotsButton.Text = 'Open Stim Plots';
            app.GLMPlotButton = uibutton(app.GridLayout, 'push');
            app.GLMPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GLMPlotButtonPushed, true);
            app.GLMPlotButton.Text = 'GLM Plot';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.ButtonDownFcn = createCallbackFcn(app, @Tab2ButtonDown, true);
            app.GridLayout2 = uigridlayout(app.Tab2);
            app.BigTimeSeriesMotionAx = uiaxes(app.GridLayout2);
            title(app.BigTimeSeriesMotionAx, 'Motion')
            ylabel(app.BigTimeSeriesMotionAx, 'hi')
            zlabel(app.BigTimeSeriesMotionAx, 'Z')
            app.BigTimeSeriesAx = uiaxes(app.GridLayout2);
            title(app.BigTimeSeriesAx, 'Title')
            xlabel(app.BigTimeSeriesAx, 'X')
            ylabel(app.BigTimeSeriesAx, 'Y')
            zlabel(app.BigTimeSeriesAx, 'Z')
            app.popoutButton = uibutton(app.GridLayout2, 'push');
            app.popoutButton.ButtonPushedFcn = createCallbackFcn(app, @popoutButtonPushed, true);
            app.popoutButton.Text = 'popout';
            app.SeperationLabel = uilabel(app.GridLayout2);
            app.SeperationLabel.Text = 'Seperation:';
            app.SeperationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SeperationEditField.Limits = [0 Inf];
            app.SeperationEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.SeperationEditField.Value = 20;
            app.SpikeSTDEditFieldLabel = uilabel(app.GridLayout2);
            app.SpikeSTDEditFieldLabel.Text = 'Spike STD: ';
            app.SpikeSTDEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpikeSTDEditField.Limits = [0.1 Inf];
            app.SpikeSTDEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.SpikeSTDEditField.Value = 3.5;
            app.smoothEditFieldLabel = uilabel(app.GridLayout2);
            app.smoothEditFieldLabel.Text = 'smooth:';
            app.smoothEditField = uieditfield(app.GridLayout2, 'numeric');
            app.smoothEditField.Limits = [1 Inf];
            app.smoothEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalFilterEditFieldValueChanged, true);
            app.smoothEditField.Value = 1;
            app.flipdffCheckbox = uicheckbox(app.GridLayout2);
            app.flipdffCheckbox.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.flipdffCheckbox.Text = '';
            app.flipdffCheckbox.Value = true;
            app.ROIListListBox_2Label = uilabel(app.GridLayout2);
            app.ROIListListBox_2Label.Text = 'ROI List';
            app.ROIListBoxPlotAx = uilistbox(app.GridLayout2);
            app.ROIListBoxPlotAx.Items = {};
            app.ROIListBoxPlotAx.Multiselect = 'on';
            app.ROIListBoxPlotAx.ValueChangedFcn = createCallbackFcn(app, @ROIListBoxImageAxValueChanged, true);
            app.ROIListBoxPlotAx.Value = {};
            app.FileDropDown_2 = uidropdown(app.GridLayout2);
            app.FileDropDown_2.Items = {};
            app.FileDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown_2.Value = {};
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.Button.Text = '<-';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Visible = 'off';
            app.Button_2.Text = '->';
            app.MotionRegressionDropdown = uidropdown(app.GridLayout2);
            app.MotionRegressionDropdown.Items = {'no motion regress', 'linear regress', 'squared regress'};
            app.MotionRegressionDropdown.ItemsData = [0 1 2];
            app.MotionRegressionDropdown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.MotionRegressionDropdown.Value = 0;
            app.DetectlightartifactButton = uibutton(app.GridLayout2, 'push');
            app.DetectlightartifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectlightartifactButtonPushed, true);
            app.DetectlightartifactButton.Text = 'Detect light artifact';
            app.SplitStimButton = uibutton(app.GridLayout2, 'state');
            app.SplitStimButton.ValueChangedFcn = createCallbackFcn(app, @SplitStimButtonValueChanged, true);
            app.SplitStimButton.Text = 'Split Stim';
            app.HPfreqEditFieldLabel = uilabel(app.GridLayout2);
            app.HPfreqEditFieldLabel.Text = 'HP freq';
            app.HPfreqEditField = uieditfield(app.GridLayout2, 'numeric');
            app.HPfreqEditField.ValueChangedFcn = createCallbackFcn(app, @replotCallback, true);
            app.HPfreqEditField.Value = 0.5;
            app.lockPlotCheckBox = uicheckbox(app.GridLayout2);
            app.lockPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @replotCallback, true);
            app.lockPlotCheckBox.Text = '';
            app.LockplotLabel = uilabel(app.GridLayout2);
            app.LockplotLabel.Text = 'Lock plot: ';
            app.FlipdfFLabel = uilabel(app.GridLayout2);
            app.FlipdfFLabel.Text = 'Flip df/F: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VoltageGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end