classdef getROICorrespondence < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ShiftUwButton             matlab.ui.control.Button
        ErodeeButton              matlab.ui.control.Button
        DilateqButton             matlab.ui.control.Button
        ShiftDsButton             matlab.ui.control.Button
        ShiftRdButton             matlab.ui.control.Button
        ShiftLaButton             matlab.ui.control.Button
        ShowUnviewedCheckBox      matlab.ui.control.CheckBox
        ShowallButton             matlab.ui.control.StateButton
        ZoomCheckBox              matlab.ui.control.CheckBox
        zrangeEditField           matlab.ui.control.NumericEditField
        zrangeEditFieldLabel      matlab.ui.control.Label
        xyrangeEditField          matlab.ui.control.NumericEditField
        xyrangeEditFieldLabel     matlab.ui.control.Label
        BlurbydistEditFieldLabel  matlab.ui.control.Label
        BlurbydistEditField       matlab.ui.control.NumericEditField
        BlurallEditFieldLabel     matlab.ui.control.Label
        BlurallEditField          matlab.ui.control.NumericEditField
        BestZEditField            matlab.ui.control.NumericEditField
        BestZEditFieldLabel       matlab.ui.control.Label
        Panel_2                   matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        ForwardButton             matlab.ui.control.Button
        BackButton                matlab.ui.control.Button
        ROIsListBox               matlab.ui.control.ListBox
        ROIsListBoxLabel          matlab.ui.control.Label
        ThrowtButton              matlab.ui.control.Button
        KeepkButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        widefield % 2d image for widefield
        confocal % 3d volumetric confocol image
        blurredConfocol
        ROIVolume % 3d volume with ROI IDs
        
        isROIGoodArr % boolean array for whether each ROI is good
        seenROIArray % boolean array for whether each ROI has been looked at
        UIAxis1Image
        UIAxis2Image
        currContourConfoc
        currContourWide
        roiIndices
    end

    properties(Dependent, Access = private)
        roiNumber
        roiDex
        nROI
    end

    methods
        function roiNumber = get.roiNumber(app)
            roiNumber = app.roiIndices(app.roiDex);
        end
        function roiDex = get.roiDex(app)
            roiDex = app.ROIsListBox.Value;
        end
        function nROI = get.nROI(app)
            nROI = length(app.isROIGoodArr);
        end
    end
    
    methods (Access = private)
        
        function drawImages(app)
            found = find(app.ROIVolume==max(app.roiNumber));
            if isempty(found)
                app.UIAxis2Image.CData(:) = 0;
                return;
            end
            [x,y,z] = ind2sub(size(app.ROIVolume),found);
            xRange = [min(x) - app.xyrangeEditField.Value, max(x) + app.xyrangeEditField.Value];
            yRange = [min(y) - app.xyrangeEditField.Value, max(y) + app.xyrangeEditField.Value];
            zRange = [max(min(z) - app.zrangeEditField.Value,1), min(max(z) + app.zrangeEditField.Value,size(app.confocal,3))];
            xRange = round(xRange);
            yRange = round(yRange);
            hold(app.UIAxes2,'off')
            imageToShow = sum(app.blurredConfocol(:,:,zRange(1):zRange(2)),3);
            if isgraphics(app.UIAxis2Image)
                app.UIAxis2Image.CData = imageToShow;
            else
                app.UIAxis2Image =  imagesc(app.UIAxes2, imageToShow);
            end
            title(app.UIAxes2,"" + zRange(1) + " - " + zRange(2));
            hold(app.UIAxes2,'on')
            

            if app.ZoomCheckBox.Value
                xlim(app.UIAxes,yRange);
                ylim(app.UIAxes,xRange);
                xlim(app.UIAxes2,yRange);
                ylim(app.UIAxes2,xRange);
                xRange = max(xRange,1); xRange= min(xRange,size(app.widefield,1));
                yRange = max(yRange,1); yRange= min(yRange,size(app.widefield,2));
                clim(app.UIAxes2,prctile(imageToShow(xRange(1):xRange(2),yRange(1):yRange(2)),[0.5,99.5],"all"));
                clim(app.UIAxes,prctile(app.widefield(xRange(1):xRange(2),yRange(1):yRange(2)),[0.5,99.5],"all"));
            else
                clim(app.UIAxes2,prctile(imageToShow(:),[0.1,99.9],"all"));
                clim(app.UIAxes,prctile(app.widefield(:),[0.1,99.9],"all"));
            end

            app.drawContours()
        end
        

        function drawContours(app)

            if app.ShowallButton.Value
                return
            end
            if ~isempty(app.currContourConfoc) && isgraphics(app.currContourConfoc)
                delete(app.currContourConfoc);
            end
            [~,app.currContourConfoc] = contour(app.UIAxes2, max(app.ROIVolume==max(app.roiNumber),[],3), [1 1], 'LineColor', 'r', 'LineWidth', 2);
            hold(app.UIAxes,'on')
            
            if ~isempty(app.currContourWide) && isgraphics(app.currContourWide)
                delete(app.currContourWide);
            end
            [~,app.currContourWide] = contour(app.UIAxes, max(app.ROIVolume==max(app.roiNumber),[],3), [1 1], 'LineColor', 'r', 'LineWidth', 2);
        end

        function updateBlurredConfocalImage(app)
            app.blurredConfocol = app.confocal;
            for i = 1:size(app.confocal, 3)
                blurSigma = abs(i-app.BestZEditField.Value)* app.BlurbydistEditField.Value + app.BlurallEditField.Value;
                if blurSigma>0
                    app.blurredConfocol(:,:,i) = imgaussfilt(app.blurredConfocol(:,:,i),blurSigma);
                else
                    app.blurredConfocol(:,:,i) = app.blurredConfocol(:,:,i);
                end
            end
        end

        function estBestZ(app)
            correlation = zeros(1,size(app.confocal, 3));
            for i = 1:size(app.confocal, 3)
                movingPlane = app.confocal(:, :, i);

                
                % Flatten the planes into vectors and remove NaNs
                fixedVec = app.widefield(:);
                movingVec = movingPlane(:);
                nanVec = isnan(fixedVec) | isnan(movingVec);
                fixedVec(nanVec) = [];
                movingVec(nanVec) = [];
                
                % Calculate correlation
                correlationMatrix = corrcoef(double(fixedVec), double(movingVec));
                
                % Store the correlation coefficient
                correlation(i) = correlationMatrix(1, 2);
            end
            [~,bestZ] = max(correlation);
            app.BestZEditField.Value = bestZ;
        end
        
        function figClose(app,callbackFn)
            volume = app.ROIVolume;
            uniques = app.roiIndices;
            goodArr = app.isROIGoodArr;
            uniques = uniques(goodArr);
            Nroi = length(uniques);
            rois = zeros([size(volume,[1,2]),Nroi]);
            if isempty(uniques)
                keyboard;
                delete(app.UIFigure);
                return
            end
            for i = 1:Nroi
                rois(:,:,i) = max(volume==uniques(i),[],3);
            end
            delete(app.UIFigure);
            callbackFn(rois,uniques)
        end
        
        function shiftHelper(app,amount,dimension)
            vol = app.ROIVolume == app.roiNumber;
            app.ROIVolume(vol) = 0;
            app.ROIVolume(circshift(vol,amount,dimension)) = app.roiNumber;
            app.drawImages()
        end
    end
    
    methods (Static)
        function [numChannels,useSamples] = getNumChannelsTif(fileTif)
            numChannels=1;
            file_info=imfinfo(fileTif);
            useSamples = file_info(1).SamplesPerPixel>1;
            if useSamples
                numChannels = file_info(1).SamplesPerPixel; return
            end
            
            if ~isfield(file_info(1),"ImageDescription")
                numChannels=1;
                return
            end
            a = split(file_info(1).ImageDescription,newline);
            a = a(contains(a,'='));
            a=split(a,'=');
            [row,col] = find(strcmp(a,'channels'));
            if (isempty(row) || isempty(col))
                return
            end
            numChannels= str2double(a(row,col+1));
        end
        function vol = myTiffreadVolume(imPath)
            nChan = getNumChannelsTif(imPath);
            vol = tiffreadVolume(imPath);
            if nChan > 1
                % Create a popup dialog for channel selection
                options = arrayfun(@num2str, 1:nChan, 'UniformOutput', false);
                [~,fn] = fileparts(imPath);
                [selection, ok] = listdlg('PromptString', "Select a channel for " + fn + ':', ...
                                          'SelectionMode', 'single', ...
                                          'ListString', options,...
                                          'ListSize', [500, 150]);
                if ok
                    vol = vol(:, :, selection:nChan:end);
                else
                    error('Channel selection was canceled.');
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imageFile1, imageFile2, ROIFile, callbackFn)
            if nargin<2 || isempty(imageFile1)
                prompt = 'Select image file 1';
                rootDir = getpref(class(app),'lastPath',pwd);
                REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
                imageFile1 = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
                if isfolder(fileparts(imageFile1))
                    setpref(class(app),'lastPath',fileparts(imageFile1))
                end
            end
            if nargin<3 || isempty(imageFile2)
                prompt = 'Select image file 2';
                rootDir = char(fileparts(imageFile1));
                REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
                imageFile2 = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
            end
            if nargin<3 || isempty(ROIFile)
                prompt = 'Select ROI file';
                rootDir = getpref(class(app),'lastPath',pwd);
                REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
                ROIFile = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
                if isfolder(fileparts(ROIFile))
                    setpref(class(app),'lastPath',fileparts(ROIFile))
                end
            end
            if nargin>4
                app.UIFigure.CloseRequestFcn = @(src, event) app.figClose(callbackFn);
            end
            image1 = app.myTiffreadVolume(imageFile1);
            image2 = app.myTiffreadVolume(imageFile2);
            app.ROIVolume = tiffreadVolume(ROIFile);
            if ismatrix(image1)
                app.widefield = image1;
            elseif ismatrix(image2)
                app.widefield = image2;
            end
            if ndims(image1) ==3
                app.confocal = image1;
            elseif ndims(image2)==3
                app.confocal = image2;
            end
            if isempty(app.confocal) || isempty(app.widefield)
                throw("curently only works for 1 2d and 1 3d image")
            end

            uniqueVals = unique(app.ROIVolume);
            uniqueVals(uniqueVals==0) = [];
            app.roiIndices = uniqueVals;
            app.isROIGoodArr = false(1,length(uniqueVals));
            app.seenROIArray = app.isROIGoodArr;
            strings = "ROI # " + uniqueVals;
            app.ROIsListBox.Items = strings;
            app.ROIsListBox.ItemsData = 1:length(uniqueVals);
            imagesc(app.UIAxes,app.widefield);
            axis(app.UIAxes,'image');
            title(app.UIAxes,'');
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            colormap(app.UIAxes,'gray');
            axis(app.UIAxes2,'image');
            title(app.UIAxes2,'');
            xlabel(app.UIAxes2,'');
            ylabel(app.UIAxes2,'');
            colormap(app.UIAxes2,'gray');

            
            if isfield(app.ForwardButton,'Interpreter')
                app.ForwardButton.Interpreter = 'latex';
                app.ForwardButton.Text = 'Forward (\rightarrow)';
                app.BackButton.Interpreter = 'latex';
                app.ForwardButton.Text = 'Back (\leftarrow)';
            end

            app.estBestZ();
            app.updateBlurredConfocalImage();
            app.BestZEditField.Limits = [1,size(app.confocal,3)];
            app.drawImages();
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            app.drawImages();
        end

        % Value changed function: BestZEditField
        function BestZEditFieldValueChanged(app, event)
            app.updateBlurredConfocalImage();
            app.drawImages();
        end

        % Value changed function: BlurbydistEditField
        function BlurbydistEditFieldValueChanged(app, event)
            app.updateBlurredConfocalImage();
            app.drawImages();
        end

        % Value changed function: BlurallEditField
        function BlurallEditFieldValueChanged(app, event)
            app.updateBlurredConfocalImage();
            app.drawImages();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            color = '';  % Initialize an empty color variable
            
            switch key
                case 't'
                    app.isROIGoodArr(app.roiDex) = false;
                    color = 'red';  % Set color for green
                case 'k'
                    app.isROIGoodArr(app.roiDex) = true;
                    color = 'green';  % Set color for red
                case {'rightarrow', 'downarrow'}
                    app.ROIsListBox.Value = mod(max(app.ROIsListBox.Value) , length(app.roiIndices)) + 1;
                    app.drawImages();
                    return
                case {'leftarrow', 'uparrow'}
                    app.ROIsListBox.Value = mod(min(app.ROIsListBox.Value)-2, length(app.roiIndices)) + 1;
                    app.drawImages();
                    return

                case 'w'
                    app.ShiftUwButtonPushed();
                    return;
                case 'a'
                    app.ShiftLaButtonPushed();
                    return;
                case 's'
                    app.ShiftDsButtonPushed();
                    return;
                case 'd'
                    app.ShiftRdButtonPushed();
                    return;
                case 'q'
                    app.DilateqButtonPushed();
                    return;
                case 'e'
                    app.ErodeeButtonPushed();
                    return;
                otherwise
                    return;  % Exit if the key is neither 't' nor 'k'
            end
            
            app.seenROIArray(app.roiDex) = true;
            
            % Apply the selected background color style
            style = uistyle('BackgroundColor', color);
            % removeStyle(app.ROIsListBox);  % Clear any existing styles
            addStyle(app.ROIsListBox, style, 'item', app.roiDex);  % Apply the selected color style
            
            app.ROIsListBox.Value = mod(app.ROIsListBox.Value, length(app.roiIndices)) + 1;
            app.drawImages();
        end

        % Value changed function: ShowallButton
        function ShowallButtonValueChanged(app, event)
            showAll = app.ShowallButton.Value;

            if ~isempty(app.currContourConfoc) && isgraphics(app.currContourConfoc)
                delete(app.currContourConfoc);
                
            end
            if ~isempty(app.currContourWide) && isgraphics(app.currContourWide)
                delete(app.currContourWide);
            end
            if showAll
                app.currContourWide = {};
                app.currContourConfoc = {};
                hold(app.UIAxes,'on')
                hold(app.UIAxes2,'on')
                if app.ShowUnviewedCheckBox.Value
                    validIndices = app.isROIGoodArr | ~app.seenROIArray;
                else
                    validIndices = app.isROIGoodArr;
                end
                [~,app.currContourConfoc] = contour(app.UIAxes2, max(ismember(app.ROIVolume,app.roiIndices(validIndices)),[],3), [1 1], 'LineColor', 'r', 'LineWidth', 2);
                [~,app.currContourWide] = contour(app.UIAxes, max(ismember(app.ROIVolume,app.roiIndices(validIndices)),[],3), [1 1], 'LineColor', 'r', 'LineWidth', 2);
                xlim(app.UIAxes,'auto')
                xlim(app.UIAxes2,'auto')
                ylim(app.UIAxes2,'auto')
                ylim(app.UIAxes,'auto')
                clim(app.UIAxes,'auto')
                clim(app.UIAxes2,'auto')

                app.UIAxes.DataAspectRatio = [1,1,1];
                app.UIAxes2.DataAspectRatio = [1,1,1];
                
                
            else
                app.drawContours();
            end
        end

        % Button pushed function: ShiftLaButton
        function ShiftLaButtonPushed(app, event)
            app.shiftHelper(-1,2)
        end

        % Button pushed function: ShiftRdButton
        function ShiftRdButtonPushed(app, event)
            app.shiftHelper(1,2)
        end

        % Button pushed function: ShiftUwButton
        function ShiftUwButtonPushed(app, event)
            app.shiftHelper(-1,1)
        end

        % Button pushed function: ShiftDsButton
        function ShiftDsButtonPushed(app, event)
            app.shiftHelper(1,1)
        end

        % Button pushed function: ErodeeButton
        function ErodeeButtonPushed(app, event)
            vol = app.ROIVolume == app.roiNumber;
            app.ROIVolume(vol) = 0;
            app.ROIVolume(imerode(vol,strel("disk",1))) = app.roiNumber;

            
            % Find the centroid of the volume
            stats = regionprops(vol, 'Centroid');
            centroid = round(median(cat(1,stats.Centroid),1)); % Round to get discrete index
            app.ROIVolume(centroid(2), centroid(1), centroid(3)) = app.roiNumber; % Assuming 3D indexing
            app.drawImages()
        end

        % Button pushed function: DilateqButton
        function DilateqButtonPushed(app, event)
            vol = app.ROIVolume == app.roiNumber;
            app.ROIVolume(vol) = 0;
            app.ROIVolume(imdilate(vol,strel("disk",1))) = app.roiNumber;
            app.drawImages()
        end

        % Value changed function: zrangeEditField
        function zrangeEditFieldValueChanged(app, event)
            app.updateBlurredConfocalImage();
            app.drawImages();
            
        end

        % Value changed function: xyrangeEditField
        function xyrangeEditFieldValueChanged(app, event)
            app.updateBlurredConfocalImage();
            app.drawImages();
        end

        % Value changed function: ShowUnviewedCheckBox
        function ShowUnviewedCheckBoxValueChanged(app, event)
            app.ShowallButtonValueChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.KeepkButton = uibutton(app.GridLayout, 'push');
            app.KeepkButton.Text = 'Keep (k)';
            app.ThrowtButton = uibutton(app.GridLayout, 'push');
            app.ThrowtButton.Text = 'Throw (t)';
            app.ROIsListBoxLabel = uilabel(app.GridLayout);
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.GridLayout);
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {'Item 1'};
            app.BackButton = uibutton(app.GridLayout, 'push');
            app.BackButton.Text = 'Back (<-)';
            app.ForwardButton = uibutton(app.GridLayout, 'push');
            app.ForwardButton.Text = 'Forward (->)';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.BestZEditFieldLabel = uilabel(app.GridLayout);
            app.BestZEditFieldLabel.Text = 'Best Z';
            app.BestZEditField = uieditfield(app.GridLayout, 'numeric');
            app.BestZEditField.ValueChangedFcn = createCallbackFcn(app, @BestZEditFieldValueChanged, true);
            app.BlurallEditField = uieditfield(app.GridLayout, 'numeric');
            app.BlurallEditField.Limits = [0 Inf];
            app.BlurallEditField.ValueChangedFcn = createCallbackFcn(app, @BlurallEditFieldValueChanged, true);
            app.BlurallEditFieldLabel = uilabel(app.GridLayout);
            app.BlurallEditFieldLabel.Text = 'Blur all';
            app.BlurbydistEditField = uieditfield(app.GridLayout, 'numeric');
            app.BlurbydistEditField.ValueChangedFcn = createCallbackFcn(app, @BlurbydistEditFieldValueChanged, true);
            app.BlurbydistEditField.Value = 0.4;
            app.BlurbydistEditFieldLabel = uilabel(app.GridLayout);
            app.BlurbydistEditFieldLabel.Text = 'Blur by dist';
            app.xyrangeEditFieldLabel = uilabel(app.GridLayout);
            app.xyrangeEditFieldLabel.Text = 'xy range';
            app.xyrangeEditField = uieditfield(app.GridLayout, 'numeric');
            app.xyrangeEditField.Limits = [0 Inf];
            app.xyrangeEditField.ValueChangedFcn = createCallbackFcn(app, @xyrangeEditFieldValueChanged, true);
            app.xyrangeEditField.Value = 100;
            app.zrangeEditFieldLabel = uilabel(app.GridLayout);
            app.zrangeEditFieldLabel.Text = 'z range';
            app.zrangeEditField = uieditfield(app.GridLayout, 'numeric');
            app.zrangeEditField.Limits = [0 Inf];
            app.zrangeEditField.ValueChangedFcn = createCallbackFcn(app, @zrangeEditFieldValueChanged, true);
            app.zrangeEditField.Value = 1;
            app.ZoomCheckBox = uicheckbox(app.GridLayout);
            app.ZoomCheckBox.Text = 'Zoom';
            app.ZoomCheckBox.Value = true;
            app.ShowallButton = uibutton(app.GridLayout, 'state');
            app.ShowallButton.ValueChangedFcn = createCallbackFcn(app, @ShowallButtonValueChanged, true);
            app.ShowallButton.Text = 'Show all';
            app.ShowUnviewedCheckBox = uicheckbox(app.GridLayout);
            app.ShowUnviewedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowUnviewedCheckBoxValueChanged, true);
            app.ShowUnviewedCheckBox.Text = 'Show Unviewed';
            app.ShiftLaButton = uibutton(app.GridLayout, 'push');
            app.ShiftLaButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftLaButtonPushed, true);
            app.ShiftLaButton.Text = 'Shift L (a)';
            app.ShiftRdButton = uibutton(app.GridLayout, 'push');
            app.ShiftRdButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftRdButtonPushed, true);
            app.ShiftRdButton.Text = 'Shift R (d)';
            app.ShiftDsButton = uibutton(app.GridLayout, 'push');
            app.ShiftDsButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftDsButtonPushed, true);
            app.ShiftDsButton.Text = 'Shift D (s)';
            app.DilateqButton = uibutton(app.GridLayout, 'push');
            app.DilateqButton.ButtonPushedFcn = createCallbackFcn(app, @DilateqButtonPushed, true);
            app.DilateqButton.Text = 'Dilate (q)';
            app.ErodeeButton = uibutton(app.GridLayout, 'push');
            app.ErodeeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeeButtonPushed, true);
            app.ErodeeButton.Text = 'Erode (e)';
            app.ShiftUwButton = uibutton(app.GridLayout, 'push');
            app.ShiftUwButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftUwButtonPushed, true);
            app.ShiftUwButton.Text = 'Shift U (w)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getROICorrespondence(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end