classdef selectROI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        DisplayDropDownLabel  matlab.ui.control.Label
        DisplayDropDown       matlab.ui.control.DropDown
        flattenSlider         matlab.ui.control.Slider
        flattenSliderLabel    matlab.ui.control.Label
        maxSlider             matlab.ui.control.Slider
        maxSliderLabel        matlab.ui.control.Label
        minSlider             matlab.ui.control.Slider
        minSliderLabel        matlab.ui.control.Label
        deletelastroiButton   matlab.ui.control.Button
        resetgrayscaleButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        origIm
        imageHolder
        flattenedOriginalImage % Description
        ROIs
        ROImask
        drawroi
        hIm
        passRoiMaskCloseFn
        mov
    end
    
    methods (Access = private)
        
        function chooseROI(app)
            app.hIm.CData = app.flattenedOriginalImage./(1 + 0.5.*single(app.ROImask));
            ROI = app.drawroi(app.UIAxes, 'DrawingArea', [0,0,width(app.hIm.CData),height(app.hIm.CData)]);
            app.ROIs{end+1} = createMask(ROI);
            bw = createMask(ROI);
            app.ROImask = app.ROImask | bw;
            app.hIm.CData = app.flattenedOriginalImage./(1 + 0.5.*single(app.ROImask));
            delete(ROI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ogData, roiFunc, passOutFn, Title)
            if ~exist("Title","var"); Title = "Title"; end
            title(app.UIAxes,Title);
            
            if ismatrix(ogData) && size(ogData,3)==1
                app.origIm =ogData;
            elseif isfield(ogData,'meanim')
                app.origIm =ogData.meanim;
            elseif ndims(ogData)==3 && size(ogData,3)>20
                app.origIm =mean(ogData,3);
                app.mov = double(ogData);
                app.DisplayDropDown.Visible=1;
            elseif ismatrix(ogData) && size(ogData,3)<20
                app.origIm =ogData(:,:,1);
            end
            app.origIm = single(app.origIm); app.origIm = app.origIm-min(app.origIm,[],'all'); app.origIm = app.origIm./max(app.origIm,[],'all');
            app.flattenedOriginalImage = app.origIm;
             
            app.ROImask = zeros(size(app.flattenedOriginalImage));
            
            absMax = double(max(app.flattenedOriginalImage,[],'all')); absMin = double(min(app.flattenedOriginalImage,[],'all'));
            
            cmax = double(prctile(app.flattenedOriginalImage,99.9,'all')); app.maxSlider.Value = (cmax - absMin)./(absMax - absMin);
            cmin = double(prctile(app.flattenedOriginalImage,0.1,'all')); app.minSlider.Value = (cmin - absMin)./(absMax - absMin);
            app.maxSlider.Limits = [absMin, absMax]; app.minSlider.Limits = [absMin, absMax];
            
            app.hIm = imagesc(app.UIAxes,app.flattenedOriginalImage);axis(app.UIAxes,'image');axis(app.UIAxes,'tight');axis(app.UIAxes,'off');colormap(app.UIAxes,gray);
            app.hIm.ButtonDownFcn = @(source,event) app.UIAxesButtonDown(event);
            app.ROIs = {};
            if exist("roiFunc",'var')
                app.drawroi = roiFunc;
            else
                app.drawroi = @drawrectangle;
            end
            app.passRoiMaskCloseFn = passOutFn;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.passRoiMaskCloseFn(logical(app.ROImask),app.ROIs);
            catch
            end
            delete(app);
%             delete(app)
        end

        % Value changed function: maxSlider, minSlider
        function minSliderValueChanged(app, event)
            minVal = app.minSlider.Value;
            maxVal=app.maxSlider.Value;
            absMax = double(max(app.flattenedOriginalImage,[],'all')); absMin = double(min(app.flattenedOriginalImage,[],'all'));
            minVal = min(minVal, maxVal); maxVal = max(minVal, maxVal);
            app.minSlider.Value = minVal; app.maxSlider.Value = maxVal;
            cmin = absMin + minVal*(absMax - absMin);
            cmax = absMin + maxVal*(absMax - absMin);
            clim(app.UIAxes,[cmin,cmax]);
        end

        % Value changed function: flattenSlider
        function flattenSliderValueChanged(app, event)
            if app.flattenSlider.Value > 0  
                app.flattenedOriginalImage = imflatfield(app.origIm, 1000.*(1 - app.flattenSlider.Value) + 1);
            else% no flattening
                app.flattenedOriginalImage = single(app.origIm);
            end
            app.resetgrayscaleButtonPushed();
        end

        % Button pushed function: resetgrayscaleButton
        function resetgrayscaleButtonPushed(app, event)
            
            absMax = double(max(app.flattenedOriginalImage,[],'all')); absMin = double(min(app.flattenedOriginalImage,[],'all'));
            cmax = double(prctile(app.flattenedOriginalImage,99.9,'all')); app.maxSlider.Value = (cmax - absMin)./(absMax - absMin);
            cmin = double(prctile(app.flattenedOriginalImage,0.1,'all')); app.minSlider.Value = (cmin - absMin)./(absMax - absMin);
            app.minSliderValueChanged();
        end

        % Button pushed function: deletelastroiButton
        function deletelastroiButtonPushed(app, event)
            
            if numel(app.ROIs) > 0
                app.ROImask(app.ROIs{end}) = 0;
                app.ROIs(end) = [];
                app.hIm.CData = app.flattenedOriginalImage./(1 + 0.5.*single(app.ROImask));
            end
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            if isfield(app.imageHolder,app.DisplayDropDown.Value)
                app.origIm = getfield(app.imageHolder,app.DisplayDropDown.Value);
            elseif ~isempty(app.mov) && ndims(app.mov)==3 && size(app.mov,3)>20
                if app.DisplayDropDown.Value == "Mean"
                    app.origIm = mean(app.mov,3);
                elseif app.DisplayDropDown.Value == "stdRange"
                    app.origIm = getRangeRelativeToNoise(app.mov);
                elseif app.DisplayDropDown.Value == "correlation"
                    app.origIm(:) = 0;
                elseif app.DisplayDropDown.Value == "stdRatio"
                    app.origIm = getSTDRatio(app.mov);
                end
                app.imageHolder = setfield(app.imageHolder,app.DisplayDropDown.Value,app.origIm);
            end
            app.hIm.CData = app.origIm;
            app.flattenedOriginalImage = app.origIm;
            app.resetgrayscaleButtonPushed();
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.chooseROI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.resetgrayscaleButton = uibutton(app.GridLayout, 'push');
            app.resetgrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @resetgrayscaleButtonPushed, true);
            app.resetgrayscaleButton.Text = 'reset grayscale';
            app.deletelastroiButton = uibutton(app.GridLayout, 'push');
            app.deletelastroiButton.ButtonPushedFcn = createCallbackFcn(app, @deletelastroiButtonPushed, true);
            app.deletelastroiButton.Text = 'delete last roi';
            app.minSliderLabel = uilabel(app.GridLayout);
            app.minSliderLabel.Text = 'min';
            app.minSlider = uislider(app.GridLayout);
            app.minSlider.Limits = [-100000000 1000000000];
            app.minSlider.MajorTicks = [];
            app.minSlider.ValueChangedFcn = createCallbackFcn(app, @minSliderValueChanged, true);
            app.minSlider.MinorTicks = [];
            app.maxSliderLabel = uilabel(app.GridLayout);
            app.maxSliderLabel.Text = 'max';
            app.maxSlider = uislider(app.GridLayout);
            app.maxSlider.Limits = [-100000000 1000000000];
            app.maxSlider.MajorTicks = [];
            app.maxSlider.ValueChangedFcn = createCallbackFcn(app, @minSliderValueChanged, true);
            app.maxSlider.MinorTicks = [];
            app.flattenSliderLabel = uilabel(app.GridLayout);
            app.flattenSliderLabel.Text = 'flatten';
            app.flattenSlider = uislider(app.GridLayout);
            app.flattenSlider.Limits = [0 1];
            app.flattenSlider.MajorTicks = [];
            app.flattenSlider.ValueChangedFcn = createCallbackFcn(app, @flattenSliderValueChanged, true);
            app.flattenSlider.MinorTicks = [];
            app.DisplayDropDown = uidropdown(app.GridLayout);
            app.DisplayDropDown.Items = {'Mean', 'stdRange', 'correlation', 'stdRatio'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Visible = 'off';
            app.DisplayDropDown.Value = 'Mean';
            app.DisplayDropDownLabel = uilabel(app.GridLayout);
            app.DisplayDropDownLabel.Visible = 'off';
            app.DisplayDropDownLabel.Text = 'Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectROI2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end