classdef time_series_plots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_4                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        tSmoothEdit                     matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        xySmoothEdit                    matlab.ui.control.NumericEditField
        xyEditFieldLabel                matlab.ui.control.Label
        PlaybackspeedEdit               matlab.ui.control.NumericEditField
        speedLabel                      matlab.ui.control.Label
        MaxDeltaEdit                    matlab.ui.control.NumericEditField
        DeltarangeLabel                 matlab.ui.control.Label
        maxBackgroundEdit               matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        SmoothingLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ImagerangeLabel                 matlab.ui.control.Label
        minBackgroundEdit               matlab.ui.control.NumericEditField
        PlayButton                      matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        UIAxes4                         matlab.ui.control.UIAxes
        GridLayout                      matlab.ui.container.GridLayout
        QuantilesEditFieldLabel         matlab.ui.control.Label
        QuantilesEditField              matlab.ui.control.NumericEditField
        SmoothingEditField              matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel         matlab.ui.control.Label
        LockPlotCheckBox                matlab.ui.control.CheckBox
        badframesEditField              matlab.ui.control.NumericEditField
        badframesEditFieldLabel         matlab.ui.control.Label
        FileLabel                       matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        ColorSchemeDropDown             matlab.ui.control.DropDown
        ColorSchemeDropDownLabel        matlab.ui.control.Label
        HPfreqHzEditField               matlab.ui.control.NumericEditField
        HPfreqHzEditFieldLabel          matlab.ui.control.Label
        GroupSepEditFieldLabel          matlab.ui.control.Label
        GroupSepEditField               matlab.ui.control.NumericEditField
        PlotGroupsListBox               matlab.ui.control.ListBox
        PlotGroupsListBoxLabel          matlab.ui.control.Label
        GroupsEditField                 matlab.ui.control.NumericEditField
        GroupsEditFieldLabel            matlab.ui.control.Label
        GenerateMoviesslowButton        matlab.ui.control.Button
        RegressandDropDown              matlab.ui.control.DropDown
        RegressandDropDownLabel         matlab.ui.control.Label
        RegressagainstDropDown          matlab.ui.control.DropDown
        RegressagainstDropDownLabel     matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        xmaxEditField                   matlab.ui.control.NumericEditField
        regressandRangeEditFieldMax     matlab.ui.control.NumericEditField
        regressandRangeEditFieldLabel_3  matlab.ui.control.Label
        regressandRangeEditFieldMin     matlab.ui.control.NumericEditField
        predictionrangeEditFieldLabel   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        regressorRangeEditFieldmax      matlab.ui.control.NumericEditField
        regressorRangeEditFieldLabel_2  matlab.ui.control.Label
        regressorRangeEditFieldmin      matlab.ui.control.NumericEditField
        predictorrangeEditFieldLabel    matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        ThingsToPlotListBox             matlab.ui.control.ListBox
        DimensionPanel                  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ApplyindividualCheckBox         matlab.ui.control.CheckBox
        DimensionsToPlotListBox         matlab.ui.control.ListBox
        DimensionsToPlotListBoxLabel    matlab.ui.control.Label
        ThingstoplotListBoxLabel        matlab.ui.control.Label
        InvertCheckBox                  matlab.ui.control.CheckBox
        SeparationEditFieldLabel        matlab.ui.control.Label
        SeparationEditField             matlab.ui.control.NumericEditField
        QuantSepEditFieldLabel          matlab.ui.control.Label
        QuantSepEditField               matlab.ui.control.NumericEditField
        SelectNewButton                 matlab.ui.control.Button
        NeuronsListBox                  matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        CorrelationTab                  matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        PCAspaceTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Dimension2DropDown              matlab.ui.control.DropDown
        Dimension2DropDownLabel         matlab.ui.control.Label
        Dimension1DropDown              matlab.ui.control.DropDown
        Dimension1DropDownLabel         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        ROImapTab                       matlab.ui.container.Tab
        roiMapAxes                      matlab.ui.control.UIAxes
        NeuronsListBoxLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ParamsArrayPrivate
        stimAlignedData %(window_length,num_windows,num_neurons);
        patternIndicies
        tmAligned
        fs
        pcaScores
        plsScores
        movieFig
        sumOfFrames
        movieIm
        framesModified
        background
        stimOffset
        windowIdxData
        dmdCorrespondence
        dmdInfo
        roiHandles
        region
        lpFilt
    end

   properties(Dependent, Access = private)
        ParamsArray
        param
        rangeRegressand
        rangeRegressandIdx
        rangeRegressor
        rangeRegressorIdx
   end

    properties (Constant,Access=private)
      Average=1
      PCs=2
      QuantileIdx = 5
      QuantileRegress=6
      Ridge=7
      Lasso=8
      ElasticNet=9
      PLSs=10
      PLSBETA=13
      PLSVIP=14
      CorrelationMat = 15
   end

    methods
        function value = get.param(app)
            value = app.ParamsArray(app.FileDropDown.Value);
        end

        function set.param(app, newParam)
            newFields = setdiff(fieldnames(newParam),fieldnames(app.ParamsArray));
                
            % Add the missing fields to app.params and initialize them to []
            for i = 1:length(newFields)
                [app.ParamsArrayPrivate.(newFields{i})] = deal([]);
            end
            app.ParamsArrayPrivate(app.FileDropDown.Value) = newParam;
        end
        function value = get.rangeRegressand(app)
            value = [app.regressandRangeEditFieldMin.Value, app.regressandRangeEditFieldMax.Value];
        end
        function value = get.rangeRegressandIdx(app)
            minVal = find(app.tmAligned>app.rangeRegressand(1),1);
            maxVal = find(app.tmAligned<app.rangeRegressand(2),1,'last');
            value = minVal:maxVal;
        end
        function value = get.rangeRegressor(app)
            value = [app.regressorRangeEditFieldmin.Value,app.regressorRangeEditFieldmax.Value];
        end
        function value = get.windowIdxData(app)
            if isempty(app.windowIdxData)
                app.get_window_idx_data();
            end
            value = app.windowIdxData;
        end
        
        function set.ParamsArray(app,val)
            app.ParamsArrayPrivate = val;
            app.FileDropDown.Items = fullfile([app.ParamsArray.pth], [app.ParamsArray.imtoken]);
            app.FileDropDown.ItemsData = 1:length(app.ParamsArray);
        end
        function ParamsArray = get.ParamsArray(app)
            ParamsArray = app.ParamsArrayPrivate;
        end

    end
    methods (Access = private)      
        function separationQuantile = getQuantileSeparationVec(app,quantiles)
            if nargin<2
                quantiles =app.QuantilesEditField.Value;
            end
            separationQuantile = (0:quantiles-1)*app.QuantSepEditField.Value;
        end

        function updateROIList(app)
            
            roiFilefn = getFileName(app.param, 'ROI');
            if exist(roiFilefn,'file')~=2
                return
            end
            roiFile = load(roiFilefn);

            fullfnDMD = getFileName(app.param, 'DMDPatternInfo');
            if exist(fullfnDMD,'file')==2
                app.dmdInfo = load(fullfnDMD);
            end
            if isfield(roiFile,'region')
                app.region = roiFile.region;
            end

            Nroi = size(roiFile.roi, 3);

            optoFile = getFileName(app.param, 'OptoData');
            if exist(optoFile,"file")
                optoInfo = load(optoFile);
                if isfield(optoInfo,"indicies")
                    maxPatternDex = max(optoInfo.indicies,[],"all");
                    app.GroupsEditField.Value = maxPatternDex;
                    app.GroupsEditField.Editable="off";
                    app.patternIndicies = optoInfo.indicies;
                    

                    % Initialize an empty cell array for the groups
                    groups = cell(1, maxPatternDex);
                    % Fill the cell array with group names up to the specified value
                    for i = 1:maxPatternDex
                        groups{i} = sprintf('Group %d', i);
                    end
                
                    % Set the Items of the PlotGroupsListBox to the generated groups
                    app.PlotGroupsListBox.Items = groups;
                    % Set the ItemsData of the PlotGroupsListBox to a vector from 1 to value
                    app.PlotGroupsListBox.ItemsData = 1:maxPatternDex;
                    if ~isfield(roiFile,'stimCorrespondence') || isempty(app.dmdInfo) || isempty(app.region) || any(app.region==-1)
                        app.ROImapTab.Parent = [];
                    else
                        app.dmdCorrespondence = roiFile.stimCorrespondence;
                        app.ROImapTab.Parent = app.TabGroup;
                    end
                end
            else
                app.dmdCorrespondence = [];
                app.ROImapTab.Parent = [];
            end


            str = cell(Nroi,1);
            for i=1:Nroi
                str{i} = ['ROI # ' num2str(i)];
            end
            set(app.NeuronsListBox, 'Items', str);
            set(app.NeuronsListBox, 'ItemsData', 1:length(str));
            str = [{'Self'}; str]; %% preppend here
            set(app.RegressagainstDropDown, 'Items',str);
            set(app.RegressagainstDropDown, 'ItemsData', 1:length(str));

            app.lpFilt=[];
            app.updateStimAlignedData();
            app.updateROIMap();

            % dat = load("E:\Synmap\responseAmp",'dat').dat;
            % newDat = struct();
            % newDat.name = fullfile(app.param.pth,app.param.imtoken);
            % newDat.dat = app.getRegressandHelper(@mean);
            % dat(strcmp([dat.name],fullfile(app.param.pth,app.param.imtoken))) = [];
            % dat = [dat;newDat];
            % save("E:\Synmap\responseAmp",'dat');
        end

        function updateFileList(app)
            app.updateROIList();
        end

        function plotPLS(app) 
            app.plotPLSNew();
            return;
            if ~ismember([app.PLSs,app.PLSBETA,app.PLSVIP],app.ThingsToPlotListBox.Value)
                return
            end

            neuronIdxs = sort(app.NeuronsListBox.Value);
            numNeurons = length(neuronIdxs);

            % Get the regressand
            regressand = app.getRegressand();

            % Get the time points in the range
            minIdx = find(app.tmAligned > app.regressorRangeEditFieldmin.Value, 1);
            maxIdx = find(app.tmAligned < app.regressorRangeEditFieldmax.Value, 1, 'last');
            timePointsRange = minIdx:maxIdx;
            separationVec = app.getSeperationVec();
            dims = app.DimensionsToPlotListBox.Value;
            % Iterate over neurons
            for i = 1:numNeurons
                neuronData = squeeze(app.stimAlignedData(timePointsRange,:,neuronIdxs(i)));
                dataMin = min(mean(neuronData,2));
                dataMax = max(mean(neuronData,2));
                % Apply PLS
                numDims = max([dims,app.Dimension1DropDown.Value,app.Dimension2DropDown.Value]);
                [XL,YL,XS,YS,BETA,PCTVAR,MSE,stats] = plsregress(neuronData', regressand(:,neuronIdxs(i)), numDims); %#ok<ASGLU> % Change 1 to the number of components you want
                app.plsScores = YS;
                XL = rescale(XL,dataMin,dataMax);
                if ismember(app.PLSs,app.ThingsToPlotListBox.Value)
                    XL(:,1) = XL(:,1)-mean(XL(:,1));
                    plot(app.UIAxes, app.tmAligned(timePointsRange), XL(:,dims) + separationVec(i));
                end
                if ismember(app.PLSBETA,app.ThingsToPlotListBox.Value)
                    BETA(2:end) = rescale(BETA(2:end),dataMin,dataMax);
                    BETA(2:end) = BETA(2:end) - mean(BETA(2:end));
                    plot(app.UIAxes, app.tmAligned(timePointsRange), BETA(2:end) + separationVec(i));
                end
                if ismember(app.PLSVIP,app.ThingsToPlotListBox.Value)
                    W0 = stats.W ./ sqrt(sum(stats.W.^2,1));
                    p = size(XL,1);
                    sumSq = sum(XS.^2,1).*sum(YL.^2,1);
                    vipScore = sqrt(p* sum(sumSq.*(W0.^2),2) ./ sum(sumSq,2));
                    plot(app.UIAxes, app.tmAligned(timePointsRange), vipScore + separationVec(i));
                end
                text(app.UIAxes, app.tmAligned(maxIdx), separationVec(i), sprintf('RMSE: %.2f', sqrt(MSE(1,2))), 'FontSize', 12);
            end
        end
        

        function plotPLSNew(app) 
            if ~ismember([app.PLSs,app.PLSBETA,app.PLSVIP],app.ThingsToPlotListBox.Value)
                return
            end

            neuronIdxs = sort(app.NeuronsListBox.Value);
            numNeurons = length(neuronIdxs);

            % Get the regressand
            regressand = app.getRegressand();

            % Get the time points in the range
            minIdx = find(app.tmAligned > app.regressorRangeEditFieldmin.Value, 1);
            maxIdx = find(app.tmAligned < app.regressorRangeEditFieldmax.Value, 1, 'last');
            timePointsRange = minIdx:maxIdx;
            windowLength = length(timePointsRange);
            separation = app.getSeperationVec();
            dimsToPlot = app.DimensionsToPlotListBox.Value;
            numDimsToRunOn = max([dimsToPlot,app.Dimension1DropDown.Value,app.Dimension2DropDown.Value]);
            if app.InvertCheckBox.Value>0
                invertMult = -1;
            else
                invertMult = 1;
            end
            % Iterate over neurons
            if app.ApplyindividualCheckBox.Value
                dataMin = min(app.stimAlignedData(timePointsRange,:,neuronIdxs),[],'all');
                dataMax = max(app.stimAlignedData(timePointsRange,:,neuronIdxs),[],'all');
                for i = 1:numNeurons
                    neuronData = squeeze(app.stimAlignedData(timePointsRange,:,neuronIdxs(i)));
                    plsPlotHelper(neuronData);
                end
            else
                dataMin = min(app.stimAlignedData(timePointsRange,:,neuronIdxs),[],'all');
                dataMax = max(app.stimAlignedData(timePointsRange,:,neuronIdxs),[],'all');
                i = 1:length(neuronIdxs);
                neuronData = reshape(permute(app.stimAlignedData(timePointsRange,:,neuronIdxs),[1,3,2]),windowLength*numNeurons,[]);
                scores = plsPlotHelper(neuronData);
                app.plsScores = scores;
            end
            
            function score = plsPlotHelper(data)
                % apply PCA across windows
                [coeff,YL,score,YS,BETA,PCTVAR,MSE,stats] = plsregress(data', mean(regressand(:,neuronIdxs(i)),2), numDimsToRunOn); %#ok<ASGLU> % Change 1 to the number of components you want
                if ismember(app.PLSs,app.ThingsToPlotListBox.Value)
                    coeff = rescale(coeff, dataMin, dataMax); %rescale so can plot with data
                    coeff = coeff - mean(coeff,1);
                    coeff=coeff*invertMult;
                    if app.ApplyindividualCheckBox.Value
                        toPlot = coeff + separation(i);
                    else
                        toPlot = reshape(coeff,windowLength,numNeurons,[]);
                        toPlot = toPlot + reshape(separation,[1 numel(separation),1]);
                        toPlot = permute(toPlot,[1,3,2]);
                    end
                    plot(app.UIAxes,app.tmAligned(timePointsRange),squeeze(toPlot(:,dimsToPlot,:)));
                end
                if ismember(app.PLSBETA,app.ThingsToPlotListBox.Value)
                    BETA(2:end) = rescale(BETA(2:end),dataMin,dataMax);
                    BETA(2:end) = BETA(2:end) - mean(BETA(2:end));
                    if app.ApplyindividualCheckBox.Value
                        toPlot = BETA(2:end) + separation(i);
                    else
                        toPlot = reshape(BETA(2:end),windowLength,numNeurons,[]);
                        toPlot = toPlot + reshape(separation,[1 numel(separation),1]);
                        toPlot = permute(toPlot,[1,3,2]);
                    end
                    plot(app.UIAxes,app.tmAligned(timePointsRange),squeeze(toPlot));
                end
                if ismember(app.PLSVIP,app.ThingsToPlotListBox.Value) && app.ApplyindividualCheckBox.Value
                    W0 = stats.W ./ sqrt(sum(stats.W.^2,1));
                    p = size(coeff,1);
                    sumSq = sum(score.^2,1).*sum(YL.^2,1);
                    vipScore = sqrt(p* sum(sumSq.*(W0.^2),2) ./ sum(sumSq,2));
                    plot(app.UIAxes, app.tmAligned(timePointsRange), vipScore + separation(i));
                end

                if app.ApplyindividualCheckBox.Value
                    text(app.UIAxes, app.tmAligned(maxIdx), separation(i), sprintf('RMSE: %.2f', sqrt(MSE(1,2))), 'FontSize', 12);
                end               
            end
        end

        function plotQuantilesHelperSorted(app)

            quantiles = app.QuantilesEditField.Value;

            if ~ismember(app.QuantileRegress,app.ThingsToPlotListBox.Value)
                return;
            end

            neuronIndicies = sort(app.NeuronsListBox.Value);
            numNeurons = length(neuronIndicies);
            numWindows = size(app.stimAlignedData,2);
            numElements = round(numWindows/quantiles);
            toPlot = zeros(size(app.stimAlignedData,1),quantiles,numNeurons);
            
            % Using 'getRegressand' function
            regressands = app.getRegressand();  % Size should be [numWindows, numNeurons]
            
            % For each neuron, sort windows by the regressand
            for neuron = 1:numNeurons
                [~, sortedIndicies] = sort(regressands(:, neuronIndicies(neuron)));
                startDex = 1;
                for i = 1:quantiles
                    endDex = min(startDex+numElements-1,numWindows);
                    sortedIndexRange = sortedIndicies(startDex:endDex);
                    toPlot(:,i,neuron) = squeeze(mean(app.stimAlignedData(:,sortedIndexRange,neuronIndicies(neuron)),2));
                    startDex = startDex + numElements;
                end
            end
            
            if app.InvertCheckBox.Value>0
                toPlot=toPlot*-1;
            end
            
            separationNeuron = app.getSeperationVec();
            toPlot = toPlot + reshape(separationNeuron, [1 1 numel(separationNeuron)]);
            separationQuantile= app.getQuantileSeparationVec();
            toPlot = toPlot + reshape(separationQuantile, [1 numel(separationQuantile) 1]);

            colorsText = getColorsText(app);
            colors = brewermap(quantiles,colorsText);
            for i = 1:quantiles
                plot(app.UIAxes, app.tmAligned,squeeze(toPlot(:,i,:)),'Color',colors(i,:));
            end
        end


        function regressand = getRegressand(app)
            switch app.RegressandDropDown.Value
                case 1
                    func = @sum;
                case 2
                    func = @(x) app.secondOut(@max, x);
                case 3
                    func = @(x) app.secondOut(@min, x);
                case 4
                    func = @min;
                case 5
                    func = @max;
                otherwise
                    % Handle other cases or throw an error
                    error('Invalid prediction type: %s', get(app.RegressandDropDown,"Value"))
            end
            regressand = getRegressandHelper(app, func);
        end


        function regressand = getRegressandHelper(app, fnHandle)
            numWindows = size(app.stimAlignedData,2);
            numNeurons = size(app.stimAlignedData,3);
            range = app.rangeRegressandIdx();
            regressand = zeros(numWindows,numNeurons);
            for i = 1:numWindows
                for j = 1:numNeurons
                    if (app.RegressagainstDropDown.Value==1) || ismember(app.CorrelationMat,app.ThingsToPlotListBox.Value)
                        regressand(i,j) = fnHandle(app.stimAlignedData(range,i,j));
                    else
                        regressand(i,j) = fnHandle(app.stimAlignedData(range,i,app.RegressagainstDropDown.Value-1));
                    end
                end
            end
        end

        function val = secondOut(~,fn, input)
            [~, val] = fn(input);
        end

        function updateStimAlignedData(app)
            [app.fs,tmi,data] = load_time_series(app.param,app.HPfreqHzEditField.Value);
            
            num_neurons = size(data,2);
            app.windowIdxData = [];
            window_idx_data = app.windowIdxData();
            num_stims = size(window_idx_data, 1);
            window_length = window_idx_data(1,2)-window_idx_data(1,1)+1;
            if ~isempty(app.patternIndicies) && ~isempty(app.stimOffset)
                if size(app.patternIndicies,1)==1
                    app.patternIndicies = app.patternIndicies';
                end
                indicies = circshift(app.patternIndicies,app.stimOffset);
                if num_stims>numel(indicies)
                    app.setColorMap(sum(indicies==1,"all"));
                    indicies = repmat(indicies,ceil(num_stims/numel(indicies)));
                    indicies = indicies(1:num_stims);
                end
                
                myIndicies = find(indicies==app.PlotGroupsListBox.Value);
                myIndicies(myIndicies>size(window_idx_data,1))=[];
                num_stims = numel(myIndicies);
                window_idx_data = window_idx_data(myIndicies,:);
            end

            app.stimAlignedData=zeros(window_length,num_stims,num_neurons);
            tms=zeros(window_length,num_stims);

            for i = 1:num_stims
                startDex = window_idx_data(i,1); endDex = window_idx_data(i,2);
                app.stimAlignedData(:, i, :) = data(startDex:endDex, :);
                tms(:,i) = tmi(startDex:endDex)-tmi(startDex)+app.xminEditField.Value;
            end
            app.tmAligned = mean(tms,2,'omitnan');
            app.QuantilesEditField.Limits = [1,num_stims];
            app.updateAllComponentVisibility();
            app.updatePlot();
            app.overlayStims();
        end

        function updateDimensionComponentVisibility(app)
            if ~(any(ismember([app.PCs, app.PLSs],app.ThingsToPlotListBox.Value)))
                app.DimensionPanel.Visible="off";
                app.hideDimensionTab();
                return
            end
            num_windows = size(app.stimAlignedData, 2);
            app.DimensionPanel.Visible="on";

            app.DimensionsToPlotListBox.Items = compose("Dimension %d", 1:num_windows-1);
            app.DimensionsToPlotListBox.ItemsData = 1:num_windows-1;


            if ~app.ApplyindividualCheckBox.Value
                app.PCAspaceTab.Parent = app.TabGroup;
                isPCs = ismember(app.PCs, app.ThingsToPlotListBox.Value);
                isPLSs = ismember(app.PLSs, app.ThingsToPlotListBox.Value);
            
                componentItemsPC = [];
                componentItemValsPC = [];
                componentItemsPLS = [];
                componentItemValsPLS = [];
                if isPCs
                    componentItemsPC = compose("%s %d", "Principal Component", 1:num_windows-1);
                    componentItemValsPC = (1:num_windows-1)*2-1;
                end
                if isPLSs
                    componentItemsPC = compose("%s %d", "PLS Component", 1:num_windows-1);
                    componentItemValsPLS = (1:num_windows-1)*2;
                end
                items = reshape([componentItemsPC;componentItemsPLS],[],1);
                itemVals = reshape([componentItemValsPC;componentItemValsPLS],[],1);
                app.Dimension1DropDown.Items = items;
                app.Dimension1DropDown.ItemsData = itemVals;
                app.Dimension2DropDown.Items = items;
                app.Dimension2DropDown.ItemsData = itemVals;
                app.Dimension2DropDown.Value = itemVals(2);
                return
            end

            app.hideDimensionTab();
        end

        function updateAllComponentVisibility(app)            
            app.updateDimensionComponentVisibility();

            % Define quantile options
            quantileOptions = [app.QuantileIdx, app.QuantileRegress]; 
            if any(ismember(quantileOptions,app.ThingsToPlotListBox.Value))
                app.Panel_3.Visible = 'on';
            else
                app.Panel_3.Visible = 'off';
            end
            app.updateColorOnNeuronsListBox();
        end

        function updateRangeLines(app)
            xline(app.UIAxes,app.regressorRangeEditFieldmin.Value,'--');
            xline(app.UIAxes,app.regressorRangeEditFieldmax.Value,'--');
            xline(app.UIAxes,app.regressorRangeEditFieldmin.Value,'--');
            xline(app.UIAxes,app.regressandRangeEditFieldMin.Value,'--');
            xline(app.UIAxes,app.regressandRangeEditFieldMax.Value,'--');
        end

        function updatePlot(app)
            if app.LockPlotCheckBox.Value
                return
            end
            cla(app.UIAxes,'reset');
            hold(app.UIAxes,"on");
            if isempty(app.NeuronsListBox.Value)
                return
            end
            app.plotPCs(); 
            app.plotPLS();
            app.plotStimLowerDim();
            app.plotRidgeLasso();
            app.plotQuantiles();
            app.plotQuantilesHelperSorted();
            app.plotAverage(); 
            if (ismember(app.CorrelationMat,app.ThingsToPlotListBox.Value))
                app.plotCorrelationMat();
                app.CorrelationTab.Parent = app.TabGroup;
                return
            end
            app.updateRangeLines();
            app.CorrelationTab.Parent = [];
            numNeurons = length(app.NeuronsListBox.Value);
            sep = app.SeparationEditField.Value;
            if app.SeparationEditField.Value>0 && length(app.NeuronsListBox.Value)>1
                tickEvery = sep/4;
                toRepeat = (-sep/2+tickEvery):tickEvery:sep/2;
                repeated = repmat(toRepeat,1,numNeurons+1);
                labels = string([-sep/2,repeated]);
                labels(strcmp(labels,string(sep/2)))="(-)"+string(sep/2);
                newYTicks = 0:length(labels)-1;
                newYTicks = newYTicks*tickEvery-sep/2;
                yticks(app.UIAxes,newYTicks)
                yticklabels(app.UIAxes,labels)
            end
            xlim(app.UIAxes,[app.xminEditField.Value,app.xmaxEditField.Value]);
            if sep>0
                ylim(app.UIAxes,[-sep/2,numNeurons*sep+sep/2]);
            end
            alpha = 0.1;
            yline(app.UIAxes,app.getSeperationVec(),Alpha=alpha);
            xline(app.UIAxes,0,Alpha=alpha);
            zoom(app.UIAxes,'reset');
            grid(app.UIAxes,'off');
        end

        function plotCorrelationMat(app)
            cla(app.UIAxes2,'reset');
            neuronIdxs = sort(app.NeuronsListBox.Value);
            if length(neuronIdxs)<2
                text(app.UIAxes,.5, 0.5, 'Select at least two ROIs', 'HorizontalAlignment', 'center', 'FontSize', 30);
                text(app.UIAxes2,.5, 0.5, 'Select at least two ROIs', 'HorizontalAlignment', 'center', 'FontSize', 30);
                return
            end
            regressand = app.getRegressand(); % 200x35 trial x ROI
            

            regressand = regressand(:,neuronIdxs);

            % Compute the correlation matrix.
            corrMat = corrcoef(regressand);
        
            % Compute dissimilarity.
            dissimilarity = 1 - corrMat;
        
            % Perform hierarchical clustering.
            Y = squareform(dissimilarity, 'tovector'); % convert the dissimilarity matrix to the vector form required by linkage
            clusters = linkage(Y, 'average');
        
%            %%bug in matlab code (nargin needs to be numargs in dendrogram's parseArguments call)
            [~,~,outPerm] = dendrogram(clusters,0,'Labels',string(neuronIdxs),'Parent',app.UIAxes2);
            corrMat = corrMat(outPerm, outPerm); % apply the reordering to the correlation matrix
        
            % Plot the reordered correlation matrix.
            hold(app.UIAxes,"off");
            imagesc(app.UIAxes,corrMat); % Creates a heatmap from the data
            ylim(app.UIAxes,[0.5,length(neuronIdxs)+0.5]);
            xlim(app.UIAxes,[0.5,length(neuronIdxs)+0.5]);
            colorbar(app.UIAxes); % Shows the color scale
            title(app.UIAxes,'Reordered Correlation Matrix of ROIs'); % Sets the title of the plot
            xlabel(app.UIAxes,'ROI'); % Sets the x-axis label
            ylabel(app.UIAxes,'ROI'); % Sets the y-axis label
         % Set axis marks to denote the ROI id of each column and row
            set(app.UIAxes, 'XTick', 1:length(outPerm));
            set(app.UIAxes, 'XTickLabel', neuronIdxs(outPerm));
            set(app.UIAxes, 'YTick', 1:length(outPerm));
            set(app.UIAxes, 'YTickLabel', neuronIdxs(outPerm));
            set(app.UIAxes, 'XTickLabelRotation', 90); % optional: rotate x labels for better visibility
            clim(app.UIAxes,[0,1]);
        end


        function separation = getSeperationVec(app)
            neuronIndicies = sort(app.NeuronsListBox.Value);
            separation = (0:length(neuronIndicies)-1)*app.SeparationEditField.Value;
        end

        function plotAverage(app)
            if ismember(app.Average,app.ThingsToPlotListBox.Value)
                app.plotQuantilesHelper(1);
            end
        end

        function colorsText = getColorsText(app)
            colorsText = app.ColorSchemeDropDown.Value;
            colorsText = split(colorsText,'|');
            colorsText = strtrim(colorsText(1));
            if iscell(colorsText)
                colorsText = colorsText{1};
            end
        end

        function plotQuantiles(app)
            if ismember(app.QuantileIdx,app.ThingsToPlotListBox.Value)
                app.plotQuantilesHelper(app.QuantilesEditField.Value);
            end
        end

        function plotQuantilesHelper(app,quantiles)            
            pause(0.001);
            neuronIndicies = sort(app.NeuronsListBox.Value);
            numNeurons = length(neuronIndicies);
            startDex = 1;
            numWindows = size(app.stimAlignedData,2);
            numElements = round(numWindows/quantiles);
            toPlot = zeros(size(app.stimAlignedData,1),quantiles,numNeurons);
            for i = 1:quantiles
                endDex = min(startDex+numElements-1,numWindows);
                indicies = startDex:endDex;
                toPlot(:,i,:) = squeeze(mean(app.stimAlignedData(:,indicies,neuronIndicies),2)); %(window_length,num_windows,num_neurons);
                startDex= startDex + numElements;
            end
            toPlot = toPlot - median(toPlot);
            if app.InvertCheckBox.Value>0
                toPlot=toPlot*-1;
            end
            separationNeuron = app.getSeperationVec();
            toPlot = toPlot + reshape(separationNeuron, [1 1 numel(separationNeuron)]);
            separationQuantile = app.getQuantileSeparationVec(quantiles);
            toPlot = toPlot + reshape(separationQuantile, [1 numel(separationQuantile) 1]);

            
            toPlot = MySmooth(toPlot,app.SmoothingEditField.Value,1,1);
            
            colorsText = getColorsText(app);
            if quantiles>1
                colors = brewermap(quantiles,colorsText);
                for i = 1:quantiles
                    plot(app.UIAxes, app.tmAligned,squeeze(toPlot(:,i,:)),'Color',colors(i,:));   %(window_length,num_windows,num_neurons);
                end
            else
                if numel(app.ThingsToPlotListBox.Value)>1
                    colors = repmat("k",[numNeurons,1]);
                else
                    colors = brewermap(length(app.NeuronsListBox.ItemsData),colorsText);
                end
                for i = 1:numNeurons
                    plot(app.UIAxes, app.tmAligned,squeeze(toPlot(:,:,i)),'Color',colors(neuronIndicies(i),:),'MarkerSize',10);   %(window_length,num_windows,num_neurons);
                end
            end

        end

        function plotRidgeLasso(app)
            regularizations = strings(0);
            if ismember([app.Ridge],app.ThingsToPlotListBox.Value)
                regularizations(end+1) = 'ridge';
            end
            if ismember([app.Lasso],app.ThingsToPlotListBox.Value)
                regularizations(end+1) = 'lasso';
            end
            if isempty(regularizations)
                return;
            end
        
            neuronIdxs = sort(app.NeuronsListBox.Value);
            numNeurons = length(neuronIdxs);
        
            % Get the regressand
            regressand = app.getRegressand();
        
            % Get the time points in the range
            minIdx = find(app.tmAligned > app.regressorRangeEditFieldmin.Value, 1);
            maxIdx = find(app.tmAligned < app.regressorRangeEditFieldmax.Value, 1, 'last');
            timePointsRange = minIdx:maxIdx;
            folds = 5;
            separationVec = app.getSeperationVec();
        
            % Iterate over neurons
            for regulularization=regularizations
                for i = 1:numNeurons
                    neuronData = squeeze(app.stimAlignedData(timePointsRange,:,neuronIdxs(i)));
                    dataMin = min(mean(neuronData,2));
                    dataMax = max(mean(neuronData,2));
                    
                    % Apply Ridge Regression
                    mdl = fitrlinear(neuronData', regressand(:,neuronIdxs(i)), 'Lambda',10,"Regularization",regulularization,'Learner','leastsquares'); % Change the Lambda value according to your requirement.
%                     mdl = fitrlinear(neuronData', regressand(:,neuronIdxs(i)), 'Lambda',0.1,"Regularization",regulularization,'Learner','leastsquares','KFold',folds); % Change the Lambda value according to your requirement.
                    BETA = mdl.Beta;
                    BETA = rescale(BETA,dataMin,dataMax);
                    BETA = BETA - mean(BETA);
                    
                    
                    plot(app.UIAxes, app.tmAligned(timePointsRange), BETA + separationVec(i));
            
                    %should do something with cross-val

%                     MSE = kfoldLoss(mdl);
%                     text(app.UIAxes, app.tmAligned(maxIdx), separationVec(i), sprintf('RMSE: %.2f', sqrt(MSE)), 'FontSize', 12);
                end
            end
        end

        function hideDimensionTab(app)
            if app.TabGroup.SelectedTab==app.PCAspaceTab
                app.TabGroup.SelectedTab=app.MainTab;
            end
            app.PCAspaceTab.Parent=[];
        end


        function plotPCs(app)
            if ~ismember(app.PCs,app.ThingsToPlotListBox.Value)
                return
            end
            neuronIdxs = sort(app.NeuronsListBox.Value);
            numNeurons = length(neuronIdxs);
            windowLength = size(app.stimAlignedData,1);
            separation = app.getSeperationVec();
            dimsToPlot = app.DimensionsToPlotListBox.Value;
            
            if app.InvertCheckBox.Value>0
                invertMult = -1;
            else
                invertMult = 1;
            end
            % Adjust data based on mode
            if app.ApplyindividualCheckBox.Value
                for i=1:numNeurons
                    neuronData = squeeze(app.stimAlignedData(:,:,neuronIdxs(i)));
                    pcaPlotHelper(neuronData);
                end
            else
                neuronData = reshape(permute(app.stimAlignedData(:,:,neuronIdxs),[1,3,2]),windowLength*numNeurons,[]);
                app.pcaScores = pcaPlotHelper(neuronData);
            end

            function score = pcaPlotHelper(data)
                dataMin = min(mean(data,2));
                dataMax = max(mean(data,2));
                % apply PCA across windows
                [coeff,score,~,~,~] = pca(data'); 
                coeff = rescale(coeff, dataMin, dataMax); %rescale so can plot with data
                coeff = coeff - mean(coeff,1);
                coeff=coeff*invertMult;
                if app.ApplyindividualCheckBox.Value
                    toPlot = coeff + separation(i);
                else
                    toPlot = reshape(coeff,windowLength,numNeurons,[]);
                    toPlot = toPlot + reshape(separation,[1 numel(separation),1]);
                    toPlot = permute(toPlot,[1,3,2]);
                end
                
                plot(app.UIAxes,app.tmAligned,squeeze(toPlot(:,dimsToPlot,:)));
            end
        end

        function plotStimLowerDim(app)
            if isempty(app.PCAspaceTab.Parent)
                return
            end
            
            if mod(app.Dimension1DropDown.Value,2)==1
                x =app.pcaScores(:,(app.Dimension1DropDown.Value+1)/2);
            else
                x =app.plsScores(:,(app.Dimension1DropDown.Value)/2);
            end 
            if mod(app.Dimension2DropDown.Value,2)==1
                y =app.pcaScores(:,(app.Dimension2DropDown.Value+1)/2);
            else
                y =app.plsScores(:,(app.Dimension2DropDown.Value)/2);
            end 


            plot(app.UIAxes3,x,y,'.','MarkerSize',15);
            xlabel(app.UIAxes3, "dimension " + string(app.Dimension1DropDown.Value/2));
            ylabel(app.UIAxes3, "dimension " + string(app.Dimension2DropDown.Value/2));
        end

        
        function get_window_idx_data(app)

            [fsStim,stim] = load_stim_series(app.param);
            [~,tmi,~] = load_time_series(app.param);
            fsData = 1/mean(diff(tmi));
            numBefore = floor(-app.xminEditField.Value*fsData);
            numAfter = floor(app.xmaxEditField.Value*fsData);
            on_stim_indices=find([0;diff(stim)>0.5]);

            if fsStim==fsData && size(stim,1)==size(tmi,1)+app.param.framesToDisregard
                movieStimIndicies=on_stim_indices-app.param.framesToDisregard;
            else
                %something is causing this to shift by an extra index each time (even when fsStim==fsData)
                % and I cannot for the life of me figure out why
                
                on_time_s=(on_stim_indices-1)./fsStim;
                on_time_s(on_time_s>max(tmi(:)))= [];
                on_time_s(on_time_s<min(tmi(:)))= [];
                movieStimIndicies = [arrayfun(@(x) find(tmi>=x,1),on_time_s)];
            end
            badIndicies = movieStimIndicies-numBefore<1 | movieStimIndicies+numAfter>size(tmi,1);
            numLostBefore = find(diff(badIndicies)<0); %numLostAfter = find(diff(badIndicies)>0);
            optoFile = getFileName(app.param, 'OptoData');
            if exist(optoFile,"file")
                optoInfo = load(optoFile);
                if isfield(optoInfo,"stimOffset")
                    app.stimOffset = optoInfo.stimOffset;
                else
                    app.stimOffset = 0;
                end
                app.stimOffset = app.stimOffset-numLostBefore;
            end

            movieStimIndicies(badIndicies) = [];
            app.windowIdxData = [movieStimIndicies-numBefore,movieStimIndicies+numAfter];
            % on_time_s = on_time_s(on_time_s+>0 & on_time_s+app.xmaxEditField.Value<tmi(end));
            % ,arrayfun(@(x) find(tmi>x,1),on_time_s+app.xmaxEditField.Value)];
            % window_length = min(window_idx_data(:,2)-window_idx_data(:,1));
            % window_idx_data(:,2) = window_idx_data(:,1)+window_length-1;
            
        end
        
        function setColorMap(app,N)
            colormap(app.UIAxes,brewermap(N,"Pastel2"));
        end
        
        function updateROIMap(app)

            movAvg = tiffreadVolume(getFileName(app.param,'Movavg'));
            cla(app.roiMapAxes,"reset");
            imagesc(app.roiMapAxes,movAvg); hold(app.roiMapAxes,'on');
            app.overlayCountours();
        end
        
        function overlayCountours(app)
            axis(app.roiMapAxes,'image');
            colormap(app.roiMapAxes,'gray');

            app.overlayROIS();
            app.overlayStims();
            app.overlayExcitation();
        end

        function overlayROIS(app)
            if isempty(app.dmdInfo) || isempty(app.region) || any(app.region==-1)
                return
            end

            roiFileData = load(getFileName(app.param, 'ROI'));
            Nroi = size(roiFileData.roi,3);
            for i = 1:min(Nroi,numel(app.roiHandles))
                delete(app.roiHandles{i});
            end
            for i = 1:Nroi            
                p.mask = roiFileData.roi(:,:,i);
                ctemp = contourc(p.mask, [1,1].*0.5);
                thr = 0.5;
                newcolumns = find(ctemp(1,:)==thr);
                if find(app.NeuronsListBox.Value==i)
                    lineWidth = 3;
                else
                    lineWidth = 1;
                end
                for j = 1:numel(newcolumns)
                    ind1 = newcolumns(j)+1;
                    if (i+1>numel(newcolumns))
                        ind2 = size(ctemp, 2);
                    else
                        ind2 = newcolumns(j+1)-1;
                    end
                    
                    app.roiHandles{i}(j) = line(app.roiMapAxes,ctemp(1,ind1:ind2), ctemp(2, ind1:ind2), 'Color', 'g', 'LineWidth', lineWidth);
                end
            end
        end

        function overlayStims(app)

            if isempty(app.dmdInfo) || isempty(app.region) || any(app.region==-1)
                return
            end
            outputViewCam = imref2d(size(zeros(3200, 3200)));
            Nroi = numel(app.NeuronsListBox.Items);
            for i = Nroi+1:numel(app.roiHandles)
                delete(app.roiHandles{i});
            end

            pattern = sum(app.dmdInfo.dmdPatterns(:,:,2:end),3);
            
            pattern(~app.dmdInfo.excitationToStimRegistration.maskBoundingDMD')=0;
            camPatternStim = imwarp(pattern, invert(app.dmdInfo.excitationToStimRegistration.T2), 'OutputView', outputViewCam);
            camPatternStim = camPatternStim(app.region(1):app.region(2),app.region(3):app.region(4))';

            ctemp = contourc(double(camPatternStim), [1,1].*0.5);
            thr = 0.5;
            newcolumns = find(ctemp(1,:)==thr);

            for i = 1:numel(newcolumns)
                ind1 = newcolumns(i)+1;
                if (i+1>numel(newcolumns))
                    ind2 = size(ctemp, 2);
                else
                    ind2 = newcolumns(i+1)-1;
                end
                
                app.roiHandles{Nroi+1}(i) = line(app.roiMapAxes,ctemp(1,ind1:ind2), ctemp(2, ind1:ind2), 'Color', 'b', 'LineWidth', 1);
            end

            pattern = app.dmdInfo.dmdPatterns(:,:,app.PlotGroupsListBox.Value+1);
            pattern(~app.dmdInfo.excitationToStimRegistration.maskBoundingDMD')=0;
            camPatternStim = imwarp(pattern, invert(app.dmdInfo.excitationToStimRegistration.T2), 'OutputView', outputViewCam);
            camPatternStim = camPatternStim(app.region(1):app.region(2),app.region(3):app.region(4))';

            ctemp = contourc(double(camPatternStim), [1,1].*0.5);
            thr = 0.5;
            newcolumns = find(ctemp(1,:)==thr);

            for i = 1:numel(newcolumns)
                ind1 = newcolumns(i)+1;
                if (i+1>numel(newcolumns))
                    ind2 = size(ctemp, 2);
                else
                    ind2 = newcolumns(i+1)-1;
                end
                
                app.roiHandles{Nroi+2}(i) = line(app.roiMapAxes,ctemp(1,ind1:ind2), ctemp(2, ind1:ind2), 'Color', 'b', 'LineWidth', 3);
            end
        end

        function overlayExcitation(app)
            if isempty(app.dmdInfo) || isempty(app.region) || any(app.region==-1)
                return
            end

            outputViewCam = imref2d(size(zeros(3200, 3200)));

            pattern = sum(app.dmdInfo.dmdPatterns,3)>0;
            pattern(~app.dmdInfo.excitationRegistration.maskBoundingDMD')=0;
            camPatternExcitation = imwarp(pattern, invert(app.dmdInfo.excitationRegistration.T2), 'OutputView', outputViewCam);
            camPatternExcitation = camPatternExcitation(app.region(1):app.region(2),app.region(3):app.region(4))';
            ctemp = contourc(double(camPatternExcitation), [1,1].*0.5);
            thr = 0.5;
            newcolumns = find(ctemp(1,:)==thr);
            for i = 1:numel(newcolumns)
                ind1 = newcolumns(i)+1;
                if (i+1>numel(newcolumns))
                    ind2 = size(ctemp, 2);
                else
                    ind2 = newcolumns(i+1)-1;
                end
                
                line(app.roiMapAxes,ctemp(1,ind1:ind2), ctemp(2, ind1:ind2), 'Color', 'r', 'LineWidth', 1);
            end
        end
        
        function updateColorOnNeuronsListBox(app)
            if ismember(app.Average,app.ThingsToPlotListBox.Value) && isscalar(app.ThingsToPlotListBox.Value)
                colorsText = getColorsText(app);
                numNeurons = length(app.NeuronsListBox.ItemsData);
                colors = brewermap(numNeurons,colorsText);
                try
                    for i = 1:numNeurons
                        style = uistyle("FontColor",colors(i,:));
                        addStyle(app.NeuronsListBox,style,"item",i);
                    end
                catch
                end
            else
                removeStyle(app.NeuronsListBox);
            end
        end
        
        function singleDFFMov(app,savePath,numMovies)

            window_idx_data = app.windowIdxData;
            fn = getFileName(app.param,'RegBGMov');
            if ~exist(fn,'file')
                fn = fullfile(app.param.pth,app.param.imtoken);
            end
            reader = FrameReader(fn);
            
            numIndiciesEach = floor(size(window_idx_data,1)/numMovies);
            if numIndiciesEach==0
                return
            end
            for j = 0:numMovies-1
                startStimNumber = j*numIndiciesEach+1;
                startDex = window_idx_data(1,1); endDex = window_idx_data(1,2);
                app.sumOfFrames = double(reader.getFramesByIndexes(startDex,endDex));
                for i =startStimNumber:startStimNumber+numIndiciesEach-1
                    startDex = window_idx_data(i,1); endDex = window_idx_data(i,2);
                    app.sumOfFrames = app.sumOfFrames+double(reader.getFramesByIndexes(startDex,endDex));
                end
                meanMovie = app.sumOfFrames./size(window_idx_data,1);


                [filepath, name, ext] = fileparts(savePath);
                if numMovies>1
                    quantSavePath = fullfile(filepath, name + '_stimQuant_' + num2str(j) +  ext);
                else
                    quantSavePath = fullfile(filepath, name + 'all' + ext);
                end
                fTIF = Fast_BigTiff_Write(quantSavePath,1,0);
    
                for i =1:size(meanMovie,3)
                    fTIF.WriteIMG(meanMovie(:,:,i));
                end
    
                fTIF.close()
            end
            

            return;
            if isempty(savePath)
                app.showSingleDFFMov();
            else
            end
        end

        function showSingleDFFMov(app)
            if (isempty(app.movieFig) || ~isvalid(app.movieFig))
                app.movieFig = uifigure();
                app.Panel_4.Parent=app.movieFig;
                app.Panel_4.Units="normalized";
                app.Panel_4.Position=[0 0 1 1];
                app.movieFig.CloseRequestFcn = @(src, event) extraFigClose(app); 
            end
            window_idx_data = app.windowIdxData;
            startDex = window_idx_data(1,1); endDex = window_idx_data(1,2);
            fn = getFileName(app.param,'RegBGMov');
            if ~exist(fn,'file')
                fn = fullfile(app.param.pth,app.param.imtoken);
            end
            reader = FrameReader(fn);

            if isempty(app.sumOfFrames) 
                app.sumOfFrames = double(reader.getFramesByIndexes(startDex,endDex));
                for i =2:size(window_idx_data,1)
                    startDex = window_idx_data(i,1); endDex = window_idx_data(i,2);
                    app.sumOfFrames = app.sumOfFrames+double(reader.getFramesByIndexes(startDex,endDex));
                end
            end
            if isempty(app.background) || isempty(app.framesModified)
                app.background = mean(app.sumOfFrames,3)./size(window_idx_data,1);
                app.framesModified = (app.sumOfFrames./size(window_idx_data,1)-app.background)./size(window_idx_data,1); %0 mean and mean of stims

                minBackground = app.minBackgroundEdit.Value; maxBackground = app.maxBackgroundEdit.Value;
                app.background = (app.background-minBackground)./maxBackground;

                maxDelta = app.MaxDeltaEdit.Value;
                app.framesModified = (app.framesModified-median(app.framesModified,"all"))./maxDelta.*100; %
                app.framesModified = imgaussfilt3(app.framesModified,[app.xySmoothEdit.Value, app.xySmoothEdit.Value, app.tSmoothEdit.Value]);
            end

            app.Slider.Limits = [1 size(app.framesModified,3)];

            imColor = cat(3,app.background,app.background,app.background);
            app.movieIm = image(app.UIAxes4,imColor);
            for i = 1:size(app.framesModified,3)
                imColor(:,:,1) =app.background + app.framesModified(:,:,i);
                app.movieIm.CData = imColor;
                pause(0.2);
            end

            function extraFigClose(app)
                try
                    app.Panel_4.Parent = [];
                    app.framesModified = [];
                    app.sumOfFrames = [];
                    app.background = [];
                catch
                end
                delete(app.movieFig);
            end
        end
    end

    methods(Static)
        function currentDir = getcurrentdir()
            if isdeployed % Stand-alone mode.g
                [~, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app, paramArray)
            app.Panel_4.Parent = [];
            addpath(genpath(app.getcurrentdir()));

            for i = 1:length(app.ThingsToPlotListBox.Items)
                text = textwrap(app.ThingsToPlotListBox.Items(i),20);
                
                app.ThingsToPlotListBox.Items{i} = strjoin(text,newline);
            end
            % ====================== PROCESSING PARAMETERS ======================= %%
            if exist("paramArray","var")
                app.ParamsArray = paramArray;
            else
                app.ParamsArray = Select_input_files(app);
            end
            setpref('time_series_plots','lastPath',app.param.pth);
            axis(app.roiMapAxes,'image');
            app.updateFileList();
        end

        % Button pushed function: SelectNewButton
        function SelectNewButtonPushed(app, event)
            % app.FileDropDown.Value = 1;
            % for i = 1:length(app.FileDropDown.Items)-1
            %     app.FileDropDown.Value = app.FileDropDown.Value+1;
            %     app.updateROIList();
            % end
            % return
            
            paramsArray = Select_input_files(app);
            if isscalar(paramsArray) && paramsArray(1).pth ==""
                return
            end

            if ~isempty(app.ParamsArray) 
                if all(ismember(fullfile([paramsArray.pth],[paramsArray.imtoken]),fullfile([app.ParamsArray.pth],[app.ParamsArray.imtoken])))
                    app.FileDropDown.Value = find(fullfile([paramsArray(1).pth],[paramsArray(1).imtoken])==fullfile([app.ParamsArray.pth],[app.ParamsArray.imtoken]));
                    %should be setting it to that one but for now no
                    return
                end
            end

            for dex = 1:length(paramsArray)
                Pipeline_getExpInfo(paramsArray(dex));
            end
            
            if isempty(app.ParamsArray)
                app.ParamsArray = paramsArray;
            else
                % Check if the fields in paramsArray already exist in app.params
                newFields = setdiff(fieldnames(paramsArray), fieldnames(app.ParamsArray));
                % Add the missing fields to app.params and initialize them to []
                for i = 1:length(newFields)
                    app.ParamsArray.(newFields{i}) = [];
                end
                % Check if the fields in paramsArray already exist in app.params
                newFields = setdiff(fieldnames(app.param),fieldnames(paramsArray));
                % Add the missing fields to app.params and initialize them to []
                for i = 1:length(newFields)
                    for j = 1:length(paramsArray)
                        paramsArray(j).(newFields{i}) = [];
                    end
                end
                app.ParamsArray(end+1:end+length(paramsArray)) = paramsArray;
            end
            for i=1:1:length(app.ParamsArray)
                temp(i) = fullfile(app.ParamsArray(i).pth,app.ParamsArray(i).imtoken);
            end
            app.FileDropDown.Items = temp;
            app.FileDropDown.ItemsData = 1:length(app.ParamsArray);
            app.FileDropDown.Value = length(app.ParamsArray);
            app.updateFileList();
        end

        % Callback function
        function OtherneuronpredictorsListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            if item==1
                pause(0.01)
                set( app.OtherneuronpredictorsListBox, 'Value', {} );
            end
        end

        % Value changed function: ThingsToPlotListBox
        function ThingsToPlotListBoxValueChanged(app, event)
           % Retrieve current and previous values
            currentValue = event.Value;
            prevValue = event.PreviousValue;
            if ismember(app.CorrelationMat,currentValue) && ~ismember(app.CorrelationMat,prevValue)
                app.ThingsToPlotListBox.Value=app.CorrelationMat;
            end
            if ismember(app.CorrelationMat,currentValue) && ismember(app.CorrelationMat,prevValue) && length(currentValue)>1
                app.ThingsToPlotListBox.Value(app.ThingsToPlotListBox.Value==app.CorrelationMat) = [];
            end
            
            % Update the plot
            app.updateAllComponentVisibility();
            app.updatePlot();
        end

        % Callback function: ApplyindividualCheckBox, Dimension1DropDown, 
        % ...and 13 other components
        function updatePlotCallback(app, event)
            if event.Source==app.ApplyindividualCheckBox
                app.updateAllComponentVisibility();
            end
            app.updatePlot();
        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)
            value = app.InvertCheckBox.Value;
            yLabelString = 'ΔF/F';
            if value
                yLabelString = "-" + yLabelString;
            end
            ylabel(app.UIAxes,yLabelString);
            app.updatePlot();
        end

        % Value changed function: PlotGroupsListBox, xmaxEditField, 
        % ...and 1 other component
        function updateStimAlignedDataCallback(app, event)
            app.updateStimAlignedData();
        end

        % Button pushed function: GenerateMoviesslowButton
        function GenerateMoviesslowButtonPushed(app, event)
            
            bad_indices = [];
            for i = 1:length(app.ParamsArrayPrivate)
                
                try
                    app.FileDropDown.Value = i;
                    app.FileDropDownValueChanged();
                    app.singleDFFMov(getFileName(app.param,'StimDFFMov'),5);
                    app.singleDFFMov(getFileName(app.param,'StimDFFMov'),1);
                catch ME
                    bad_indices(end+1) = i;
                    warning(ME.identifier,'Error occurred while running on %d: %s', i, ME.getReport);
                end
            end
            % 
            % fig = uifigure;
            % msg = "Saving these changes will overwrite previous changes.";
            % title = "How many movies to calculate";
            % choice = uiconfirm(fig,msg,title, ...
            %     "Options",['All Files','Current one',"Cancel"], ...
            %     "DefaultOption",2,"CancelOption",3);
            % disp('hi')
            % uiwait(fig);
            % delete(fig);
            % switch choice
            %     case 'All Files'
            %         rootDir = getpref(class(app),'lastPath',pwd);
            %         selpath = uigetdir(rootDir,"select a directory to save all movies. Otherwise saves to Analysis Dir");
            %         for i = 1:length(app.ParamsArrayPrivate)
            %             app.FileDropDown.Value = i;
            %             app.FileDropDownValueChanged();
            %             app.doSingleDFFMov();
            %         end
            %     case 'Current one'
            %         app.doSingleDFFMov();
            %     case 'Cancel'
            %         return;
            % end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.movieFig);
            delete(app)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            val = ceil(app.Slider.Value);
            imColor = cat(3,app.background,app.background,app.background);
            imColor(:,:,1) =app.background + app.framesModified(:,:,val);
            app.movieIm.CData = imColor;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            val = ceil(event.Value);
            imColor = cat(3,app.background,app.background,app.background);
            imColor(:,:,1) =app.background + app.framesModified(:,:,val);
            app.movieIm.CData = imColor;
        end

        % Value changed function: GroupsEditField
        function GroupsEditFieldValueChanged(app, event)
            value = app.GroupsEditField.Value;  % Assuming this is how you obtain the value
            
            % Initialize an empty cell array for the groups
            groups = cell(1, value);
            % Fill the cell array with group names up to the specified value
            for i = 1:value
                groups{i} = sprintf('Group %d', i);
            end
        
            % Set the Items of the PlotGroupsListBox to the generated groups
            app.PlotGroupsListBox.Items = groups;
            % Set the ItemsData of the PlotGroupsListBox to a vector from 1 to value
            app.PlotGroupsListBox.ItemsData = 1:value;
        
            % Call the update function to refresh data based on the new groups
            app.updateStimAlignedData();
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            app.updateROIList();
        end

        % Value changed function: badframesEditField
        function badframesEditFieldValueChanged(app, event)
            value = app.badframesEditField.Value;
            app.param.framesToDisregard = value;
            app.updateStimAlignedData();
        end

        % Value changed function: NeuronsListBox
        function NeuronsListBoxValueChanged(app, event)
            app.updatePlot();
            fullfnDMD = getFileName(app.param, 'DMDPatternInfo');
            if exist(fullfnDMD,'file')==2
                app.overlayROIS();
            end
        end

        % Value changed function: HPfreqHzEditField
        function HPfreqHzEditFieldValueChanged(app, event)
            app.lpFilt = [];
            app.updateStimAlignedData();
        end

        % Value changed function: SmoothingEditField
        function SmoothingEditFieldValueChanged(app, event)
            value = app.SmoothingEditField.Value;
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.NeuronsListBoxLabel = uilabel(app.GridLayout);
            app.NeuronsListBoxLabel.Text = 'Neurons';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CorrelationTab = uitab(app.TabGroup);
            app.CorrelationTab.Title = 'Correlation';
            app.GridLayout4 = uigridlayout(app.CorrelationTab);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PCAspaceTab = uitab(app.TabGroup);
            app.PCAspaceTab.Title = 'PCA space';
            app.GridLayout2 = uigridlayout(app.PCAspaceTab);
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Title')
            zlabel(app.UIAxes3, 'Z')
            app.Dimension1DropDownLabel = uilabel(app.GridLayout2);
            app.Dimension1DropDownLabel.Text = 'Dimension 1';
            app.Dimension1DropDown = uidropdown(app.GridLayout2);
            app.Dimension1DropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.Dimension2DropDownLabel = uilabel(app.GridLayout2);
            app.Dimension2DropDownLabel.Text = 'Dimension 2';
            app.Dimension2DropDown = uidropdown(app.GridLayout2);
            app.Dimension2DropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.ROImapTab = uitab(app.TabGroup);
            app.ROImapTab.Title = 'ROI map';
            app.roiMapAxes = uiaxes(app.ROImapTab);
            xlabel(app.roiMapAxes, 'X')
            ylabel(app.roiMapAxes, 'Y')
            zlabel(app.roiMapAxes, 'Z')
            colormap(app.roiMapAxes, 'gray')
            app.NeuronsListBox = uilistbox(app.GridLayout);
            app.NeuronsListBox.Multiselect = 'on';
            app.NeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @NeuronsListBoxValueChanged, true);
            app.NeuronsListBox.Value = {'Item 1'};
            app.SelectNewButton = uibutton(app.GridLayout, 'push');
            app.SelectNewButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNewButtonPushed, true);
            app.SelectNewButton.Text = 'Select New';
            app.QuantSepEditField = uieditfield(app.GridLayout, 'numeric');
            app.QuantSepEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.QuantSepEditFieldLabel = uilabel(app.GridLayout);
            app.QuantSepEditFieldLabel.Text = 'Quant Sep';
            app.SeparationEditField = uieditfield(app.GridLayout, 'numeric');
            app.SeparationEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.SeparationEditField.Value = 2;
            app.SeparationEditFieldLabel = uilabel(app.GridLayout);
            app.SeparationEditFieldLabel.Text = 'Separation';
            app.InvertCheckBox = uicheckbox(app.GridLayout);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Text = 'Invert';
            app.InvertCheckBox.Value = true;
            app.ThingstoplotListBoxLabel = uilabel(app.GridLayout);
            app.ThingstoplotListBoxLabel.Text = 'Things to plot';
            app.DimensionPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.DimensionPanel);
            app.DimensionsToPlotListBoxLabel = uilabel(app.GridLayout3);
            app.DimensionsToPlotListBoxLabel.Text = 'Dimensions To Plot';
            app.DimensionsToPlotListBox = uilistbox(app.GridLayout3);
            app.DimensionsToPlotListBox.Multiselect = 'on';
            app.DimensionsToPlotListBox.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.DimensionsToPlotListBox.ClickedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.DimensionsToPlotListBox.Value = {'Item 1'};
            app.ApplyindividualCheckBox = uicheckbox(app.GridLayout3);
            app.ApplyindividualCheckBox.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.ApplyindividualCheckBox.Text = 'Apply individual';
            app.ThingsToPlotListBox = uilistbox(app.GridLayout);
            app.ThingsToPlotListBox.Items = {'Average', 'PCs', 'Means of quantiles stim index', 'Means of quantiles regressand', 'Ridge', 'Lasso', 'Elastic Net', 'PLS dimensions', 'PLS BETA', 'PLS VIP', 'Correlation matrix'};
            app.ThingsToPlotListBox.ItemsData = [1 2 5 6 7 8 9 10 13 14 15];
            app.ThingsToPlotListBox.Multiselect = 'on';
            app.ThingsToPlotListBox.ValueChangedFcn = createCallbackFcn(app, @ThingsToPlotListBoxValueChanged, true);
            app.ThingsToPlotListBox.Value = 1;
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Visible = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.xminEditField = uieditfield(app.Panel, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @updateStimAlignedDataCallback, true);
            app.xminEditField.Value = -0.5;
            app.predictorrangeEditFieldLabel = uilabel(app.Panel);
            app.predictorrangeEditFieldLabel.Text = 'predictor range';
            app.regressorRangeEditFieldmin = uieditfield(app.Panel, 'numeric');
            app.regressorRangeEditFieldmin.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.regressorRangeEditFieldmin.Value = -0.5;
            app.regressorRangeEditFieldLabel_2 = uilabel(app.Panel);
            app.regressorRangeEditFieldLabel_2.Text = '-';
            app.regressorRangeEditFieldmax = uieditfield(app.Panel, 'numeric');
            app.regressorRangeEditFieldmax.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.regressorRangeEditFieldmax.Value = -0.001;
            app.Panel_2 = uipanel(app.GridLayout);
            app.predictionrangeEditFieldLabel = uilabel(app.Panel_2);
            app.predictionrangeEditFieldLabel.Text = 'prediction range';
            app.regressandRangeEditFieldMin = uieditfield(app.Panel_2, 'numeric');
            app.regressandRangeEditFieldMin.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.regressandRangeEditFieldMin.Value = 0.01;
            app.regressandRangeEditFieldLabel_3 = uilabel(app.Panel_2);
            app.regressandRangeEditFieldLabel_3.Text = '-';
            app.regressandRangeEditFieldMax = uieditfield(app.Panel_2, 'numeric');
            app.regressandRangeEditFieldMax.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.regressandRangeEditFieldMax.Value = 0.2;
            app.xmaxEditField = uieditfield(app.Panel_2, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @updateStimAlignedDataCallback, true);
            app.xmaxEditField.Value = 1.9;
            app.RegressagainstDropDownLabel = uilabel(app.GridLayout);
            app.RegressagainstDropDownLabel.Text = 'Regress against';
            app.RegressagainstDropDown = uidropdown(app.GridLayout);
            app.RegressagainstDropDown.Items = {'Own'};
            app.RegressagainstDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.RegressagainstDropDown.Value = 'Own';
            app.RegressandDropDownLabel = uilabel(app.GridLayout);
            app.RegressandDropDownLabel.Text = 'Regressand:';
            app.RegressandDropDown = uidropdown(app.GridLayout);
            app.RegressandDropDown.Items = {'AOC', 'latency of max', 'latency of min', 'max', 'min'};
            app.RegressandDropDown.ItemsData = [1 2 3 4 5];
            app.RegressandDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.RegressandDropDown.Value = 1;
            app.GenerateMoviesslowButton = uibutton(app.GridLayout, 'push');
            app.GenerateMoviesslowButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMoviesslowButtonPushed, true);
            app.GenerateMoviesslowButton.Text = 'Generate Movies (slow)';
            app.GroupsEditFieldLabel = uilabel(app.GridLayout);
            app.GroupsEditFieldLabel.Text = '# Groups:';
            app.GroupsEditField = uieditfield(app.GridLayout, 'numeric');
            app.GroupsEditField.Limits = [1 Inf];
            app.GroupsEditField.ValueChangedFcn = createCallbackFcn(app, @GroupsEditFieldValueChanged, true);
            app.GroupsEditField.Tooltip = {'Number of groups to break detected stims into.'};
            app.GroupsEditField.Value = 1;
            app.PlotGroupsListBoxLabel = uilabel(app.GridLayout);
            app.PlotGroupsListBoxLabel.Text = 'Plot Groups';
            app.PlotGroupsListBox = uilistbox(app.GridLayout);
            app.PlotGroupsListBox.Items = {'Group 1'};
            app.PlotGroupsListBox.ItemsData = 1;
            app.PlotGroupsListBox.ValueChangedFcn = createCallbackFcn(app, @updateStimAlignedDataCallback, true);
            app.PlotGroupsListBox.Value = 1;
            app.GroupSepEditField = uieditfield(app.GridLayout, 'numeric');
            app.GroupSepEditField.Value = 5;
            app.GroupSepEditFieldLabel = uilabel(app.GridLayout);
            app.GroupSepEditFieldLabel.Text = 'Group Sep';
            app.HPfreqHzEditFieldLabel = uilabel(app.GridLayout);
            app.HPfreqHzEditFieldLabel.Text = 'HP freq (Hz)';
            app.HPfreqHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.HPfreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @HPfreqHzEditFieldValueChanged, true);
            app.HPfreqHzEditField.Value = 0.5;
            app.ColorSchemeDropDownLabel = uilabel(app.GridLayout);
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme';
            app.ColorSchemeDropDown = uidropdown(app.GridLayout);
            app.ColorSchemeDropDown.Items = {'Accent | Qualitative', 'Blues | Sequential', 'PuBuGn | Sequential', 'Dark2 | Qualitative', 'BuGn | Sequential', 'PuRd | Sequential', 'Paired | Qualitative', 'BuPu | Sequential', 'Purples | Sequential', 'Pastel1 | Qualitative', 'GnBu | Sequential', 'RdPu | Sequential', 'Pastel2 | Qualitative', 'Greens | Sequential', 'Reds | Sequential', 'RdGy | Diverging', 'Set1 | Qualitative', 'Greys | Sequential', 'YlGn | Sequential', 'Set2 | Qualitative', 'OrRd | Sequential', 'YlGnBu | Sequential', 'Set3 | Qualitative', 'Oranges | Sequential', 'YlOrBr | Sequential', 'PuBu | Sequential', 'YlOrRd | Sequential', 'BrBG | Diverging', 'PiYG | Diverging', 'PRGn | Diverging', 'PuOr | Diverging', 'RdBu | Diverging', 'RdYlBu | Diverging', 'RdYlGn | Diverging', 'Spectral | Diverging'};
            app.ColorSchemeDropDown.Value = 'Dark2 | Qualitative';
            app.FileDropDown = uidropdown(app.GridLayout);
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileLabel = uilabel(app.GridLayout);
            app.FileLabel.Text = 'File';
            app.badframesEditFieldLabel = uilabel(app.GridLayout);
            app.badframesEditFieldLabel.Text = '# bad frames';
            app.badframesEditField = uieditfield(app.GridLayout, 'numeric');
            app.badframesEditField.ValueChangedFcn = createCallbackFcn(app, @badframesEditFieldValueChanged, true);
            app.badframesEditField.Value = 50;
            app.LockPlotCheckBox = uicheckbox(app.GridLayout);
            app.LockPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.LockPlotCheckBox.Text = 'Lock Plot';
            app.SmoothingEditFieldLabel = uilabel(app.GridLayout);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.GridLayout, 'numeric');
            app.SmoothingEditField.Limits = [0 Inf];
            app.SmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingEditFieldValueChanged, true);
            app.QuantilesEditField = uieditfield(app.GridLayout, 'numeric');
            app.QuantilesEditField.Limits = [1 Inf];
            app.QuantilesEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlotCallback, true);
            app.QuantilesEditField.Value = 4;
            app.QuantilesEditFieldLabel = uilabel(app.GridLayout);
            app.QuantilesEditFieldLabel.Text = 'Quantiles';
            app.Panel_4 = uipanel(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.UIAxes4 = uiaxes(app.GridLayout5);
            title(app.UIAxes4, 'Title')
            zlabel(app.UIAxes4, 'Z')
            app.Slider = uislider(app.GridLayout5);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.PlayButton = uibutton(app.GridLayout5, 'push');
            app.PlayButton.Text = 'Play';
            app.minBackgroundEdit = uieditfield(app.GridLayout5, 'numeric');
            app.ImagerangeLabel = uilabel(app.GridLayout5);
            app.ImagerangeLabel.Text = 'Image range';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.SmoothingLabel = uilabel(app.GridLayout5);
            app.SmoothingLabel.Text = 'Smoothing';
            app.EditFieldLabel = uilabel(app.GridLayout5);
            app.EditFieldLabel.Text = '-';
            app.maxBackgroundEdit = uieditfield(app.GridLayout5, 'numeric');
            app.maxBackgroundEdit.Value = 255;
            app.DeltarangeLabel = uilabel(app.GridLayout5);
            app.DeltarangeLabel.Text = 'Delta range';
            app.MaxDeltaEdit = uieditfield(app.GridLayout5, 'numeric');
            app.MaxDeltaEdit.Value = 3;
            app.speedLabel = uilabel(app.GridLayout5);
            app.speedLabel.Text = 'speed';
            app.PlaybackspeedEdit = uieditfield(app.GridLayout5, 'numeric');
            app.xyEditFieldLabel = uilabel(app.GridLayout5);
            app.xyEditFieldLabel.Text = 'xy';
            app.xySmoothEdit = uieditfield(app.GridLayout5, 'numeric');
            app.xySmoothEdit.Value = 2;
            app.tEditFieldLabel = uilabel(app.GridLayout5);
            app.tEditFieldLabel.Text = 't';
            app.tSmoothEdit = uieditfield(app.GridLayout5, 'numeric');
            app.tSmoothEdit.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = time_series_plots(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)Startup(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end