classdef annotateROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SkipnondoubletsCheckBox     matlab.ui.control.CheckBox
        toggleDoubletdButton        matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        CLimSlider                  matlab.ui.control.RangeSlider
        CLimSliderLabel             matlab.ui.control.Label
        ExportButton                matlab.ui.control.Button
        ROIsListBox                 matlab.ui.control.ListBox
        ROIsListBoxLabel            matlab.ui.control.Label
        nButton                     matlab.ui.control.Button
        yButton                     matlab.ui.control.Button
        FOVsizeEditField            matlab.ui.control.NumericEditField
        FOVsizeEditFieldLabel       matlab.ui.control.Label
        NeighborhoodEditField       matlab.ui.control.NumericEditField
        NeighborhoodEditFieldLabel  matlab.ui.control.Label
        ROIEditField                matlab.ui.control.NumericEditField
        PercentileROILabel          matlab.ui.control.Label
        MetricDropDown              matlab.ui.control.DropDown
        MetricDropDownLabel         matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        NeighborhoodszEditField     matlab.ui.control.NumericEditField
        NeighborhoodszLabel         matlab.ui.control.Label
        ForwardButton               matlab.ui.control.Button
        BackButton                  matlab.ui.control.Button
    end

    properties (Access = private)
        ROIVolume % 3d volume with ROI IDs
        imageVol
        imageArray
        contourArr
        outputTable
        tileAxesArray
        tiledLayout
        ROIVolumeAll
        voltronVolume
    end
    properties (Access = private,Constant)
        tableCols = {'CellID', 'Center_X', 'Center_Y', 'Center_Z', ...
                                                  'MeanROIIntensity', 'MeanNeighborhoodIntensity', ...
                                                  'MetricValue','UserLabel','roiSeen','isDoublet'}
    end

    properties(Dependent, Access = private)
        roiDex
        roiDexROIListBox
        nROI
    end

    methods
        function roiDexROIListBox = get.roiDexROIListBox(app)
            roiDexROIListBox = find(app.ROIsListBox.ItemsData==app.ROIsListBox.Value);
        end
        function roiDex = get.roiDex(app)
            roiDex = app.ROIsListBox.Value;
        end
        function nROI = get.nROI(app)
            nROI = length(app.isROIGoodArr);
        end
    end
    
    methods (Access = private)
        function resultTable = makeTable(app)
            % Precompute structuring element
            se = strel('disk', app.NeighborhoodszEditField.Value);
            
            % Get unique ROI indices
            indices = unique(app.ROIVolume(:));
            indices(indices == 0) = []; % Remove background if labeled as 0
            UserLabel = zeros(size(indices));

            metricType = app.MetricDropDown.Value;
            % Preallocate metric values array
            metricVals = zeros(size(indices));
            roiSeen = zeros(size(indices));
            isDoublet = zeros(size(indices));
            roiPercentile = app.ROIEditField.Value;
            neighborhoodPercentile = app.NeighborhoodEditField.Value;
            stats = regionprops3(app.ROIVolume, 'BoundingBox', 'Centroid');

            meanROIIntensities = zeros(numel(indices), 1);
            meanNeighborhoodIntensities = zeros(numel(indices), 1);
            % Use parfor to parallelize the loop
            for idx = 1:numel(indices)
                index = indices(idx);
                
                % Get the ROI mask
                
                
                % Obtain bounding box for the ROI
                
                bbox = ceil(stats(idx,:).BoundingBox);
                
                % Define sub-volume ranges (assuming 2D data)
                rowRange = bbox(2):(bbox(2) + bbox(5) - 1);
                colRange = bbox(1):(bbox(1) + bbox(4) - 1);
                zRange = bbox(3):(bbox(3) + bbox(6) - 1);
                
                % Ensure ranges are within image bounds
                rowRange = max(1, rowRange);
                colRange = max(1, colRange);
                zRange = max(1, zRange);
                rowRange = min(size(app.ROIVolume, 1), rowRange);
                colRange = min(size(app.ROIVolume, 2), colRange);
                zRange = min(size(app.ROIVolume, 3), zRange);
                % Extract sub-volumes
                
                imageSub = app.imageVol(rowRange, colRange,zRange);
                roivolSub = app.ROIVolume(rowRange, colRange,zRange);
                roivolAllSub = app.ROIVolumeAll(rowRange, colRange,zRange);
                roiSub = roivolSub==index;
                % Perform dilation and neighborhood masking on sub-volume
                roiNeighborhood = imdilate(roiSub, se);
                roiNeighborhood(roivolAllSub > 0) = 0;
                
                % Extract ROI and neighborhood values
                roiValues = single(imageSub(roiSub));
                neighborhoodValues = single(imageSub(roiNeighborhood));
                

                meanROIIntensities(idx) = mean(roiValues);
                meanNeighborhoodIntensities(idx) = mean(neighborhoodValues);

                % Compute metric based on metricType
                switch metricType
                    case 1 % Z-score
                        roiPercentileValue = prctile(roiValues, roiPercentile);
                        neighborhoodPercentileValue = prctile(neighborhoodValues, neighborhoodPercentile);
                        neighborhoodStd = std(neighborhoodValues)./sqrt(numel(roiValues));
                        metricValue = (roiPercentileValue - neighborhoodPercentileValue) / neighborhoodStd;
                    
                    case 2 % Z-score mean
                        roiMean = mean(roiValues);
                        neighborhoodMean = mean(neighborhoodValues);
                        neighborhoodStd = std(neighborhoodValues)./sqrt(numel(roiValues));
                        metricValue = (roiMean - neighborhoodMean) / neighborhoodStd;
                    
                    case 3 % Ratio
                        roiPercentileValue = prctile(roiValues, roiPercentile);
                        neighborhoodPercentileValue = prctile(neighborhoodValues, neighborhoodPercentile);
                        metricValue = roiPercentileValue / neighborhoodPercentileValue;
                    
                    case 4 % Ratio mean
                        roiMean = mean(roiValues);
                        neighborhoodMean = mean(neighborhoodValues);
                        metricValue = roiMean / neighborhoodMean;
                    
                    otherwise
                        metricValue = NaN;
                end
                metricVals(idx) = metricValue;
            end
            centroids = stats.Centroid;
            % Create the table
            resultTable = table(indices, ...
                                centroids(:, 1), centroids(:, 2), centroids(:, 3), ...
                                meanROIIntensities, meanNeighborhoodIntensities, ...
                                metricVals, UserLabel, roiSeen,isDoublet, ...
                                'VariableNames', app.tableCols);
        end

        function updateOrderAndROIList(app)
            metricVals = app.outputTable.MetricValue;
            indices = app.outputTable.CellID;
            [metricVals,order] = sort(metricVals);
            indices = indices(order);
            strings = "ROI # " + indices + " (" + metricVals + ")";
            removeStyle(app.ROIsListBox);
            app.ROIsListBox.Items = strings;
            app.ROIsListBox.ItemsData = indices;
            for i = 1:size(app.outputTable,1)
                dex = indices(i);
                item = app.outputTable(app.outputTable.CellID==dex,:);
                if item.roiSeen
                    if item.UserLabel
                        color = "green";
                    else
                        color = "red";
                    end

                    style = uistyle('BackgroundColor', color);
                    addStyle(app.ROIsListBox, style, 'item', i);
                end
                if item.isDoublet
                    % Apply black border style
                    style = uistyle('Icon', 'warning');
                    style.FontWeight = 'bold';
                    addStyle(app.ROIsListBox, style, 'item', i);
                end
            end
        end


        function drawImages(app)
            % Find the indices where ROIVolume equals its maximum value
            found = find(app.ROIVolume == max(app.roiDex));
            [x, y, z] = ind2sub(size(app.ROIVolume), found);
            
            % Retrieve FOV size from the edit field
            FOVsize = app.FOVsizeEditField.Value;
            
            % Calculate the centroid of the ROI
            x_center = mean(x, 'omitnan');
            y_center = mean(y, 'omitnan');
            
            % Define half of the FOV size
            halfFOV = floor(FOVsize / 2);
            
            % Calculate xRange and yRange centered around the centroid
            xRange = [round(x_center) - halfFOV, round(x_center) + halfFOV];
            yRange = [round(y_center) - halfFOV, round(y_center) + halfFOV];
            
            % Clamp xRange and yRange within image bounds
            xRange = max(xRange, 1);
            xRange = min(xRange, size(app.imageVol, 1));
            yRange = max(yRange, 1);
            yRange = min(yRange, size(app.imageVol, 2));
            
            % Ensure the ranges are exactly FOVsize
            xRange = [xRange(1), min(xRange(1) + FOVsize - 1, size(app.imageVol, 1))];
            yRange = [yRange(1), min(yRange(1) + FOVsize - 1, size(app.imageVol, 2))];
            
            xStart = max(1, xRange(1));
            xEnd = min(xRange(2), size(app.imageVol, 1));
            yStart = max(1, yRange(1));
            yEnd = min(yRange(2), size(app.imageVol, 2));
            % Calculate the zRange
            zRange = [max(min(z), 1), min(max(z), size(app.imageVol, 3))];
            
            % Calculate the mean value for padding
            meanValue = mean(app.imageVol(max(1, xRange(1)):min(xRange(2), size(app.imageVol, 1)), ...
                                          max(1, yRange(1)):min(yRange(2), size(app.imageVol, 2)), ...
                                          max(1, zRange(1)):min(zRange(2), size(app.imageVol, 3))), ...
                            'all', 'omitnan');
            meanValue = mean(app.voltronVolume(max(1, xRange(1)):min(xRange(2), size(app.imageVol, 1)), ...
                                          max(1, yRange(1)):min(yRange(2), size(app.imageVol, 2)), ...
                                          max(1, zRange(1)):min(zRange(2), size(app.imageVol, 3))), ...
                            'all', 'omitnan');
            
            % Determine the slice indices
            slices = zRange(1):zRange(2);
            numSlices = numel(slices);
            
            % Select central 8 slices if more than 8
            if numSlices > 5
                centerIdx = ceil(numSlices / 2);
                slices = slices(max(centerIdx - 2, 1):min(centerIdx + 2, numSlices));
            end
            
            % Ensure exactly 8 slices
            slices = slices(1:min(5, numSlices));
            numSlices = numel(slices);
            
            % Target size for all slices based on FOVsize
            targetXSize = FOVsize;
            targetYSize = FOVsize;
            % Display each slice in a tile
            for i = 1:numSlices
                
                currentSlice = slices(i);
                sliceData = app.imageVol(:, :, currentSlice);
                sliceDataVolt = app.voltronVolume(:, :, currentSlice);
                
                % Initialize the padded slice with the mean value
                paddedSlice = meanValue * ones(targetXSize, targetYSize, 'like', sliceData);
                paddedSliceVolt = meanValue * ones(targetXSize, targetYSize, 'like', sliceDataVolt);
                
                % Determine the overlap between the desired range and actual data
                
                % Calculate where to place the actual data in the padded slice
                padXStart = 1 + (xStart - xRange(1));
                padXEnd = padXStart + (xEnd - xStart);
                padYStart = 1 + (yStart - yRange(1));
                padYEnd = padYStart + (yEnd - yStart);
                
                paddedROI = zeros(size(paddedSlice));
                paddedSlice(padXStart:padXEnd, padYStart:padYEnd) = sliceData(xStart:xEnd, yStart:yEnd);
                paddedROI(padXStart:padXEnd, padYStart:padYEnd) = app.ROIVolume(xStart:xEnd, yStart:yEnd,currentSlice);
                paddedROI = paddedROI==app.roiDex;

                paddedSliceVolt(padXStart:padXEnd, padYStart:padYEnd) = sliceDataVolt(xStart:xEnd, yStart:yEnd);
                % Normalize paddedSliceVolt to match the range of paddedSlice
                % Cast to single to prevent issues with integer types
                paddedSlice = single(paddedSlice);
                paddedSliceVolt = single(paddedSliceVolt);
                
                % Normalize paddedSliceVolt to match the range of paddedSlice
                minPaddedSlice = min(paddedSlice(:));
                maxPaddedSlice = max(paddedSlice(:));
                
                minPaddedSliceVolt = min(paddedSliceVolt(:));
                maxPaddedSliceVolt = max(paddedSliceVolt(:));
                
                % Normalize paddedSliceVolt
                paddedSliceVolt = (paddedSliceVolt - minPaddedSliceVolt) / (maxPaddedSliceVolt - minPaddedSliceVolt); % Scale to [0, 1]
                paddedSliceVolt = paddedSliceVolt * (maxPaddedSlice - minPaddedSlice) + minPaddedSlice; % Scale to [minPaddedSlice, maxPaddedSlice]

                
                app.imageArray(i+1).CData = cat(2,paddedSlice,paddedSliceVolt);
                
                % Draw contours for the current slice
                if numel(app.contourArr) >= i && isgraphics(app.contourArr(i+1))
                    delete(app.contourArr(i+1));
                end

                [~,app.contourArr(i+1)] = contour(app.tileAxesArray(i+1), cat(2,paddedROI,paddedROI), [1 1], 'LineColor', 'r', 'LineWidth', 2);
            end
            
            % Clear remaining tiles if fewer than 8 slices
            for i = numSlices + 2:6
                if numel(app.imageArray) >= i && isgraphics(app.imageArray(i))
                    app.imageArray(i).CData(:) = 0;
                end
                if numel(app.contourArr) >= i && isgraphics(app.contourArr(i))
                    delete(app.contourArr(i));
                end
            end
            if isgraphics(app.contourArr(1))
                delete(app.contourArr(1));
            end
            app.contourArr(1) = rectangle(app.tileAxesArray(1), ...
                    'Position', [yStart, xStart, app.FOVsizeEditField.Value, app.FOVsizeEditField.Value], ...
                    'EdgeColor', 'r', ...  % Red edge color
                    'LineWidth', 2);       % Line thickness     
        end


        function incrementDex(app)
            newDex = find(app.ROIsListBox.ItemsData==app.ROIsListBox.Value) + 1;
            if app.SkipnondoubletsCheckBox.Value && any(app.outputTable.isDoublet)
                while(~app.outputTable.isDoublet(app.ROIsListBox.ItemsData(newDex)))
                    newDex = mod(newDex,length(app.ROIsListBox.Items)) + 1;
                end
            end
            if newDex>numel(app.ROIsListBox.ItemsData)
                app.ROIsListBox.Value  = app.ROIsListBox.ItemsData(1);
            else
                app.ROIsListBox.Value  = app.ROIsListBox.ItemsData(newDex);
            end
        end

        function decrementDex(app)
            newDex = find(app.ROIsListBox.ItemsData==app.ROIsListBox.Value) - 1;
            if newDex<1
                app.ROIsListBox.Value  = app.ROIsListBox.ItemsData(end);
            else
                app.ROIsListBox.Value  = app.ROIsListBox.ItemsData(newDex);
            end
        end
        
        function recalcMetricAndReorder(app)
            % Recalculate metrics and update the ROI list
            newTable = app.makeTable(); % Recalculate metrics table
            for i = 1:size(app.outputTable,1)
                newTable(newTable.CellID==app.outputTable(i,:).CellID,:).UserLabel = app.outputTable(i,:).UserLabel;
                newTable(newTable.CellID==app.outputTable(i,:).CellID,:).roiSeen = app.outputTable(i,:).roiSeen;
                newTable(newTable.CellID==app.outputTable(i,:).CellID,:).isDoublet = app.outputTable(i,:).isDoublet;
            end
            app.outputTable = newTable; % Update table property
            app.updateOrderAndROIList();
        end

        
        function style = getCurrentROIStyle(app)
            style = uistyle();

            % Locate styles applied to the specific row
            for i = 1:size(app.ROIsListBox.StyleConfigurations,1)
                if isequal(app.ROIsListBox.StyleConfigurations.TargetIndex{i}, app.roiDexROIListBox)
                    style = app.ROIsListBox.StyleConfigurations.Style(i);
                    removeStyle(app.ROIsListBox,i);
                    break
                end
            end
        end
    end
    
    methods (Static)
        function [nChan,useSamples] = getNumChannelsTif(imPath)
            nChan=1;
            file_info=imfinfo(imPath);
            useSamples = file_info(1).SamplesPerPixel>1;
            if useSamples
                nChan = file_info(1).SamplesPerPixel; return
            end
            
            if ~isfield(file_info(1),"ImageDescription")
                nChan=1;
                return
            end
            a = split(file_info(1).ImageDescription,newline);
            a = a(contains(a,'='));
            a=split(a,'=');
            [row,col] = find(strcmp(a,'channels'));
            if (isempty(row) || isempty(col))
                return
            end
            nChan= str2double(a(row,col+1));
        end
        function vol = myTiffreadVolume(imPath)
            nChan=1;
            file_info=imfinfo(imPath);
            useSamples = file_info(1).SamplesPerPixel>1;
            if useSamples
                nChan = file_info(1).SamplesPerPixel;
            elseif ~isfield(file_info(1),"ImageDescription")
                nChan=1;
            else
                a = split(file_info(1).ImageDescription,newline);
                a = a(contains(a,'='));
                a=split(a,'=');
                [row,col] = find(strcmp(a,'channels'));
                if ~(isempty(row) || isempty(col))
                    nChan= str2double(a(row,col+1));
                end
            end
            vol = tiffreadVolume(imPath);
            if nChan > 1
                % Create a popup dialog for channel selection
                options = arrayfun(@num2str, 1:nChan, 'UniformOutput', false);
                [~,fn] = fileparts(imPath);
                [selection, ok] = listdlg('PromptString', "Select a channel for " + fn + ':', ...
                                          'SelectionMode', 'single', ...
                                          'ListString', options,...
                                          'ListSize', [750, 150]);
                if ok
                    if ndims(vol)==3
                        vol = vol(:, :, selection:nChan:end);
                    elseif ndims(vol)==4
                        vol = vol(:, :, :, selection);
                    else
                        error('unexpected vol shape');
                    end
                else
                    error('Channel selection was canceled.');
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imageFile1, ROIFileVoltron, ROIFileAll, VoltronVolume)
            if nargin<2 || isempty(imageFile1)
                prompt = 'Select image file 1';
                rootDir = getpref(class(app),'lastPath',pwd);
                REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
                imageFile1 = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
                if isfolder(fileparts(imageFile1))
                    setpref(class(app),'lastPath',fileparts(imageFile1))
                end
            end
            if nargin<3 || isempty(ROIFileVoltron)
                prompt = 'Select Voltron ROI file';
                rootDir = getpref(class(app),'lastPath',pwd);
                REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
                ROIFileVoltron = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
                if isfolder(fileparts(ROIFileVoltron))
                    setpref(class(app),'lastPath',fileparts(ROIFileVoltron))
                end
            end
            if nargin<4 || isempty(ROIFileAll)
                prompt = 'Select ALL ROI file';
                rootDir = getpref(class(app),'lastPath',pwd);
                REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
                ROIFileAll = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
                if isfolder(fileparts(ROIFileAll))
                    setpref(class(app),'lastPath',fileparts(ROIFileAll))
                end
            end
            if nargin<5 || isempty(VoltronVolume)
                prompt = 'Select voltron vol file';
                rootDir = getpref(class(app),'lastPath',pwd);
                REFilter ='\.(tif(f?)|(mat))$'; %'\.tif(f?)$'
                VoltronVolume = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
                if isfolder(fileparts(VoltronVolume))
                    setpref(class(app),'lastPath',fileparts(VoltronVolume))
                end
            end
            app.tiledLayout = tiledlayout(app.Panel,3,3,'TileSpacing','tight','Padding','tight');
            app.tileAxesArray = gobjects(1, 9); % Preallocate an array of graphics objects
            app.imageArray = gobjects(1, 9); % Preallocate an array of graphics objects
            app.contourArr = gobjects(1, 9); % Preallocate an array of graphics objects
            for i = 1:6
                if i ==1
                    app.tileAxesArray(i) = nexttile(app.tiledLayout,[2,2]);
                else
                    app.tileAxesArray(i) = nexttile(app.tiledLayout);
                end

                app.imageArray(i) = imagesc(app.tileAxesArray(i), zeros(app.FOVsizeEditField.Value,app.FOVsizeEditField.Value*2));
                
                colormap(app.tileAxesArray(i), 'gray');
                hold(app.tileAxesArray(i), 'on');
                axis(app.tileAxesArray(i), 'image');
                axis(app.tileAxesArray(i), 'off');
            end
            delete(app.imageArray(1));


            app.imageVol = app.myTiffreadVolume(imageFile1);
            app.ROIVolume = app.myTiffreadVolume(ROIFileVoltron);
            app.ROIVolumeAll = app.myTiffreadVolume(ROIFileAll);
            app.voltronVolume = app.myTiffreadVolume(VoltronVolume);
            app.imageArray(1) = imagesc(app.tileAxesArray(1), max(app.imageVol,[],3));
            uniqueVals = unique(app.ROIVolume);
            uniqueVals(uniqueVals==0) = [];
            
            app.outputTable = app.makeTable();
            app.updateOrderAndROIList()
            
            if isfield(app.ForwardButton,'Interpreter')
                app.ForwardButton.Interpreter = 'latex';
                app.ForwardButton.Text = 'Forward (\rightarrow)';
                app.BackButton.Interpreter = 'latex';
                app.ForwardButton.Text = 'Back (\leftarrow)';
            end
            

            colorLims = prctile(app.imageVol(:), [0.1, 99.9],'all');
            for i = 1:6
                clim(app.tileAxesArray(i),colorLims);
            end
            app.CLimSlider.Limits = double(prctile(app.imageVol(:), [0.01, 99.99],'all'));
            app.drawImages();
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            app.drawImages();
        end

        % Window key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'y'
                    app.yButtonPushed();
                    app.drawImages();
                    return
                case 'n'
                    app.nButtonPushed();
                    app.drawImages();
                    return
                case 'd'
                    app.toggleDoubletdButtonPushed();
                    return
                case {'rightarrow', 'downarrow'}
                    app.incrementDex();
                    app.drawImages();
                    return
                case {'leftarrow', 'uparrow'}
                    app.decrementDex();
                    app.drawImages();
                    return
                otherwise
                    return;
            end
        end

        % Button pushed function: yButton
        function yButtonPushed(app, event)
            app.outputTable(app.outputTable.CellID == app.roiDex,:).roiSeen = 1;
            app.outputTable(app.outputTable.CellID == app.roiDex,:).UserLabel = 1;
            style = app.getCurrentROIStyle();
            style.BackgroundColor='green';
            
            addStyle(app.ROIsListBox, style, 'item', app.roiDexROIListBox);
            app.incrementDex();
        end

        % Button pushed function: nButton
        function nButtonPushed(app, event)
            app.outputTable(app.outputTable.CellID == app.roiDex,:).roiSeen = 1;
            app.outputTable(app.outputTable.CellID == app.roiDex,:).UserLabel = 0;
            style = app.getCurrentROIStyle();
            style.BackgroundColor='red';
            addStyle(app.ROIsListBox, style, 'item', app.roiDexROIListBox);
            app.incrementDex();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            minNo = min(app.outputTable.MetricValue(app.outputTable.roiSeen & ~app.outputTable.UserLabel));
            maxYes = max(app.outputTable.MetricValue(app.outputTable.roiSeen & app.outputTable.UserLabel));
            app.outputTable.UserLabel(~app.outputTable.roiSeen & app.outputTable.MetricValue>maxYes) = 1;
            app.outputTable.UserLabel(~app.outputTable.roiSeen & app.outputTable.MetricValue<minNo) = 0;
            [fileName, filePath] = uiputfile('*.csv', 'Save Table as CSV');
            fullFileName = fullfile(filePath, fileName);
            writetable(app.outputTable, fullFileName);
        end

        % Value changed function: MetricDropDown, NeighborhoodEditField, 
        % ...and 2 other components
        function MetricDropDownValueChanged(app, event)
            app.recalcMetricAndReorder();       
        end

        % Value changed function: FOVsizeEditField
        function FOVsizeEditFieldValueChanged(app, event)
            for i = 2:6
                delete(app.imageArray(i));
                app.imageArray(i) = imagesc(app.tileAxesArray(i), zeros(app.FOVsizeEditField.Value));
            end
            app.drawImages();
        end

        % Callback function: CLimSlider, CLimSlider
        function CLimSliderValueChanged(app, event)
            for i = 1:6
                clim(app.tileAxesArray(i),app.CLimSlider.Value);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
        % Prompt for CSV file
        prompt = 'Select CSV file';
        rootDir = getpref(class(app), 'lastPath', pwd);
        REFilter = '\.(csv)$';
        csvFile = uipickfiles('FilterSpec', rootDir, 'REFilter', REFilter, ...
                              'Prompt', prompt, 'Output', 'char');
        if isempty(csvFile)
            warning('No file selected. Operation cancelled.');
            return;
        end
    
        % Read table and extract column names
        importTable = readtable(csvFile);
        csvColumns = importTable.Properties.VariableNames;
    
        % Initialize required column data
        nEntries = height(importTable);
        columnData.CellID = [];
        columnData.UserLabel = zeros(nEntries, 1);  % Default to zeros
        columnData.isDoublet = zeros(nEntries, 1); % Default to zeros
    
        % Define required columns
        requiredColumns = ["CellID"];
        optionalColumns = ["UserLabel", "isDoublet"];
        allCols = [requiredColumns,optionalColumns];
        
        % Map columns to required fields
        for i = 1:numel(allCols)
            colName = allCols(i);
            % if ismember(colName, csvColumns)
            %    columnData.(colName) = importTable.(colName);
            %    continue;
            % end

            prompt = sprintf('Select the CSV column corresponding to "%s":', colName);
            [selectionIdx, ok] = listdlg('PromptString', prompt, ...
                                         'SelectionMode', 'single', ...
                                         'ListString', csvColumns, 'ListSize', [500, 150]);
            if ok 
                columnData.(colName) = importTable.(csvColumns{selectionIdx});
            elseif ismember(colName,requiredColumns)
                    warning(colName + " is a required column for loading");
                    return
            elseif strcmp(colName, 'UserLabel')
                % Map current UserLabel if possible
                for j = 1:size(app.outputTable, 1)
                    matchIdx = find(columnData.CellID == app.outputTable.CellID(j), 1);
                    if ~isempty(matchIdx)
                        columnData.UserLabel(matchIdx) = app.outputTable.UserLabel(j);
                    end
                end
            end
        end
    
        % Populate outputTable with mapped data
        app.outputTable = table(columnData.CellID, columnData.UserLabel, ...
                                columnData.UserLabel, columnData.isDoublet, ...
                                'VariableNames', {'CellID', 'UserLabel', 'roiSeen', 'isDoublet'});
    
        % Recalculate metrics and reorder
        app.recalcMetricAndReorder();
        end

        % Button pushed function: toggleDoubletdButton
        function toggleDoubletdButtonPushed(app, event)
            % Get the current doublet status for the selected ROI
            isDoublet = app.outputTable.isDoublet(app.outputTable.CellID == app.roiDex);
            style = app.getCurrentROIStyle();

            if ~isDoublet
                % Mark as doublet and apply black border style
                app.outputTable.isDoublet(app.outputTable.CellID == app.roiDex) = 1;
                style.Icon='warning';
                style.FontWeight='bold';
            else
                % Unmark as doublet
                app.outputTable.isDoublet(app.outputTable.CellID == app.roiDex) = 0;
                style.Icon='';
                style.FontWeight='';
            end

            addStyle(app.ROIsListBox, style, 'item', app.roiDexROIListBox);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BackButton = uibutton(app.GridLayout, 'push');
            app.BackButton.Text = 'Back (<-)';
            app.ForwardButton = uibutton(app.GridLayout, 'push');
            app.ForwardButton.Text = 'Forward (->)';
            app.NeighborhoodszLabel = uilabel(app.GridLayout);
            app.NeighborhoodszLabel.Text = 'Neighborhood sz:';
            app.NeighborhoodszEditField = uieditfield(app.GridLayout, 'numeric');
            app.NeighborhoodszEditField.Limits = [1 Inf];
            app.NeighborhoodszEditField.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.NeighborhoodszEditField.Value = 15;
            app.Panel = uipanel(app.GridLayout);
            app.MetricDropDownLabel = uilabel(app.GridLayout);
            app.MetricDropDownLabel.Text = 'Metric:';
            app.MetricDropDown = uidropdown(app.GridLayout);
            app.MetricDropDown.Items = {'Percentile (z-score)', 'Mean (z-score)', 'Percentile (ratio)', 'Mean (ratio)'};
            app.MetricDropDown.ItemsData = [1 2 3 4];
            app.MetricDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.MetricDropDown.Value = 1;
            app.PercentileROILabel = uilabel(app.GridLayout);
            app.PercentileROILabel.Text = '% ROI:';
            app.ROIEditField = uieditfield(app.GridLayout, 'numeric');
            app.ROIEditField.Limits = [0 100];
            app.ROIEditField.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.ROIEditField.Value = 85;
            app.NeighborhoodEditFieldLabel = uilabel(app.GridLayout);
            app.NeighborhoodEditFieldLabel.Text = '% Neighborhood';
            app.NeighborhoodEditField = uieditfield(app.GridLayout, 'numeric');
            app.NeighborhoodEditField.Limits = [0 100];
            app.NeighborhoodEditField.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.NeighborhoodEditField.Value = 85;
            app.FOVsizeEditFieldLabel = uilabel(app.GridLayout);
            app.FOVsizeEditFieldLabel.Text = 'FOV size:';
            app.FOVsizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.FOVsizeEditField.ValueChangedFcn = createCallbackFcn(app, @FOVsizeEditFieldValueChanged, true);
            app.FOVsizeEditField.Value = 100;
            app.yButton = uibutton(app.GridLayout, 'push');
            app.yButton.ButtonPushedFcn = createCallbackFcn(app, @yButtonPushed, true);
            app.yButton.Text = '+ (y)';
            app.nButton = uibutton(app.GridLayout, 'push');
            app.nButton.ButtonPushedFcn = createCallbackFcn(app, @nButtonPushed, true);
            app.nButton.Text = '- (n)';
            app.ROIsListBoxLabel = uilabel(app.GridLayout);
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.GridLayout);
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {'Item 1'};
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CLimSliderLabel = uilabel(app.GridLayout);
            app.CLimSliderLabel.Text = 'CLim:';
            app.CLimSlider = uislider(app.GridLayout, 'range');
            app.CLimSlider.ValueChangedFcn = createCallbackFcn(app, @CLimSliderValueChanged, true);
            app.CLimSlider.ValueChangingFcn = createCallbackFcn(app, @CLimSliderValueChanged, true);
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.toggleDoubletdButton = uibutton(app.GridLayout, 'push');
            app.toggleDoubletdButton.ButtonPushedFcn = createCallbackFcn(app, @toggleDoubletdButtonPushed, true);
            app.toggleDoubletdButton.Text = 'toggle Doublet (d)';
            app.SkipnondoubletsCheckBox = uicheckbox(app.GridLayout);
            app.SkipnondoubletsCheckBox.Text = 'Skip non-doublets';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotateROI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end