classdef unmixV5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        AutoAllButton                 matlab.ui.control.Button
        CompositeFigButton            matlab.ui.control.Button
        ExportImageButton             matlab.ui.control.Button
        BatchProcessButton            matlab.ui.control.Button
        LoadSettingsButton            matlab.ui.control.Button
        SaveSettingsButton            matlab.ui.control.Button
        UpdateimageButton             matlab.ui.control.Button
        PlaneEditField                matlab.ui.control.NumericEditField
        PlaneLabel                    matlab.ui.control.Label
        SpectralUnmixingPanel         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        AutoEstimateButton            matlab.ui.control.Button
        unmixTable                    matlab.ui.control.Table
        ImageShiftsPanel              matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        RevertnonrigidButton          matlab.ui.control.Button
        ApplynonrigidnormxcorrButton  matlab.ui.control.Button
        shiftTable                    matlab.ui.control.Table
        LipooptionsPanel              matlab.ui.container.Panel
        FileDropDown                  matlab.ui.control.DropDown
        FileLabel                     matlab.ui.control.Label
        ChannelsPanel                 matlab.ui.container.Panel
        imageAx                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fn
        parent
        Nrow
        Ncol
        Nplanes
        Nchans
        stack
        tiffstack
        plane
        imageAxisIm
        color_button
        ymax
        xmax
        str
        chanLabel_edit
        cmin_edit
        cmax_edit
        autolim
        channelOrder
        enable_checkbox
        lipo
        lipo_checkbox
        lipomodelchans_edit
        liporemovechans_edit
        lipoAx
        LPF_checkbox
        radius_edit
        hilo_checkbox
        updateLipoModel_button
        crosshairs_checkbox
        lipoCrosshair
        bgROI
        applyNonRigidShifts
        nonRigidShifts
    end
    
    methods (Access = private)

        function getLipoParams(app)
            app.lipo.bg = zeros(app.Nchans, 1);
            app.lipo.amp = zeros(app.Nchans, 1);
            
            
            crossCoord = app.lipoCrosshair.Position;
            x = round(crossCoord(1));
            y = round(crossCoord(2));
            
            bgROIMask = createMask(app.bgROI);
            
            tmp = reshape(app.tiffstack(:,:,app.plane,:), [], app.Nchans);
            app.lipo.bg = double(squeeze(prctile(tmp(bgROIMask, :),20)));
            app.lipo.amp = double(squeeze(app.tiffstack(y,x,app.plane,:))) - app.lipo.bg(:);
            
            app.lipo.amp(app.lipo.amp<0) = 0;
        end

        function updateLipoModel(app,~)
            app.getLipoParams();
            hold (app.lipoAx, 'Off');
            plot(app.lipoAx, app.lipo.bg,DisplayName='background');
            hold (app.lipoAx, 'On');
            plot(app.lipoAx, app.lipo.amp,DisplayName='lipo');
            legend(app.lipoAx);
        end
        
        function loadTifStack(app)
            fullfn = fullfile(app.parent, app.fn);
            
            [app.tiffstack,app.Nrow,app.Ncol,app.Nchans,app.Nplanes] = app.myReadTiff(fullfn);
            if ~isempty(app.channelOrder)
                app.tiffstack = app.tiffstack(:,:,:,app.channelOrder);
            end
            app.ymax = app.Nrow;
            app.xmax = app.Ncol;
            
            defplane = ceil(app.Nplanes/2);
            prompt = {['Enter plane to load (of ' num2str(app.Nplanes) '):'],'Image size (''inf'' for maximum)'};
            dlgtitle = 'Input';
            dims = [1 size(app.tiffstack,3)];
            
            definput = {num2str(defplane), 'inf'};
            
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.plane = str2double(answer{1});
            app.stack = squeeze(app.tiffstack(:,:,app.plane,:));
        end

        function makeComposite(app)
            ncol = 3;
            nrow = ceil((app.Nchans+1)./ncol);
            
            dr = 1./nrow;
            dc = 1./ncol;
            
            figure(643);
            ax(1) = axes;
            set(ax(1), 'Units', 'Normalized', 'Position', [0 (1-dr) dc dr])
            data = app.imageAxisIm.CData;
            imagesc(ax(1),data);
            axis(ax(1),'square');
            axis(ax(1),'off');
            
            
            ax = zeros(app.Nchans, 1);
            for i = (1:app.Nchans)+1
                loc = i;
                row = ceil(loc/ncol);
                col = mod(loc-1, ncol)+1;
            
                ax(i) = axes;
                set(ax(i), 'Units', 'Normalized', 'Position', [dc*(col-1) (1-dr)-(row-1)*dr dc dr]);
            
                data = zeros(app.Nrow, app.Ncol, 3);
                clr = get(app.color_button(i), 'BackgroundColor');
                scim = getScaledImage(app, i);
            
                for j = 1:3
                    data(:,:,j) = data(:,:,j)+scim.*clr(j);
                end
            
                imagesc(axis(i),data);
                axis(i,'square');
                axis(i,'off');
            end
        end

        function switchFile(app)
            app.fn = app.FileDropDown.Value;
            
            app.loadTifStack();
            app.getImageStack()
            app.applyNonRigidShifts = 0;
        end

        function colorButtonFcn(app, chan)
            newclr = uisetcolor(app.color_button(chan));
            if numel(newclr)<3
                return
            end
            app.color_button(chan).BackgroundColor = newclr;
            app.updateImage();
        end

        function enableLims(app,num,skipUpdateImage)
            
            if ~app.autolim(num).Value
                set(app.cmin_edit(num), 'Enable', 'On');
                set(app.cmax_edit(num), 'Enable', 'On'); 
            else
                app.updateImageOne(num,-(app.enable_checkbox(num).Value*2-1));
                set(app.cmin_edit(num), 'Enable', 'Off');
                set(app.cmax_edit(num), 'Enable', 'Off');
                refIm = app.stack(:,:,num);
                a = qprctile(refIm(:), [0.1 99.995]);
            
                cmin = a(1);
                cmax = a(2);
                
                if cmax<=cmin
                    cmax = cmin+1;
                end
                
                set(app.cmin_edit(num), 'String', num2str(round(cmin)));
                set(app.cmax_edit(num), 'String', num2str(round(cmax)));
                if ~exist('skipUpdateImage','var') || ~skipUpdateImage
                    app.updateImageOne(num,app.enable_checkbox(num).Value*2-1);
                end
            end
        end

        function loadButtonFcn(app)
            
            [file,pth] = uigetfile('*.mat','Select file name', '');
            load(fullfile(pth, file),'params');
            
            app.str = params.str;
            for i = 1:app.Nchans
                set(app.chanLabel_edit(i), 'String', app.str{i});
            end
            
            for i = 1:app.Nchans
                set(app.color_button(i), 'BackgroundColor', params.clr{i});
                set(app.cmin_edit(i), 'String', params.cmin{i});
                set(app.cmax_edit(i), 'String', params.cmax{i});
                set(app.autolim(i), 'Value', params.autolim(i));
                set(app.enable_checkbox(i), 'Value', params.enable(i));
                app.enableLims(i);
            end
            
            app.shiftTable.Data = params.shiftDat;
            app.unmixTable.Data = params.unmixDat;
            
            app.lipo = params.lipo;
            set(app.lipo_checkbox, 'Value', params.lipocheck);
            
            set(app.lipomodelchans_edit, 'String', num2str(params.lipo.modelchans));
            set(app.liporemovechans_edit, 'String', num2str(params.lipo.targetchans));
            
            hold (app.lipoAx, 'Off');
            plot(app.lipoAx, app.lipo.bg);
            hold (app.lipoAx, 'On');
            plot(app.lipoAx, app.lipo.amp);
            
            
            set(app.LPF_checkbox, 'Value', params.lpf);
            set(app.radius_edit, 'String', params.radius);


            app.getImageStack();
            app.updateImage();
        end

        function lims = getFigLims(app, num)
            cmin = str2double(get(app.cmin_edit(num), 'String'));
            cmax = str2double(get(app.cmax_edit(num), 'String'));
            lims = [cmin cmax];
        end

        function scaledIm = getScaledImage(app, chan)
            scaledIm = double(app.stack(:,:,chan));
            lims = app.getFigLims(chan);
            
            scaledIm = (scaledIm - lims(1))./(lims(2)-lims(1));
            
            scaledIm(scaledIm>1) = 1;
            scaledIm(scaledIm<0) = 0;
        end

        function lipoCheckboxCallback(app)
            if app.crosshairs_checkbox.Value
                app.bgROI.Visible = 'On';
                app.lipoCrosshair.Visible = 'On';
            else
                app.bgROI.Visible = 'Off';
                app.lipoCrosshair.Visible = 'Off';
            end
        end

        function updateImage(app)
            im = zeros(app.ymax, app.xmax, 3);
            
            for i = 1:app.Nchans
                if ~get(app.enable_checkbox(i), 'Value')
                    continue;
                end
                
                clr = get(app.color_button(i), 'BackgroundColor');
                scim = getScaledImage(app, i);
                
                for j = 1:3
                    im(:,:,j) = im(:,:,j)+scim.*clr(j);
                end
            
                    
            end


            if get(app.hilo_checkbox, 'Value')
            
                mask = ones(size(im,1), size(im,2));
                for j = 1:3
                    mask = mask & (im(:,:,j) == 0);
                end
            
                im = imoverlay(im, mask, [0 0 1]);
            end


            tstr = app.fn;
            tstr = strrep(tstr, '_', ' ');
            app.imageAxisIm.CData = im;
            app.imageAx.Title.String = tstr;
        end

        function climChange(app,dex,isMax,event)
            if ~app.enable_checkbox(dex).Value
                return
            end

            %if could get pre-callback could do updateImageOne but not
            %worth time
            app.updateImage();
        end

        function updateImageOne(app, dex, changeMult)            
            clr = get(app.color_button(dex), 'BackgroundColor');
            clr = reshape(clr,1,1,[]);
            scim = app.getScaledImage(dex);
            app.imageAxisIm.CData = app.imageAxisIm.CData+ changeMult*(scim.*clr);
        end

        function saveButtonFcn(app)            
            [file,path] = uiputfile('*.mat','Select file name', 'Settings.mat');
            
            params.str = cell(app.Nchans, 1);
            for i = 1:app.Nchans
                params.str{i} = get(app.chanLabel_edit(i), 'String');
            end
            
            for i = 1:app.Nchans
                params.clr{i}  = get(app.color_button(i), 'BackgroundColor');
                params.cmin{i} = get(app.cmin_edit(i), 'String');
                params.cmax{i} = get(app.cmax_edit(i), 'String');
                params.autolim(i) = get(app.autolim(i), 'Value');
                params.enable(i) = get(app.enable_checkbox(i), 'Value');
            end
            
            params.unmixDat = app.unmixTable.Data;
            params.shiftDat = app.shiftTable.Data;
            
            params.lpf = get(app.LPF_checkbox, 'Value');
            params.radius = get(app.radius_edit, 'String');
            
            params.lipo           = app.lipo;
            params.lipocheck = get(app.lipo_checkbox, 'Value');
            
            save(fullfile(path, file), 'params');
        end

        function batchProcess(app)
            % defpath = fileparts(h.info(1).Filename);
            % [fns, h.parent] = uigetfile('*.tif', 'Select files to process', defpath, 'MultiSelect', 'On');
            % if ~iscell(h.fn)
            %     if numel(h.fn)==1
            %         return;
            %     else
            %         h.fn = {h.fn};
            %     end
            % end
            
            fns = app.FileDropDown.Items;
            nums = app.FileDropDown.Value;
            
            
            for i = 1:numel(nums)
                app.fn = fns{nums(i)};
                app.loadTifStack();
                app.exportImageStack();
            end
            disp('Done Batch Processing!!!!')
        end

        function exportImageStack(app)
            if app.applyNonRigidShifts
                for i = 1:size(app.tiffstack,3)
                    disp(strcat("Plane ", num2str(i), " of ", num2str(size(app.tiffstack, 3))));
                    thisPlane = squeeze(app.tiffstack(:,:,i,1:2));
                    thisPlaneNormed = normalize_local_contrast(thisPlane,[25,25],0,1,0);
                    
                    thisPlaneNormed(isnan(thisPlaneNormed))=0;
                    template = median(thisPlaneNormed,3);
                    options_nonrigid = NoRMCorreSetParms('d1',size(app.tiffstack,1),'d2',size(app.tiffstack,2),'grid_size',[256,256],'mot_uf',4,'max_shift',3,'max_dev',3,'us_fac',50,'correct_bidir',0,'usegpu',0);
                    try
                        [~,shifts,~,~] = normcorre(thisPlaneNormed,options_nonrigid,template);
                        app.tiffstack(:,:,i,1:2) = apply_shifts(squeeze(app.tiffstack(:,:,i,1:2)),shifts,options_nonrigid);
                    catch
                        app.tiffstack(:,:,i,1:2) = thisPlane;
                    end
                end
            end
            app.stack = app.tiffstack;
            %filepart
            [~,b,~] = fileparts(app.fn);
            fnOut = fullfile(app.parent, b + '-unmixed.tif');
            
            t = Tiff(fnOut, 'w8');
            tagstruct.ImageLength = app.Nrow;
            tagstruct.ImageWidth = app.Ncol;
            tagstruct.SampleFormat = Tiff.SampleFormat.UInt; % uint
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.BitsPerSample = 16;
            tagstruct.SamplesPerPixel = app.Nchans;
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            [~,invOrder] = sort(app.channelOrder);
            for ii=1:app.Nplanes
                app.plane = ii;
                app.getImageStack();
                im = app.stack(:,:,invOrder);
                setTag(t,tagstruct);
                write(t,uint16(im));
                writeDirectory(t);
            end
            close(t)
            
            % tmp.plane = h.plane;
            % tmp.ymax = h.ymax;
            % tmp.xmax = h.xmax;
            % 
            % h.ymax = h.Nrow;
            % h.xmax = h.Ncol;
            % 
            % cnt = 0;
            % for i = 1:h.Nplanes
            %     h.plane = i;
            % 
            %     guidata(fig, h);
            %     getImageStack([], [], fig)
            % 
            %     h = guidata(fig);
            %     im = h.stack;
            % 
            %     for j = 1:h.Nchans
            %         cnt = cnt+1;
            % 
            %         if cnt == 1
            %             imwrite(uint16(squeeze(im(:,:,j))), fn, 'tif', 'writemode', 'overwrite', ...
            %                 'Compression', 'None', 'Description', h.info(1).ImageDescription);
            %         else
            %             imwrite(uint16(squeeze(im(:,:,j))), fn, 'tif','writemode', 'append', ...
            %                 'Compression', 'None', 'Description', h.info(1).ImageDescription);
            %         end
            %     end
            %     updateImage([], [], fig);
            % end


            % h.plane = tmp.plane;
            % h.ymax = tmp.ymax;
            % h.xmax = tmp.xmax;
        end

        function getImageStack(app)
            app.refreshStack();
            app.removeLipo();
            app.doShifts();
            app.lowPassFilterStack();
            app.doUnmixing();
            for i = 1:app.Nchans
                app.enableLims(i,1);
            end
            app.updateImage();
        end

        function refreshStack(app)
            app.stack = squeeze(double(app.tiffstack(1:app.ymax, 1:app.xmax, app.plane,:)));
        end

        function doShifts(app)
            for i = 1:size(app.shiftTable.Data,1)
                for j = 1:size(app.shiftTable.Data,2)
                    if ~isa(app.shiftTable.Data(i,j),'double')
                        app.shiftTable.Data(i,j) = str2double(app.shiftTable.Data(i,j));
                    elseif isempty(app.shiftTable.Data(i,j))
                        app.shiftTable.Data(i,j) = 0;
                    end
                end
            end

            if ~all(app.shiftTable.Data==round(app.shiftTable.Data),"all")
                app.doShiftsFFT();
                return;
            end
            
            newstack = zeros(app.ymax, app.xmax, app.Nchans);
            
            xshift = zeros(app.Nchans, 1);
            yshift = zeros(app.Nchans, 1);
            
            shiftDat = app.shiftTable.Data;
            for i = 1:size(shiftDat, 1)
                if ~isempty(shiftDat(i, 1)) && shiftDat(i, 1)>0
                    xshift(shiftDat(i, 1)) = shiftDat(i, 2);
                    yshift(shiftDat(i, 1)) = shiftDat(i, 3);
                end
            end
            
            for i = 1:app.Nchans 
                
                y1 = 1:app.ymax;
                y2 = 1:app.ymax;
                if yshift(i)>0
                    y1 = 1+yshift(i):app.ymax;
                    y2 = 1:app.ymax-yshift(i);
                elseif yshift(i)<0
                    y1 = 1:app.ymax+yshift(i);
                    y2 = 1-yshift(i):app.ymax;
                end
                
                x1 = 1:app.xmax;
                x2 = 1:app.xmax;
                if xshift(i)>0
                    x1 = 1+xshift(i):app.xmax;
                    x2 = 1:app.xmax-xshift(i);
                elseif xshift(i)<0
                    x1 = 1:app.xmax+xshift(i);
                    x2 = 1-xshift(i):app.xmax;
                end
                newstack(y1,x1,i) = app.stack(y2, x2, i);
            end
            app.stack = newstack;
        end

        function doShiftsFFT(app)
            xshift = zeros(app.Nchans, 1);
            yshift = zeros(app.Nchans, 1);
            
            shiftDat = app.shiftTable.Data;
            for i = 1:size(shiftDat, 1)
                if ~isempty(shiftDat(i, 1)) && shiftDat(i,1)>0
                    xshift(shiftDat(i, 1)) = shiftDat(i, 2);
                    yshift(shiftDat(i, 1)) = shiftDat(i, 3);
                end
            end
            % Perform FFT
            fft_img = fft2(app.stack);
            [ny, nx, nchans] = size(fft_img);
            
            % Compute shift amounts in terms of frequency domain coordinates
            dx = xshift;
            dy = yshift;
            x = ifftshift(-fix(nx/2):ceil(nx/2)-1);
            y = ifftshift(-fix(ny/2):ceil(ny/2)-1);
            [X, Y] = meshgrid(x, y);
            
            % Create shift matrix for each channel
            Exp_shift = exp(-1j * 2 * pi * ((X .* reshape(dx, [1, 1, nchans]) / nx) + (Y .* reshape(dy, [1, 1, nchans]) / ny)));
            
            % Apply shift in the frequency domain
            shifted_fft = fft_img .* Exp_shift;
            
            % Inverse FFT to get the shifted image
            app.stack = ifft2(shifted_fft, 'symmetric');
        end

        function lowPassFilterStack(app)
            if ~app.LPF_checkbox.Value
                return
            end
            pix = str2double(get(app.radius_edit, 'String'));
            app.stack = gaussFilt(app.stack, pix);
        end
        function doUnmixing(app)
            % 5x5 cell array representing values in spectral unmixing panel
            tabDat = app.unmixTable.Data;
            
            % Y by X by channels
            newstack = app.stack;
            
            % iterate through rows
            for i = 1:size(tabDat, 1)
            
                % if spectral unmixing row is enabled
                if ~isempty(tabDat{i, 5}) && tabDat{i, 5}
                    
                    from = tabDat{i, 1};
                    to   = tabDat{i, 2};
                    
                    % sourceIm = image to be subtracted from image where unmixing is
                    % desired
                    sourceIm = double(newstack(:,:,from));
                    offset = tabDat{i, 3};
                    scale = tabDat{i, 4};
            
                    sourceIm = (sourceIm - offset).*scale;
                    sourceIm(sourceIm<0) = 0;
            
                    unmixedIm = newstack(:,:,to) - sourceIm;
                    % unmixedIm(unmixedIm < 0) = 0;
            
                    newstack(:,:,to) = unmixedIm;
                end
            end
            app.stack = newstack;
        end


        function removeLipo(app)
            if ~app.lipo_checkbox.Value
                return
            end
            newstack = app.stack;
            app.lipo.modelchans = str2num(app.lipomodelchans_edit.String);
            app.lipo.targetchans = str2num(app.liporemovechans_edit.String);
            lim = zeros(size(app.stack, 1), size(app.stack, 2), numel(app.lipo.modelchans));
            for i = 1:numel(app.lipo.modelchans)
                amp = app.lipo.amp(app.lipo.modelchans(i));
                bg = app.lipo.bg(app.lipo.modelchans(i));
        
                lim(:,:,i) = (app.stack(:,:,app.lipo.modelchans(i)) - bg)./amp;  %lipo has amplitude of 1
            end
            bgim = min(lim, [], 3);
            bgim(bgim<0) = 0;
            
            for i = 1:numel(app.lipo.targetchans)
                chan = app.lipo.targetchans(i);
                
                bg = bgim.*app.lipo.amp(chan);
                newstack(:,:,chan) = app.stack(:,:,chan) - bg;
            end
            % amp = app.lipo.amp;
            % bg = app.lipo.bg;
            % lim = zeros(size(app.stack, 1), size(app.stack, 2), numel(app.lipo.modelchans));
            % for i = 1:numel(app.lipo.modelchans)
            %     dex = app.lipo.modelchans(i);
            % 
            %     lim(:,:,i) = (app.stack(:,:,dex) - bg(dex))./amp(dex);  %lipo has amplitude of 1
            %     % lim(:,:,i) = limChan./prctile(limChan(limChan>0.5),99);
            % end
            % bgim = min(lim, [], 3);
            % bgim(bgim<0) = 0;
            % % bgMask = bgim>0.5;
            % % bgim = bgim/(prctile(bgim(bgim>0.5),99)); %don't want anything greater than 1;
            % 
            % for i = 1:app.Nchans
            %     if any(app.lipo.modelchans==i)
            %         limChan = (app.stack(:,:,i) - bg(i))./amp(i);
            %         correction = prctile(limChan(limChan>0.1 & bgim>0.1),99); %where there is lipo and this channel
            %         amp(i) = amp(i)/correction;
            %     else
            %         continue
            %     end
            % 
            % end
            % 
            % bgim = bgim.*reshape(amp,1,1,[]);
            % app.stack = app.stack-bgim;
            % bgimMask = bgim>0.1;
            % % for i = 1:app.Nchans
            % %     thisChan = app.stack(:,:,i);
            % %     bgimMaskThisIm = bgimMask & thisChan<bg(i);
            % %     thisChan(bgimMaskThisIm) = bg(i);
            % %     app.stack(:,:,i) = thisChan;
            % % end
            % %equivalent to above commented out code but faster (just making
            % %sure not over-subtracting anything)
            % app.stack = app.stack- min((app.stack - reshape(bg,1,1,[])),0).*bgimMask; 
            app.stack = newstack;
        end


        function b = estimateUnmixCoef(app,toRemoveChan,toRemoveFromChan)
            thisPlane = squeeze(app.stack);
            thisPlane = double(thisPlane);
            
            toRemove = thisPlane(:,:,toRemoveChan);
            toRemoveFrom = thisPlane(:,:,toRemoveFromChan);
            tmp = reshape(thisPlane, [], app.Nchans);
            try
                bgROIMask = createMask(app.bgROI);
                bg = double(squeeze(median(tmp(bgROIMask, :))));
                bgStd = double(squeeze(std(tmp(bgROIMask,:))));
            catch
                warning("please select backround area using the lipo tools-- else estimation of mixing is poor")
                bg = median(tmp);
                for i = 1:size(tmp,2)
                    bgStd(i) = std(tmp(tmp(:,i)<bg(i),i));
                end
            end
            
            thisPlaneLocalCentered = normalize_local_contrast(thisPlane,[25,25],0,1,0);
            bgThresh = reshape(bg+2*bgStd,1,1,[]);
            foregroundMask = thisPlaneLocalCentered>bgThresh;
            
            % Centering the data
            toRemove_centered = toRemove - bg(toRemoveChan); toRemove_centered(toRemove_centered<0) = 0;
            toRemoveFrom_centered = double(toRemoveFrom) - bg(toRemoveFromChan); toRemoveFrom_centered(toRemoveFrom_centered<0) = 0;
            
            % mask = sum(foregroundMask(:,:,[toRemoveFromChan toRemoveChan]),3)>0;
            mask = foregroundMask(:,:,toRemoveChan);
            if sum(imclose(mask,ones(3)),'all')/numel(mask)<0.01
                b = 0;
                return
            end

            
            % Performing linear regression
            b = regress(toRemoveFrom_centered(mask), toRemove_centered(mask));
            
            return 
            % Removing the variance explained by plane 4
            adjustedPlane = thisPlane(:,:,toRemoveFromChan) - (thisPlane(:,:,toRemoveChan) * b); %#ok<UNRCH>

            % Visualization
            figure; moviesc(vm(cat(3, thisPlane(:,:,toRemoveFromChan), thisPlane(:,:,toRemoveChan), adjustedPlane,mask)));
        end

    end

    methods(Static)
        function imFns = findImageFiles(parent, token)
            contents = dir(parent);
            good = false(numel(contents), 1);
            for i = 1:numel(good)
               if ~isempty(strfind(contents(i).name, token))
                   good(i) = true;
               end
            end
            
            contents = contents(good);
            imFns = cell(numel(contents), 1);
            for i = 1:numel(contents)
                imFns{i} = contents(i).name;
            end
        end

        function varargout = myReadTiff(fullfn)
            varargout{1} = tiffreadVolume(fullfn);
            info = imfinfo(fullfn);   
            Nrow = info(1).Height;
            Ncol = info(1).Width;
            [nchans,nplanes] = parseTiffChansPlanesInfo(info(1));
            varargout{1} = reshape(varargout{1}, Nrow, Ncol, nchans, nplanes);
            varargout{1} = permute(varargout{1},[1,2,4,3]);
            if nargout>1
                varargout(2:5) = {Nrow,Ncol,nchans,nplanes};
                varargout = varargout(1:nargout);
            end
        end

        function [chans, planes] = parseTiffChansPlanesInfo(info)
            id = info.ImageDescription;
            
            tok = regexp(id, 'channels=(\d*)', 'tokens');
            if isempty(tok)
                disp('Could not read number of channels in tif.  Assuming 1 channel');
                chans = 1;
            else
                chans = str2double(tok{1}{1});
            end
            
            tok = regexp(id, 'slices=(\d*)', 'tokens');
            
            if isempty(tok)
                tok = regexp(id, 'frames=(\d*)', 'tokens');
            end
            if isempty(tok)
                disp('Could not read number of z planes in tif.  Assuming 1 plane');
                planes = 1;
            else
                planes = str2double(tok{1}{1});
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rootDir = getpref(class(app),'lastPath',pwd);
            if ~exist(rootDir,'dir')
                rootDir = pwd;
            end
            
            REFilter ='\.(tif(f?)|raw|prd)$'; %'\.tif(f?)$'
            prompt = 'Select image';
            outFile = uipickfiles('FilterSpec',rootDir,'REFilter',REFilter,'Prompt',prompt,'Output','char');
            outFile = strtrim(string(outFile));
            [filePath,fileName,ext] = fileparts(outFile);
            app.fn = fileName+ext;
            app.parent = filePath;
            
            if exist(filePath,'dir')
                setpref(class(app),'lastPath',filePath);
            end
            
            bcol = [1 1 1];
            pcol = [0.9 0.9 0.9];
            
            set(app.UIFigure, 'Color', bcol);

            app.loadTifStack()
            title(app.imageAx, app.fn,'interpreter', 'none');
            
            app.imageAxisIm = imagesc(app.imageAx, zeros(app.Nrow, app.Ncol, 3)); 
            axis(app.imageAx, 'off');
            axis(app.imageAx, 'tight');
            axis(app.imageAx, 'image');
            
            
            app.lipo.modelchans =  1:app.Nchans;
            app.lipo.targetchans = 1:app.Nchans;
            
            app.lipo.bg = zeros(app.Nchans, 1);
            app.lipo.amp = zeros(app.Nchans, 1);
            
            possibleclrs = [...
                1 0 0;...
                0 1 0;...
                0 0 1;...
                1 0 1;...
                0 1 1;...
                1 1 0;...
                1 1 1];
            defclrs = possibleclrs(1:app.Nchans, :);
            
            app.str = {};            
            tokens = split(app.fn, '_');
            nums = [];
            for i = 1:length(tokens)
                % Extract numbers from the token using a regular expression
                num = regexp(tokens{i}, '\d\d\d$', 'match');
                
                % Check if the extracted number is in the im_wavelengths set and within the range 250 to 999
                if ~isempty(num)
                    num = str2double(num{1});  % Convert the string to a number
                    if num >= 250 && num <= 999
                        app.str{end+1} = tokens{i};
                        nums(end+1) = num;
                    end
                end
            end

            [~,app.channelOrder] = sort(nums);
            app.str = app.str(app.channelOrder);
            app.tiffstack = app.tiffstack(:,:,:,app.channelOrder);
            app.stack = app.stack(:,:,app.channelOrder);

            if isempty(app.str)
                app.str = cell(app.Nchans, 1);
                for i = 1:numel(app.str)
                   app.str{i} = ['Channel ' num2str(i)];
                end
            end


            for i = 1:app.Nchans
                cmax = 255;
                yoff = 10;
                xoff = 130*(i-1);
                
                app.chanLabel_edit(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'edit', 'String', app.str{i},'Units','pixels' ...
                    ,'BackgroundColor', [1 1 1],'Position',[xoff+15 yoff+150 110 20],'HorizontalAlignment','Center', 'Fontsize', 7, 'Fontweight', 'Bold');
                
                app.color_button(i) = uicontrol('parent', app.ChannelsPanel,'Style', 'pushbutton',  'Units', 'Pixels', 'Position', ...
                [xoff+15, yoff+115, 110, 30], 'String', '', 'Callback', @(~,~) app.colorButtonFcn(i), 'BackgroundColor', defclrs(i, :));
                
                uicontrol('parent', app.ChannelsPanel, 'Style', 'text', 'String', 'Min: ','Units','pixels' ...
                    ,'BackgroundColor',pcol,'Position',[xoff+14 yoff+66 40 24],'HorizontalAlignment','Right');
                uicontrol('parent', app.ChannelsPanel, 'Style', 'text', 'String', 'Max: ','Units','pixels' ...
                    ,'BackgroundColor',pcol,'Position',[xoff+14 yoff+37 40 24],'HorizontalAlignment','Right');
                
                app.cmin_edit(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'edit', 'String', '0','Units','pixels' ...
                    ,'BackgroundColor',[1 1 1],'Position',[xoff+61 yoff+72 56 24],'HorizontalAlignment','Right', ...
                    'Callback', @(src,event) app.climChange(i,0,event), 'Enable', 'Off');
                
                app.cmax_edit(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'edit', 'String', num2str(cmax),'Units','pixels' ...
                    ,'BackgroundColor',[1 1 1],'Position',[xoff+61 yoff+42 56 24],'HorizontalAlignment','Right', ...
                    'Callback', @(src,event) app.climChange(i,1,event), 'Enable', 'Off');
                
                app.autolim(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'checkbox',  'Units', 'pixels', 'Position', ...
                    [xoff+78 yoff+7 70 24], 'String', 'Auto','FontWeight','Bold', 'Min', 0, 'Max', 1, ...
                    'BackgroundColor', pcol, 'Value', 1, 'Callback', @(~,~) app.enableLims(i));
                
                app.enable_checkbox(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'checkbox', 'Value', i<4,'Units','pixels' ...
                ,'BackgroundColor',pcol,'Position',[xoff+28 yoff+7 46 24],'HorizontalAlignment','Right', ...
                'Callback', @(src,~) app.updateImageOne(i,src.Value*2-1), 'BackgroundColor', pcol, 'String', 'On', 'FontSize', 10);
            
                uicontrol('parent', app.ChannelsPanel, 'Style', 'text', 'String', ' ','Units','pixels' ...
                    ,'BackgroundColor',pcol-0.1,'Position',[xoff+132 yoff+10 5 150],'HorizontalAlignment','Right');
            end
            app.chanLabel_edit = findobj(app.chanLabel_edit);
            app.color_button = findobj(app.color_button);
            app.cmin_edit = findobj(app.cmin_edit);
            app.cmax_edit = findobj(app.cmax_edit);
            app.autolim = findobj(app.autolim);
            app.enable_checkbox = findobj(app.enable_checkbox);
            
            app.hilo_checkbox = uicontrol('parent', app.ChannelsPanel, 'Style', 'checkbox', 'Value', 0,'Units','pixels' ...
                ,'BackgroundColor',pcol,'Position',[800 150 200 24],'HorizontalAlignment','Right', ...
                'Callback', @(~,~)  app.updateImage, 'BackgroundColor', pcol, 'String', 'HiLo LUT', 'FontSize', 10);


            data = cell(10, 5);
            data(:,:) = {0};
            data(1,:) = {1, 2, 0, 1, false};
            data(:, 5) = {false};
            app.unmixTable.Data = data;
            
            data = zeros(app.Nchans, 3);  % Initialize a 5x3 matrix of zeros (all elements are doubles)
            data(1,:) = [1, 0, 0];  % Set the first row with the desired values
            
            app.shiftTable.Data = data;
            
            xx = -790;
            yy = -50;
            app.lipo_checkbox = uicontrol('parent', app.LipooptionsPanel,'Style', 'checkbox', 'Value', 0,'Units','pixels' ...
                ,'BackgroundColor',[1 1 1],'Position',[800+xx 120+yy 150 20],'HorizontalAlignment','Right', ...
                'FontWeight', 'Bold', 'BackgroundColor', pcol, 'String', 'Remove lipofuscin');
            
            app.crosshairs_checkbox = uicontrol('parent', app.LipooptionsPanel,'Style', 'checkbox', 'Value', 0,'Units','pixels' ...
                ,'BackgroundColor',[1 1 1],'Position',[950+xx 120+yy 150 20],'HorizontalAlignment','Right', ...
                'FontWeight', 'Bold', 'BackgroundColor', pcol, 'String', 'Show ROIs', ...
                'Callback', @(~,~) app.lipoCheckboxCallback);
            
            app.lipoAx = axes('parent', app.LipooptionsPanel);
            set(app.lipoAx, 'Units', 'pixels', 'Position', [820+xx 160+yy 220 85]);
            plot(app.lipoAx, app.lipo.bg); hold on; plot(app.lipoAx, app.lipo.amp);
            
            app.lipomodelchans_edit = uicontrol('parent', app.LipooptionsPanel,'Style', 'edit', 'String', num2str(app.lipo.modelchans),'Units','pixels' ...
                ,'BackgroundColor',[1 1 1],'Position',[800+xx 315+yy 70 20],'HorizontalAlignment','Right');
            uicontrol('parent', app.LipooptionsPanel,'Style', 'text', 'String', 'Channels to build model from','Units','pixels' ...
                ,'BackgroundColor',pcol,'Position',[880+xx 315+yy 150 17],'HorizontalAlignment','Left');
            
            app.liporemovechans_edit = uicontrol('parent', app.LipooptionsPanel,'Style', 'edit', 'String', num2str(app.lipo.targetchans),'Units','pixels' ...
                ,'BackgroundColor',[1 1 1],'Position',[800+xx 290+yy 70 20],'HorizontalAlignment','Right');
            uicontrol('parent', app.updateLipoModel_button,'Style', 'text', 'String', 'Channels to remove lipo from','Units','pixels' ...
                ,'BackgroundColor',pcol,'Position',[880+xx 290+yy 150 17],'HorizontalAlignment','Left');
            
            app.updateLipoModel_button = uicontrol('parent', app.LipooptionsPanel,'Style', 'pushbutton',  'Units', 'pixels', 'Position', ...
                [800+xx 255+yy 260 25], 'String', 'Update lipo model','FontWeight','Bold', 'Callback', ...
                @(~,~) app.updateLipoModel);


            xx = -790;
            yy = -60;
            app.LPF_checkbox = uicontrol('parent', app.LipooptionsPanel,'Style', 'checkbox', 'Value', 0,'Units','pixels' ...
                ,'BackgroundColor',[1 1 1],'Position',[800+xx 100+yy 120 20],'HorizontalAlignment','Right', ...
                'FontWeight', 'Bold', 'BackgroundColor', pcol, 'String', 'Spatial Filter');
            
            app.radius_edit = uicontrol('parent', app.LipooptionsPanel,'Style', 'edit', 'String', '1.25','Units','pixels' ...
                ,'BackgroundColor',[1 1 1],'Position',[800+xx 75+yy 50 20],'HorizontalAlignment','Right');
            
            uicontrol('parent', app.LipooptionsPanel,'Style', 'text', 'String', 'width (pixels)','Units','pixels' ...
                ,'BackgroundColor',pcol,'Position',[860+xx 75+yy 100 17],'HorizontalAlignment','Left');
            
            
            app.bgROI = images.roi.Rectangle(app.imageAx,'Position',[100 100 25 25], 'LineWidth', 3, 'Visible', 'Off');
            app.lipoCrosshair = drawcrosshair('Parent', app.imageAx, 'Position', [50, 50], 'Visible', 'Off');
            
            
            imFns = app.findImageFiles(app.parent, '.tif');
            app.FileDropDown.Items = imFns;


            for i = 1:app.Nchans
                app.enableLims(i,1);
            end
            app.updateImage();
        end

        % Cell edit callback: shiftTable
        function shiftTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.saveButtonFcn();
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            app.loadButtonFcn();
        end

        % Button pushed function: UpdateimageButton
        function UpdateimageButtonPushed(app, event)
            app.getImageStack()
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            app.exportImageStack()
        end

        % Button pushed function: CompositeFigButton
        function CompositeFigButtonPushed(app, event)
            app.makeComposite()
        end

        % Button pushed function: BatchProcessButton
        function BatchProcessButtonPushed(app, event)
            app.batchProcess()
        end

        % Button pushed function: ApplynonrigidnormxcorrButton
        function ApplynonrigidnormxcorrButtonPushed(app, event)
            app.applyNonRigidShifts = 1;
            if ~exist('normcorre','file')
                prompt = 'Select normcorre folder (adding to path)';
                outFolder = uipickfiles('FilterSpec',pwd,'Prompt',prompt,'Output','char');
                outFolder = strtrim(string(outFolder));
                if ~isfolder(outFolder)
                    [outFolder,~,~] = fileparts(outFolder);
                end
                addpath(outFolder);
            end
            
            options_nonrigid = NoRMCorreSetParms('d1',size(app.tiffstack,1),'d2',size(app.tiffstack,2),'grid_size',[256,256],'mot_uf',4,'max_shift',3,'max_dev',3,'us_fac',50,'usegpu',0);
            thisPlane = squeeze(app.tiffstack(:,:,app.plane,1:2));
            
            thisPlaneNormed = normalize_local_contrast(thisPlane,[25,25],0,1,0);
            
            thisPlaneNormed(isnan(thisPlaneNormed))=0;
            template = median(thisPlaneNormed,3);
            [~,app.nonRigidShifts,~,~] = normcorre(thisPlaneNormed,options_nonrigid,template);
            app.tiffstack(:,:,app.plane,1:2) = apply_shifts(squeeze(app.tiffstack(:,:,app.plane,1:2)),app.nonRigidShifts,options_nonrigid);
            app.getImageStack()
        end

        % Button pushed function: RevertnonrigidButton
        function RevertnonrigidButtonPushed(app, event)
            app.switchFile();
        end

        % Button pushed function: AutoEstimateButton
        function AutoEstimateButtonPushed(app, event)
            app.stack = double(squeeze(app.tiffstack(:,:,app.plane,:)));
            app.removeLipo();
            for i = 1:app.Nchans
                app.unmixTable.Data{i,1} = i;
                app.unmixTable.Data{i+app.Nchans-1,1} = app.Nchans-i+1;
                app.unmixTable.Data{i,2} = i+1;
                app.unmixTable.Data{i+app.Nchans-1,2} = app.Nchans-i;
                app.unmixTable.Data{i,5} = false;
                app.unmixTable.Data{i+app.Nchans-1,5} = false;
                app.unmixTable.Data{i,4} = 0;
                app.unmixTable.Data{i+app.Nchans-1,4} = 0;
            end
            app.unmixTable.Data{app.Nchans,2} = app.Nchans-1;
            app.unmixTable.Data{app.Nchans*2-1,5} = false;
            for i = 1:app.Nchans-1
                app.unmixTable.Data{i,4} = app.estimateUnmixCoef(i,i+1);
                if app.unmixTable.Data{i,4}==0
                    app.unmixTable.Data{i,5} = false;
                else
                    app.unmixTable.Data{i,5} = true;
                    app.getImageStack();
                end
            end
            for i = app.Nchans:-1:2
                app.unmixTable.Data{i+app.Nchans-1,4} = app.estimateUnmixCoef(i,i-1);
                app.getImageStack();
                if app.unmixTable.Data{i+app.Nchans-1,4}==0
                    app.unmixTable.Data{i+app.Nchans-1,5} = false;
                else
                    app.unmixTable.Data{i+app.Nchans-1,5} = true;
                    app.getImageStack();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.imageAx = uiaxes(app.GridLayout);
            title(app.imageAx, 'Title')
            xlabel(app.imageAx, 'X')
            ylabel(app.imageAx, 'Y')
            zlabel(app.imageAx, 'Z')
            app.ChannelsPanel = uipanel(app.GridLayout);
            app.ChannelsPanel.Title = 'Channels';
            app.FileLabel = uilabel(app.GridLayout);
            app.FileLabel.Text = 'File:';
            app.FileDropDown = uidropdown(app.GridLayout);
            app.LipooptionsPanel = uipanel(app.GridLayout);
            app.LipooptionsPanel.Title = 'Lipo options';
            app.ImageShiftsPanel = uipanel(app.GridLayout);
            app.ImageShiftsPanel.Title = 'Image Shifts';
            app.GridLayout2 = uigridlayout(app.ImageShiftsPanel);
            app.shiftTable = uitable(app.GridLayout2);
            app.shiftTable.ColumnName = {'Channel'; 'X shift'; 'Y shift'};
            app.shiftTable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.shiftTable.ColumnEditable = true;
            app.shiftTable.CellEditCallback = createCallbackFcn(app, @shiftTableCellEdit, true);
            app.ApplynonrigidnormxcorrButton = uibutton(app.GridLayout2, 'push');
            app.ApplynonrigidnormxcorrButton.ButtonPushedFcn = createCallbackFcn(app, @ApplynonrigidnormxcorrButtonPushed, true);
            app.ApplynonrigidnormxcorrButton.Text = 'Apply non-rigid (normxcorr)';
            app.RevertnonrigidButton = uibutton(app.GridLayout2, 'push');
            app.RevertnonrigidButton.ButtonPushedFcn = createCallbackFcn(app, @RevertnonrigidButtonPushed, true);
            app.RevertnonrigidButton.Text = 'Revert non-rigid';
            app.SpectralUnmixingPanel = uipanel(app.GridLayout);
            app.SpectralUnmixingPanel.Title = 'Spectral Unmixing';
            app.GridLayout3 = uigridlayout(app.SpectralUnmixingPanel);
            app.unmixTable = uitable(app.GridLayout3);
            app.unmixTable.ColumnName = {'Unmix from'; 'Unmix to'; 'Offset'; 'Scale'; 'Enable'};
            app.unmixTable.RowName = {};
            app.unmixTable.ColumnEditable = true;
            app.AutoEstimateButton = uibutton(app.GridLayout3, 'push');
            app.AutoEstimateButton.ButtonPushedFcn = createCallbackFcn(app, @AutoEstimateButtonPushed, true);
            app.AutoEstimateButton.Text = 'Auto Estimate';
            app.PlaneLabel = uilabel(app.GridLayout);
            app.PlaneLabel.Text = 'Plane:';
            app.PlaneEditField = uieditfield(app.GridLayout, 'numeric');
            app.PlaneEditField.Value = 1;
            app.UpdateimageButton = uibutton(app.GridLayout, 'push');
            app.UpdateimageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateimageButtonPushed, true);
            app.UpdateimageButton.Text = 'Update image';
            app.SaveSettingsButton = uibutton(app.GridLayout, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.GridLayout, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.BatchProcessButton = uibutton(app.GridLayout, 'push');
            app.BatchProcessButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessButtonPushed, true);
            app.BatchProcessButton.Text = 'Batch Process';
            app.ExportImageButton = uibutton(app.GridLayout, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Text = 'Export Image';
            app.CompositeFigButton = uibutton(app.GridLayout, 'push');
            app.CompositeFigButton.ButtonPushedFcn = createCallbackFcn(app, @CompositeFigButtonPushed, true);
            app.CompositeFigButton.Text = 'Composite Fig';
            app.AutoAllButton = uibutton(app.GridLayout, 'push');
            app.AutoAllButton.Text = 'Auto All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unmixV5

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end